classdef volumetricMTR_v01_008 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SelectedPDBfileEditFieldLabel   matlab.ui.control.Label
        SelectedPDBfileEditField        matlab.ui.control.EditField
        SelectPDBFileButton             matlab.ui.control.Button
        inputTable                      matlab.ui.control.Table
        ClearInputTableButton           matlab.ui.control.Button
        SaveInputTableButton            matlab.ui.control.Button
        OpenInputTableButton            matlab.ui.control.Button
        SelectBaseFolderButton          matlab.ui.control.Button
        SelectedBaseFolderEditFieldLabel  matlab.ui.control.Label
        SelectedBaseFolderEditField     matlab.ui.control.EditField
        SelectPDBSeqFileButton          matlab.ui.control.Button
        SelectedPDBGeneSeqfileEditFieldLabel  matlab.ui.control.Label
        SelectedPDBGeneSeqfileEditField  matlab.ui.control.EditField
        SelectVariantFileButton         matlab.ui.control.Button
        SelectedVariantfileEditFieldLabel  matlab.ui.control.Label
        SelectedVariantfileEditField    matlab.ui.control.EditField
        SelectVariantSeqFileButton      matlab.ui.control.Button
        SelectedVariantGeneSeqfileEditFieldLabel  matlab.ui.control.Label
        SelectedVariantGeneSeqfileEditField  matlab.ui.control.EditField
        UseSelectedFilesButton          matlab.ui.control.Button
        SelectedVariantGeneSeqfileEditField_2Label  matlab.ui.control.Label
        ChainsthatVariantFileCorrespondswithinthePDBStructureEditField  matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        UpdateProcessingTableButton     matlab.ui.control.Button
        AppInitializedLampLabel         matlab.ui.control.Label
        AppInitializedLamp              matlab.ui.control.Lamp
        EntryIDEditFieldLabel           matlab.ui.control.Label
        EntryIDEditField                matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SequenceAlignmentTab            matlab.ui.container.Tab
        SequenceAlignmentDisplayTable   matlab.ui.control.Table
        GeneAlignmenttoshowDropDownLabel  matlab.ui.control.Label
        GeneAlignmenttoshowDropDown     matlab.ui.control.DropDown
        alignmentPlot                   matlab.ui.control.UIAxes
        SaveAlignmentPlotButton         matlab.ui.control.Button
        AlignmentMethodDropDownLabel    matlab.ui.control.Label
        AlignmentMethodDropDown         matlab.ui.control.DropDown
        RealignButton                   matlab.ui.control.Button
        patchGaps                       matlab.ui.control.CheckBox
        PDBTab                          matlab.ui.container.Tab
        ShowOriginalPDBButton           matlab.ui.control.Button
        PDBtoshowDropDownLabel          matlab.ui.control.Label
        PDBtoshowDropDown               matlab.ui.control.DropDown
        InputPDBsLabel                  matlab.ui.control.Label
        ResultantPDBsLabel              matlab.ui.control.Label
        CalcuationFactortoapplytoPDBDropDownLabel  matlab.ui.control.Label
        CalcuationFactortoapplytoPDBDropDown  matlab.ui.control.DropDown
        Save3DMTRPDBButton              matlab.ui.control.Button
        savedPDBdescriptionLabel        matlab.ui.control.Label
        savedPDBdescriptionLabel_2      matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        DMTRSelectedFactorTab           matlab.ui.container.Tab
        CalcuationFactorDropDownLabel   matlab.ui.control.Label
        CalcuationFactorDropDown        matlab.ui.control.DropDown
        vMTRFactorPlot                  matlab.ui.control.UIAxes
        SaveMTRFactorPlotButton         matlab.ui.control.Button
        Save3DMTRFactorPDBButton        matlab.ui.control.Button
        ResiduetohighlightinPBDDropDownLabel  matlab.ui.control.Label
        ResiduetohighlightinPBDDropDown  matlab.ui.control.DropDown
        SaveaPDBmarkingaselectedResidueandtheresiduesLabel  matlab.ui.control.Label
        SaveMTRFactorSpreadsheetButton  matlab.ui.control.Button
        SaveaPDBmarkingaselectedResidueandtheresiduesLabel_2  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Calculated3DMTRTab              matlab.ui.container.Tab
        GenetoViewDropDown_2Label       matlab.ui.control.Label
        GenetoViewDropDown              matlab.ui.control.DropDown
        CalcuationFactortoShowDropDownLabel  matlab.ui.control.Label
        CalcuationFactortoShowDropDown  matlab.ui.control.DropDown
        swMTRresultPlot                 matlab.ui.control.UIAxes
        vMTRresultPlot                  matlab.ui.control.UIAxes
        SaveTandemMTRPlotsButton        matlab.ui.control.Button
        fittoWhatDropDownLabel          matlab.ui.control.Label
        fittoWhatDropDown               matlab.ui.control.DropDown
        DMTRDifferenceTab               matlab.ui.container.Tab
        Diff_SaveDiffMTRPlotsButton     matlab.ui.control.Button
        GenetoViewDropDown_2Label_2     matlab.ui.control.Label
        Diff_GenetoViewDropDown         matlab.ui.control.DropDown
        Factor1DropDownLabel            matlab.ui.control.Label
        Diff_Factor1DropDown            matlab.ui.control.DropDown
        Factor2DropDownLabel            matlab.ui.control.Label
        Diff_Factor2DropDown            matlab.ui.control.DropDown
        DifferenceMTRplotsFactor2Factor1Label  matlab.ui.control.Label
        Diff_vMTRresultPlot             matlab.ui.control.UIAxes
        SaveDiff3DMTRPDBButton          matlab.ui.control.Button
        PermutationsTab                 matlab.ui.container.Tab
        GenetoViewDropDown_3Label       matlab.ui.control.Label
        Perm_GenetoViewDropDown         matlab.ui.control.DropDown
        CalcuationFactortoShowDropDown_2Label  matlab.ui.control.Label
        Perm_CalcuationFactortoShowDropDown  matlab.ui.control.DropDown
        CalculatePermutationsButton     matlab.ui.control.Button
        SavePermutationsButton          matlab.ui.control.Button
        NumPermuationsEditFieldLabel    matlab.ui.control.Label
        NumPermuationsEditField         matlab.ui.control.NumericEditField
        Perm_vMTRresultPlot             matlab.ui.control.UIAxes
        PermuteWhatDropDownLabel        matlab.ui.control.Label
        PermuteWhatDropDown             matlab.ui.control.DropDown
        PermutationsCalculatedLampLabel  matlab.ui.control.Label
        PermutationsCalculatedLamp      matlab.ui.control.Lamp
        SavePermutationPlotButton       matlab.ui.control.Button
        SaveExamplePermutationFactorPlotButton  matlab.ui.control.Button
        SavePermutationSignificancePDBButton  matlab.ui.control.Button
        RasterPlotsTab                  matlab.ui.container.Tab
        rasterInclusionTable            matlab.ui.control.Table
        Save3DMTRRasterPlotButton       matlab.ui.control.Button
        GenetoplotDropDownLabel         matlab.ui.control.Label
        GenetoplotDropDown              matlab.ui.control.DropDown
        DebugingTab                     matlab.ui.container.Tab
        SaveStateButton                 matlab.ui.control.Button
        LoadStateButton                 matlab.ui.control.Button
        Calculate3DMTRButton            matlab.ui.control.Button
        DataLoadedLampLabel             matlab.ui.control.Label
        DataLoadedLamp                  matlab.ui.control.Lamp
        DMTRCalculatedLampLabel         matlab.ui.control.Label
        DMTRCalculatedLamp              matlab.ui.control.Lamp
        SaveResultsButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        baseFolder = '' % Description
        rawData = struct;
        prsData = struct;
    end
    
    methods (Access = private)
        
        function loadFiles(app)
            
            
            app.StatusEditField.Value = ['loading Files']; pause(0.01);
            
            app.rawData.baseFolder = app.baseFolder;
            app.rawData.inputTable = app.inputTable.Data;
            app.rawData.ID = app.rawData.inputTable(:,1)';
            app.rawData.files.pdb = app.rawData.inputTable(:,2)';
            app.rawData.files.pdbSeq = app.rawData.inputTable(:,3)';
            app.rawData.files.var = app.rawData.inputTable(:,4)';
            app.rawData.files.varSeq = app.rawData.inputTable(:,5)';
            app.rawData.chains = app.rawData.inputTable(:,6)';
            
            %make results folder
            app.prsData.resultsFolder = [app.rawData.baseFolder '\' 'results\'];
            if ~(isfolder(app.prsData.resultsFolder))
                app.StatusEditField.Value = ['making results folder']; pause(0.01);
                mkdir(app.prsData.resultsFolder);
            end
            
            for i = 1:size(app.rawData.ID,2)
                if i==1
                    sugName = app.rawData.ID{1,i};
                else
                    sugName = [sugName '_' app.rawData.ID{1,i}];
                end
            end
            
            app.prsData.resultsFolder = [app.prsData.resultsFolder '\' sugName];
            if ~(isfolder(app.prsData.resultsFolder))
                app.StatusEditField.Value = ['making results folder']; pause(0.01);
                mkdir(app.prsData.resultsFolder);
            end
            
            app.prsData.resultsFolder = [app.prsData.resultsFolder '\' datestr(now,'yyyymmdd') '\'];
            if ~(isfolder(app.prsData.resultsFolder))
                app.StatusEditField.Value = ['making results folder']; pause(0.01);
                mkdir(app.prsData.resultsFolder);
            end
            
            %load data
            
            %load variant data
            app.StatusEditField.Value = ['loading Files: variant data']; pause(0.01);
            app.rawData.nGenes = size(app.rawData.inputTable,1);
            for j = 1:app.rawData.nGenes
                app.rawData.gnomAD{j} = readtable([app.rawData.files.var{j}]);
                [app.prsData.varData.varObserved{j} app.prsData.varData.varSeq{j}] = tabulateSameMisVariantsGen(app.rawData.gnomAD{j},0);
            end
            
            %load variant gene data
            app.StatusEditField.Value = ['loading Files: variant gene data']; pause(0.01);
            for j = 1:app.rawData.nGenes
                app.prsData.varData.gSeq{j} = fastaread([app.rawData.files.varSeq{j}]);
                app.prsData.varData.gSeq{j} = upper(app.prsData.varData.gSeq{j});
                app.prsData.varData.pSeq{j} = nt2aa(app.prsData.varData.gSeq{j}, 'AlternativeStartCodons', false);
                app.prsData.varData.varExpected{j} = tabulateExpectedVariantsGen(app.prsData.varData.gSeq{j},app.prsData.varData.pSeq{j},0);
            end
            
            %load pdbs
            app.StatusEditField.Value = ['loading Files: pdb data']; pause(0.01);
            less = 0;
            numPDBs = 0;
            loadedPDBs = {};
            loadedPDB_IDs = {};
            loadedPDBFiles = {};
            loadedPDBFilePointer = {};
            temp = {};
            temp2 = {};
            for j = 1:app.rawData.nGenes
                app.StatusEditField.Value = ['loading Files: variant data: checking pdb #' num2str(j)]; pause(0.01);
                if j == 1
                    loadedPDBs{j} = pdbread(app.rawData.files.pdb{j});
                    loadedPDB_IDs{j} = app.rawData.files.pdb{j};
                    numPDBs = numPDBs + 1;
                    loadedPDBFiles{j} = app.rawData.files.pdb{j};
                    loadedPDBFilePointer{j} = j;
                    combinedPDBs.Model.Atom = loadedPDBs{j}.Model.Atom;
                    temp2 = num2cell(ones(size(combinedPDBs.Model.Atom,1),size(combinedPDBs.Model.Atom,2),'int8').*numPDBs);
                    [combinedPDBs.Model.Atom.pdbNum] = temp2{:};
                else
                    found = 0;
                    foundAt = 0;
                    for i = 1:size(loadedPDBFiles,2)
                        if strcmp(loadedPDBFiles{i},app.rawData.files.pdb{j})
                            found = 1;
                            foundAt = i;
                            break
                        end
                    end
                    if ~found %is it a new struct
                        loadedPDBs{j} = pdbread(app.rawData.files.pdb{j});
                        loadedPDB_IDs{j} = app.rawData.files.pdb{j};
                        numPDBs = numPDBs + 1;
                        temp = {};
                        temp.Model.Atom = loadedPDBs{j}.Model.Atom;
                        temp2 = num2cell(ones(size(temp.Model.Atom,1),size(temp.Model.Atom,2),'int8').*numPDBs);
                        [temp.Model.Atom.pdbNum] = temp2{:};
                        combinedPDBs.Model.Atom = [combinedPDBs.Model.Atom,temp.Model.Atom];
                        loadedPDBFiles{j} = app.rawData.files.pdb{j};
                        loadedPDBFilePointer{j} = j;
                    else
                        less = less+1;
                        loadedPDBFiles{j} = app.rawData.files.pdb{j};
                        loadedPDBFilePointer{j} = foundAt;
                    end
                end
                
            end
            app.rawData.loadedPDBs = loadedPDBs;
            app.rawData.loadedPDB_IDs = loadedPDB_IDs;
            app.rawData.loadedPDBFiles = loadedPDBFiles;
            app.rawData.loadedPDBFilePointer = loadedPDBFilePointer;
            app.rawData.numPDBs = numPDBs;
            app.prsData.combinedPDBs = combinedPDBs;
            clear temp temp2 found foundAt i j less loadedPDBFiles loadedPDBFilePointer numPDBs loadedPDBs combinedPDBs
            %then combine pbs into one
            
            app.rawData.oldchains = app.rawData.chains;
            [app.prsData.combinedPDBs,app.rawData.chains] = updateChainIDforApp(app,app.prsData.combinedPDBs,app.rawData.nGenes,app.rawData.chains,app.rawData.loadedPDBFilePointer);
            
            %load pdb gene data
            app.StatusEditField.Value = ['loading Files: pdb gene data']; pause(0.01);
            for j = 1:app.rawData.nGenes
                app.prsData.pdbData.gSeq{j} = fastaread([app.rawData.files.pdbSeq{j}]);
                app.prsData.pdbData.gSeq{j} = upper(app.prsData.pdbData.gSeq{j});
                app.prsData.pdbData.pSeq{j} = nt2aa(app.prsData.pdbData.gSeq{j}, 'AlternativeStartCodons', false);
            end
            
            
        end
        
        function alignSequences(app)
            
            chainLookup = struct;
            chainLookup.chain = '';
            n=0;
            for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                curC = split(app.rawData.chains{j},',');
                for k = 1:size(curC,1)
                    n = n + 1;
                    chainLookup.chain(n) = (curC{k});
                    chainLookup.ID{n} = {app.rawData.ID{j}};
                    chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                end
            end
            
            pdbS = app.prsData.combinedPDBs.Model.Residue;
            pdbSeq = struct;
            pdbSeq.n = zeros(size(pdbS,2),1);
            pdbSeq.chain = char(size(pdbS,2),1);
            pdbSeq.res = char(size(pdbS,2),1);
            pdbSeqByChain = {};
            pdbSeqByChainLabel = {};
            st_en = [];
            tracking = struct;
            for k = 1:size(pdbS,2)
                if strcmp(pdbS(k).resName,'UNK')
                    continue
                end
                temp = (pdbS(k).resName);
                if strcmp(temp,'HIE')
                    temp = 'HIS';
                end
                if strcmp(temp,'LYN')
                    temp = 'LYS';
                end
                temp = aminolookup(temp);
                tempChain = pdbS(k).chainID;
                pdbSeq(k).res = temp;
                pdbSeq(k).n = pdbS(k).resSeq;
                pdbSeq(k).chain = tempChain;
                if ~isempty(pdbSeqByChainLabel)
                    isIn = 0;
                    for m = 1:size(pdbSeqByChainLabel,2)
                        if strcmp(tempChain,pdbSeqByChainLabel{m})
                            isIn = 1;
                            break
                        end
                    end
                    if isIn
                        tempSeq = pdbSeqByChain{m};
                        pdbSeqByChain{m} = [tempSeq temp];
                        st_en(2,m) = k;
                    else
                        pdbSeqByChain{m+1} = { temp };
                        pdbSeqByChainLabel{m+1} = tempChain;
                        tracking(m+1).chain = pdbS(k).chainID;
                        st_en(1,m+1) = k;
                        st_en(2,m+1) = k;
                    end
                else
                    pdbSeqByChain{1} = { temp };
                    pdbSeqByChainLabel{1} = tempChain;
                    
                    tracking.chain = pdbS(k).chainID;
                    st_en(1,1) = 1;
                    st_en(2,1) = 1;
                end
            end
            a=1;
            tempPDBSeq=struct;
            lastN = pdbSeq(1).n-1;
            if app.patchGaps.Value
                GapSize = 1;
            else
                GapSize = 9999;
            end
            for i = 1:size(pdbSeq,2)
                if i == 1
                    ii=1;
                else
                    ii = size(tempPDBSeq,2)+1;
                end
                if ((lastN + 1) == pdbSeq(i).n) %if it is a new entry add
                    tempPDBSeq(ii).n = pdbSeq(i).n;
                    temp3 = pdbSeq(i).res;
                    %                     if strcmp(temp3,'HIE')
                    %                         temp3 = 'HIS';
                    %                     end
                    tempPDBSeq(ii).res = temp3;
                    tempPDBSeq(ii).chain = pdbSeq(i).chain;
                elseif ((lastN + 1) >= pdbSeq(i).n) %if it jumps back down to a new chain add the new one
                    tempPDBSeq(ii).n = pdbSeq(i).n;
                    temp3 = pdbSeq(i).res;
                    %                     if strcmp(temp3,'HIE')
                    %                         temp3 = 'HIS';
                    %                     end
                    tempPDBSeq(ii).res = temp3;
                    tempPDBSeq(ii).chain = pdbSeq(i).chain;
                elseif (pdbSeq(i).n-(lastN + 1))>GapSize %150 %if the gap is super big dont fill in Xs
                    tempPDBSeq(ii).n = pdbSeq(i).n;
                    temp3 = pdbSeq(i).res;
                    %                     if strcmp(temp3,'HIE')
                    %                         temp3 = 'HIS';
                    %                     end
                    tempPDBSeq(ii).res = temp3;
                    tempPDBSeq(ii).chain = pdbSeq(i).chain;
                else % if there is a gap then add some blank residues (X)
                    for iii = ((lastN+1:pdbSeq(i).n) - lastN)
                        ii = size(tempPDBSeq,2)+1;
                        tempPDBSeq(ii).n = tempPDBSeq(ii-1).n+1;
                        tempPDBSeq(ii).res = 'X';
                        tempPDBSeq(ii).chain = pdbSeq(i).chain;
                    end
                    temp3 = pdbSeq(i).res;
                    %                     if strcmp(temp3,'HIE')
                    %                         temp3 = 'HIS';
                    %                     end
                    tempPDBSeq(ii).res = temp3;
                    
                end
                lastN = pdbSeq(i).n;
            end
            app.prsData.pdbData.pdbSeq = pdbSeq;
            app.prsData.pdbData.pdbSeqPatch = tempPDBSeq;
            %create one table of the PDB residues adding in obs/exp variant data
            corVecA = [];
            corVecB = [];
            residueChecking = struct;
            for i = 1:app.rawData.nGenes
                cStr = split(app.rawData.chains{i},',');
                for j = 1:size(cStr,1)
                    cMTR = app.prsData.varData.mtrSW{i};
                    cSeq = '';
                    for k = 1:size(cMTR,2)
                        cSeq = [cSeq cMTR(k).res];
                    end
                    
                    firstCodon = 1000;
                    for k = 1:size(pdbS,2)
                        if cStr{j} == pdbS(1,k).chainID
                            if pdbS(1,k).resSeq < firstCodon
                                firstCodon = pdbS(1,k).resSeq;
                            end
                        end
                    end
                    
                    %now match current Seqence to pdb sequence
                    w = 0;
                    for k = 1:size(pdbSeqByChainLabel,2)
                        if pdbSeqByChainLabel{k} == cStr{j}
                            w=k;
                        end
                    end
                    
                    temp = '';
                    tempMTR = app.prsData.varData.mtr{i};
                    for k = 1:size(tempMTR,2)
                        if isempty(tempMTR(1,k).resEx)
                            temp = [temp '-'];
                        else
                            temp2 = (tempMTR(1,k).resEx);
                            
                            if strcmp(temp2,'HIE')
                                temp2 = 'HIS';
                            end
                            if strcmp(temp2,'LYN')
                                temp2 = 'LYS';
                            end
                            temp2 = aminolookup(temp2);
                            temp = [temp temp2];
                        end
                    end
                    
                    curPDBSeqComp = app.prsData.pdbData.pdbSeqPatch(strcmp({app.prsData.pdbData.pdbSeqPatch.chain}, cStr{j}));
                    curPDBGSeqComp = struct;
                    curPDBGSeqComp.n = [];
                    curPDBGSeqComp.res = '';
                    curVarGSeqComp = struct;
                    curVarGSeqComp.n = [];
                    curVarGSeqComp.res = '';
                    curVarSeqComp = app.prsData.varData.varSeq{i};
                    for k = 1:size(app.prsData.pdbData.pSeq{1,i},2)
                        curPDBGSeqComp(k).n = k;
                        curPDBGSeqComp(k).res = app.prsData.pdbData.pSeq{1,i}(k);
                    end
                    for k = 1:size(app.prsData.varData.pSeq{1,i},2)
                        curVarGSeqComp(k).n = k;
                        curVarGSeqComp(k).res = app.prsData.varData.pSeq{1,i}(k);
                    end
                    
                    
                    switch app.AlignmentMethodDropDown.Value
                        case 'standard'
                            
                            [transSeq_pbd2pbdG,transAlign_pbd2pbdG,numAlign_pbd2pbdG] = translateSequenceStructs(curPDBSeqComp,curPDBGSeqComp,'lookforchunks');
                            if strcmp(app.rawData.files.pdbSeq{i},app.rawData.files.varSeq{i})
                                
                                [transSeq_pbdG2varG,transAlign_pbdG2varG,numAlign_pbdG2varG] = translateSequenceStructs(curPDBGSeqComp,curVarGSeqComp,'lookforchunks');
                            else
                                [transSeq_pbdG2varG,transAlign_pbdG2varG,numAlign_pbdG2varG] = translateSequenceStructs(curPDBGSeqComp,curVarGSeqComp,'diff');
                            end
                            [transSeq_varG2var,transAlign_varG2var,numAlign_varG2var] = translateSequenceStructs(curVarGSeqComp,curVarSeqComp);
                        case 'local'
                            
                            [transSeq_pbd2pbdG,transAlign_pbd2pbdG,numAlign_pbd2pbdG] = translateSequenceStructs(curPDBSeqComp,curPDBGSeqComp,'local');
                            [transSeq_pbdG2varG,transAlign_pbdG2varG,numAlign_pbdG2varG] = translateSequenceStructs(curPDBGSeqComp,curVarGSeqComp,'local');
                            [transSeq_varG2var,transAlign_varG2var,numAlign_varG2var] = translateSequenceStructs(curVarGSeqComp,curVarSeqComp);
                        case 'custom'
                            
                            [transSeq_pbd2pbdG,transAlign_pbd2pbdG,numAlign_pbd2pbdG] = translateSequenceStructs(curPDBSeqComp,curPDBGSeqComp,'lookforchunks');
                            selectedFolderPDB = 0;
                            m = 0;
                            for r = 1:size(chainLookup.chain,2)
                                if strcmp(chainLookup.chain(r),pdbSeqByChainLabel{w})
                                    m = r
                                end
                            end
                            answer3 = questdlg(['Use a custom alignment for ' chainLookup.ID{m} '?'], ...
                               	'use custom alignment', ...
                               	'Yes','No','Yes');
                            if strcmp(answer3,'Yes')
                                curFolder = app.baseFolder;
                                while selectedFolderPDB==0
                                    [selectedFilePDB,selectedFolderPDB,~] = uigetfile(...
                                        {'*.txt','structure Files (*.txt)'}, ...
                                        'Select a ascii alignment file that matches the PDB',curFolder,'MultiSelect','off');
                                    app.StatusEditField.Value = 'idle - error, no file selected: select again'; pause(0.01);
                                end
                                selectedFolderGEN = 0;
                                while selectedFolderGEN==0
                                    [selectedFileGEN,selectedFolderGEN,~] = uigetfile(...
                                        {'*.txt','structure Files (*.txt)'}, ...
                                        'Select a ascii alignment file that matches the gnomAD data',curFolder,'MultiSelect','off');
                                    app.StatusEditField.Value = 'idle - error, no file selected: select again'; pause(0.01);
                                end
                                
                                cusPDBalign = importdata([selectedFolderPDB '\' selectedFilePDB]);
                                cusGENalign = importdata([selectedFolderGEN '\' selectedFileGEN]);
                                cusPDBalign = cusPDBalign{1};
                                cusGENalign = cusGENalign{1};
                                
                                bothGaps = (cusPDBalign=='-') & (cusGENalign=='-');
                                
                                cusPDBalign(bothGaps) = '';
                                cusGENalign(bothGaps) = '';
                                cusInbetween = cusPDBalign;
                                cusInbetween(:) = ' ';
                                
                                transAlign_pbdG2varG = [cusPDBalign;cusInbetween;cusGENalign];
                                numAlign_pbdG2varG = zeros(size(transAlign_pbdG2varG,1),size(transAlign_pbdG2varG,2));
                                topCount = 0;
                                botCount = 0;
                                transSeq_pbdG2varG='';
                                for p = 1:size(cusPDBalign,2)
                                    
                                    if ((transAlign_pbdG2varG(1,p)~='-') && (transAlign_pbdG2varG(3,p)~='-'))
                                        botCount = botCount+1;
                                        topCount = topCount+1;
                                        numAlign_pbdG2varG(1,p) = topCount;
                                        numAlign_pbdG2varG(3,p) = botCount;
                                        transSeq_pbdG2varG(p) = transAlign_pbdG2varG(3,p);
                                        
                                    elseif (transAlign_pbdG2varG(3,p)~='-')
                                        botCount = botCount+1;
                                        numAlign_pbdG2varG(3,p) = botCount;
                                        numAlign_pbdG2varG(1,p) = 0;
                                        transSeq_pbdG2varG(p) = transAlign_pbdG2varG(3,p);
                                        
                                    elseif (transAlign_pbdG2varG(1,p)~='-')
                                        topCount = topCount+1;
                                        numAlign_pbdG2varG(1,p) = topCount;
                                        numAlign_pbdG2varG(3,p) = 0;
                                        transSeq_pbdG2varG(p) = transAlign_pbdG2varG(1,p);
                                    end
                                end
                                tempStruc = struct;
                                tempStruc.n = [];
                                tempStruc.res = '';
                                counter = 0;
                                for k = 1:size(cusGENalign,2)
                                    if cusGENalign(k)~='-'
                                        counter = counter+1;
                                        tempStruc(k).n = counter;
                                        tempStruc(k).res = cusGENalign(k);
                                    else
                                        tempStruc(k).n = 0;
                                        tempStruc(k).res = cusGENalign(k);
                                    end
                                end
                                
                                %                                 cusGENalignLess(cusGENalignLess=='-') = '';
                                [transSeq_varG2var,transAlign_varG2var,numAlign_varG2var] = translateSequenceStructs(tempStruc,curVarSeqComp);
                            else
                                answer4 = questdlg(['What kind of alignment do you want to use for chain ' pdbSeqByChainLabel{w} '?'], ...
                                   	'kind of alignment', ...
                                   	'standard','local','standard');
                                switch answer4
                                    case 'standard'
                                        if strcmp(app.rawData.files.pdbSeq{i},app.rawData.files.varSeq{i})
                                            [transSeq_pbdG2varG,transAlign_pbdG2varG,numAlign_pbdG2varG] = translateSequenceStructs(curPDBGSeqComp,curVarGSeqComp,'lookforchunks');
                                        else
                                            [transSeq_pbdG2varG,transAlign_pbdG2varG,numAlign_pbdG2varG] = translateSequenceStructs(curPDBGSeqComp,curVarGSeqComp,'diff');
                                        end
                                    case 'local'
                                        [transSeq_pbdG2varG,transAlign_pbdG2varG,numAlign_pbdG2varG] = translateSequenceStructs(curPDBGSeqComp,curVarGSeqComp,'local');
                                end
                                [transSeq_varG2var,transAlign_varG2var,numAlign_varG2var] = translateSequenceStructs(curVarGSeqComp,curVarSeqComp);
                            end
                            
                    end


                    [fullAlign fullNumAlign] = makeFullAlignment(transAlign_pbd2pbdG,transAlign_pbdG2varG,transAlign_varG2var,...
                        numAlign_pbd2pbdG,numAlign_pbdG2varG,numAlign_varG2var);


                    %Join the pbd structure with the mtr structure
                    chainTemp = [pdbS.chainID];
                    resNTemp = [pdbS.resSeq];
                    for k = 1:size(pdbS,2)
                        if pdbS(1,k).chainID~=cStr{j}
                            continue
                        end
                        pdbN = find(fullNumAlign(1,:) == pdbS(1,k).resSeq);
                        if isempty(pdbN)
                            a=1;
                        end
                        %                         [pd,mtrN,gn] = pdbResRelatedToX(numAlign_pbd2pbdG,numAlign_pbdG2varG,numAlign_varG2var,pdbN);
                        %
                        
                        %                         k
                        %                         mtrN
                        
                        pdbS(k).pdbResN = fullNumAlign(1,pdbN);
                        pdbS(k).pdbRes = fullAlign(1,pdbN);
                        pdbS(k).pdbGResN = fullNumAlign(3,pdbN);
                        pdbS(k).pdbGRes = fullAlign(3,pdbN);
                        mtrN = fullNumAlign(5,pdbN);
                        
                        if isempty(mtrN)
                            continue;
                        end
                        if mtrN ==0
                            a=1;
                            mtrN = fullNumAlign(7,pdbN);
                            
                            if mtrN ==0
                                continue;
                            end
                            
                            pdbS(k).varGResN = fullNumAlign(7,pdbN);
                            pdbS(k).varGRes = fullAlign(7,pdbN);
                        else
                            
                            pdbS(k).varGResN = fullNumAlign(5,pdbN);
                            pdbS(k).varGRes = fullAlign(5,pdbN);
                        end
                        pdbS(k).pdbResN = fullNumAlign(1,pdbN);
                        pdbS(k).pdbRes = fullAlign(1,pdbN);
                        pdbS(k).pdbGResN = fullNumAlign(3,pdbN);
                        pdbS(k).pdbGRes = fullAlign(3,pdbN);
                        %                         pdbS(k).varGResN = fullNumAlign(5,pdbN);
                        %                         pdbS(k).varGRes = fullAlign(5,pdbN);
                        pdbS(k).varResN = fullNumAlign(7,pdbN);
                        pdbS(k).varRes = fullAlign(7,pdbN);
                        pdbS(k).resG = cMTR(mtrN).res;
                        pdbS(k).resGB = cMTR(mtrN).resGB;
                        pdbS(k).resEx = cMTR(mtrN).resEx;
                        pdbS(k).codon = cMTR(mtrN).codon;
                        pdbS(k).eSSvar = cMTR(mtrN).eSSvar;
                        pdbS(k).eMSvar = cMTR(mtrN).eMSvar;
                        pdbS(k).oSSvar = cMTR(mtrN).oSSvar;
                        pdbS(k).oMSvar = cMTR(mtrN).oMSvar;
                        pdbS(k).eSSsw = cMTR(mtrN).eSSsw;
                        pdbS(k).eMSsw = cMTR(mtrN).eMSsw;
                        pdbS(k).oSSsw = cMTR(mtrN).oSSsw;
                        pdbS(k).oMSsw = cMTR(mtrN).oMSsw;
                        pdbS(k).eSS = cMTR(mtrN).eSS;
                        pdbS(k).eMS = cMTR(mtrN).eMS;
                        pdbS(k).oSS = cMTR(mtrN).oSS;
                        pdbS(k).oMS = cMTR(mtrN).oMS;
                        pdbS(k).mtr = cMTR(mtrN).mtr;
                        pdbS(k).mtrFx = cMTR(mtrN).mtrFx;
                        pdbS(k).mtrSW = cMTR(mtrN).mtrSW;
                        pdbS(k).width = cMTR(mtrN).width;
                        
                        residueChecking(k).geneRes = pdbS(k).resGB;
                        residueChecking(k).gnomRes = pdbS(k).resEx;
                        residueChecking(k).chainID = pdbS(k).chainID;
                        residueChecking(k).pdbRes = pdbS(k).resName;
                        residueChecking(k).pdbN = pdbN;
                        residueChecking(k).k = k;
                        residueChecking(k).mtrN = mtrN;
                        residueChecking(k).resSeq = pdbS(1,k).resSeq;
                    end
                    %Save each alignment
                    app.prsData.alignments{i,j}.PDBSeqComp = curPDBSeqComp;
                    app.prsData.alignments{i,j}.PDBGSeqComp = curPDBGSeqComp;
                    app.prsData.alignments{i,j}.VarGSeqComp = curVarGSeqComp;
                    app.prsData.alignments{i,j}.VarSeqComp = curVarSeqComp;
                    app.prsData.alignments{i,j}.transSeq_pbd2pbdG = transSeq_pbd2pbdG;
                    app.prsData.alignments{i,j}.transAlign_pbd2pbdG = transAlign_pbd2pbdG;
                    app.prsData.alignments{i,j}.numAlign_pbd2pbdG = numAlign_pbd2pbdG;
                    app.prsData.alignments{i,j}.transSeq_pbdG2varG = transSeq_pbdG2varG;
                    app.prsData.alignments{i,j}.transAlign_pbdG2varG = transAlign_pbdG2varG;
                    app.prsData.alignments{i,j}.numAlign_pbdG2varG = numAlign_pbdG2varG;
                    app.prsData.alignments{i,j}.transSeq_varG2var = transSeq_varG2var;
                    app.prsData.alignments{i,j}.transAlign_varG2var = transAlign_varG2var;
                    app.prsData.alignments{i,j}.numAlign_varG2var = numAlign_varG2var;
                    app.prsData.alignments{i,j}.fullAlign = fullAlign;
                    app.prsData.alignments{i,j}.fullNumAlign = fullNumAlign;
                    app.prsData.alignments{i,j}.ID = app.rawData.ID{i};
                    app.prsData.alignments{i,j}.chain = cStr{j};
                    
                end
                
            end
            %save combined pdb?
            app.prsData.pdbMTR = pdbS;
        end
        
        function updateSequenceTab(app)
            app.StatusEditField.Value = ['updating updating: vMTR alignment tab']; pause(0.01);
            
            
            toShowTable={};
            gene = app.GeneAlignmenttoshowDropDown.Value;
            chain = gene(end);
            gene = gene(1:(find(gene==':',1,"first")-1));
            items = app.rawData.ID;
            for k = 1:size(items,2)
                if strcmp(gene,items{k})
                    break;
                end
            end
            
            chainLookup.chain = '';
            n=0;
            for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                curC = split(app.rawData.chains{j},',');
                for k = 1:size(curC,1)
                    n = n + 1;
                    chainLookup.chain(n) = (curC{k});
                    chainLookup.ID{n} = {app.rawData.ID{j}};
                    chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                end
            end
            
            temp = find(chainLookup.chain==chain,1,'first');
            sel = [0 0];
            for a = 1:size(app.prsData.alignments,1)
                for b = 1:size(app.prsData.alignments,2)
                    if app.prsData.alignments{a,b}.chain==chain
                        sel = [a b];
                    end
                end
            end
            
            
            boxVal = app.GeneAlignmenttoshowDropDown.Value;
            for j = 1:size(app.GeneAlignmenttoshowDropDown.Items,2)
                if strcmp(boxVal,app.GeneAlignmenttoshowDropDown.Items{j})
                    break;
                end
            end
            items = app.GeneAlignmenttoshowDropDown.Items;
            
            temp = app.prsData.alignments{sel(1),sel(2)}.fullAlign;
            temp4 = app.prsData.alignments{sel(1),sel(2)}.fullNumAlign;
            
            toShowTable = {' '};
            
            [a b] = size(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG);
            temp7 = num2cell(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG(1,:),b);
            temp8 = mat2cell(app.prsData.alignments{sel(1),sel(2)}.transAlign_pbd2pbdG,ones(a,1),ones(1,b));
            temp9 = num2cell(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG(3,:),b);
            toShowTable = mergecell(toShowTable,temp7,2);
            toShowTable = mergecell(toShowTable,temp8,2);
            toShowTable = mergecell(toShowTable,temp9,2);
            toShowTable = mergecell(toShowTable,{' '},2);
            
            [a b] = size(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG);
            temp7 = num2cell(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG(1,:),b);
            temp8 = mat2cell(app.prsData.alignments{sel(1),sel(2)}.transAlign_pbdG2varG,ones(a,1),ones(1,b));
            temp9 = num2cell(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG(3,:),b);
            toShowTable = mergecell(toShowTable,temp7,2);
            toShowTable = mergecell(toShowTable,temp8,2);
            toShowTable = mergecell(toShowTable,temp9,2);
            toShowTable = mergecell(toShowTable,{' '},2);
            
            [a b] = size(app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var);
            temp7 = num2cell(app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var(1,:),b);
            temp8 = mat2cell(app.prsData.alignments{sel(1),sel(2)}.transAlign_varG2var,ones(a,1),ones(1,b));
            temp9 = num2cell(app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var(3,:),b);
            toShowTable = mergecell(toShowTable,temp7,2);
            toShowTable = mergecell(toShowTable,temp8,2);
            toShowTable = mergecell(toShowTable,temp9,2);
            toShowTable = mergecell(toShowTable,{' '},2);
            
            %             eachRowTemp = cell(1,size(temp,2));
            %             row1=eachRowTemp;
            %             row1{1,1} = items{j};
            
            %             row2=eachRowTemp;
            %             start = find(temp(1,:)~='-',1,"first");
            %             for i = start:5:size(temp4(1,:),2)
            %                 row2{1,i} = num2str(temp4(1,i));
            %             end
            %             toShowTable =row2;
            %
            %             for i = 1:size(temp,1)
            %                 rowtemp = eachRowTemp;
            %                 for k = 1:size(temp(i,:),2)
            %                     rowtemp{1,k} = temp(i,k);
            %                 end
            %                 toShowTable = mergecell(toShowTable,rowtemp,2);
            %             end


            %             toShowTable = mergecell({items{j};...
            %                 'pdb-numbering';'pdb';'alignment';'pdb gene seq';'pdb gene seq-numbering';' ';...
            %                 'pdb gene seq-numbering';'pdb gene seq';'alignment';'variant gene seq';'variant gene seq-numbering';' ';...
            %                 'variant gene seq-numbering';'variant gene seq';'alignment';'variant gene data';'variant gene data-numbering'},toShowTable,1);
            app.SequenceAlignmentDisplayTable.Data = toShowTable;
            
            colWidths = {};
            colIDs = {};
            for i = 1:size(toShowTable,2)
                colWidths{i} = 35;
                colIDs{i} = i;
            end
            %             colWidths{1} = 'auto';
            %             colIDs{1} = 'Alignment';
            app.SequenceAlignmentDisplayTable.ColumnWidth = colWidths;
            app.SequenceAlignmentDisplayTable.ColumnName = colIDs;
            app.SequenceAlignmentDisplayTable.RowName = {items{j};...
                'pdb-numbering';'pdb';'alignment';'pdb gene seq';'pdb gene seq-numbering';' ';...
                'pdb gene seq-numbering';'pdb gene seq';'alignment';'variant gene seq';'variant gene seq-numbering';' ';...
                'variant gene seq-numbering';'variant gene seq';'alignment';'variant gene data';'variant gene data-numbering'};
            
            %make good alignment plots
            p2pg = app.prsData.alignments{sel(1),sel(2)}.transAlign_pbd2pbdG;
            pg2vg = app.prsData.alignments{sel(1),sel(2)}.transAlign_pbdG2varG;
            vg2v = app.prsData.alignments{sel(1),sel(2)}.transAlign_varG2var;
            
            %             p2pgN = app.prsData.alignments{j,1}.transNumAlign_pbd2pbdG;
            %             pg2vgN = app.prsData.alignments{j,1}.transNumAlign_pbdG2varG;
            %             vg2vN = app.prsData.alignments{j,1}.transNumAlign_varG2var;
            
            [p2pgRasCX p2pgRasCY p2pgRasWX p2pgRasWY] = makeRasterFromAlignment(p2pg);
            [pg2vgRasCX pg2vgRasCY pg2vgRasWX pg2vgRasWY] = makeRasterFromAlignment(pg2vg);
            [vg2vRasCX vg2vRasCY vg2vRasWX vg2vRasWY] = makeRasterFromAlignment(vg2v);
            
            hold(app.alignmentPlot,'off')
            plot(app.alignmentPlot,p2pgRasCX,p2pgRasCY+1+6,'b')
            hold(app.alignmentPlot,'on')
            plot(app.alignmentPlot,p2pgRasWX,p2pgRasWY+0+6,'m')
            plot(app.alignmentPlot,pg2vgRasCX,pg2vgRasCY+1+3,'b')
            plot(app.alignmentPlot,pg2vgRasWX,pg2vgRasWY+0+3,'m')
            plot(app.alignmentPlot,vg2vRasCX,vg2vRasCY+1+0,'b')
            plot(app.alignmentPlot,vg2vRasWX,vg2vRasWY+0+0,'m')
        end
        
        function showOriginalPDB(app)
            app.StatusEditField.Value = ['Updating: updating pdb tab']; pause(0.01);
            
            toShowTable={};
            boxVal = app.PDBtoshowDropDown.Value;
            for j = 1:size(app.PDBtoshowDropDown.Items,2)
                if contains(boxVal,app.PDBtoshowDropDown.Items{j})
                    break;
                end
            end
            if j == 1
                molviewer(app.prsData.combinedPDBs)
            else
                molviewer(app.rawData.loadedPDBs{j-1})
            end
            
        end
        
        function calcSWMTR(app)
            %calculate 31 codon MTR
            app.StatusEditField.Value = ['calculating calculating: sequential sliding window MTR']; pause(0.01);
            
            labels = {};
            chains = {};
            for j = 1:app.rawData.nGenes
                app.prsData.varData.mtr{j} = calculateVariantMTRgenForApp(app.prsData.varData.varExpected{j},app.prsData.varData.varObserved{j});
                [app.prsData.varData.mtrSW{j}...
                    app.prsData.varData.mtrSWvec{j}...
                    app.prsData.varData.mtrSWvecFx{j}...
                    app.prsData.varData.corAlignment_var_gene{j}] = ...
                    calculateSlidingWindowMTRgenForApp(app.prsData.varData.mtr{j},31);
                labels{j} = app.rawData.ID{j}; %?
                chains{j} = app.rawData.chains{j}; %?
            end
        end
        
        function [combinedPDBs,newChains] = updateChainIDforApp(app,combinedPDBs,nGenes,chains,pdbPointers)
            allChainIDs = {};
            for j = 1:size(chains,2)
                curChains = split(chains(1,j),',');
                if j==1
                    allChainIDs = curChains;
                    newChains{1,j} = chains{1,j};
                else
                    newChain={};
                    if pdbPointers{j} ~= j
                        newChains{1,j} = chains{1,j};
                        continue
                    end
                    for i = 1:size(curChains,1)
                        isin = 0;
                        for k = 1:size(allChainIDs,1)
                            if strcmp(allChainIDs{k},curChains{i})
                                isin=1;
                            end
                        end
                        if isin
                            newChain(i) = {char(int8(allChainIDs{end})+1)};
                            allChainIDs = mergecell(allChainIDs,newChain(i),2);
                        end
                    end
                    newChainStr = '';
                    for i = 1:size(newChain,2)
                        if i==1
                            newChainStr = newChain{i};
                        else
                            newChainStr = [newChainStr ',' newChain{i}];
                        end
                    end
                    
                    newChains{1,j} = newChainStr;
                end
            end
            
            for m = 1:size(combinedPDBs.Model.Atom,2)
                if combinedPDBs.Model.Atom(1,m).pdbNum==1
                    continue
                end
                
                newC = split(newChains{1,combinedPDBs.Model.Atom(1,m).pdbNum},',');
                oldC = split(chains{1,combinedPDBs.Model.Atom(1,m).pdbNum},',');
                
                lookup = 0;
                
                for i = 1:size(oldC,1)
                    if oldC{i} == combinedPDBs.Model.Atom(1,m).chainID
                        lookup = i;
                        break
                    end
                end
                if lookup~=0
                    combinedPDBs.Model.Atom(1,m).chainID = newC{lookup};
                end
                
            end
        end
        
        function clearTableAndData(app)
            app.rawData = struct;
            app.prsData = struct;
            app.inputTable.Data = {};
            app.DataLoadedLamp.Color = [0 0 0];
            app.DMTRCalculatedLamp.Color = [0 0 0];
            app.PermutationsCalculatedLamp.Color = [0 0 0];
            
            app.SequenceAlignmentDisplayTable.Data = {};
            hold(app.alignmentPlot,"off");
            plot(app.alignmentPlot,0,0);
            app.GeneAlignmenttoshowDropDown.Items = {};
            
            app.PDBtoshowDropDown.Items = {};
            
            plot(app.vMTRFactorPlot,0,0);
            title(app.vMTRFactorPlot,'');
            
            hold(app.swMTRresultPlot,"off");
            plot(app.swMTRresultPlot,0,0);
            title(app.swMTRresultPlot,'');
            hold(app.vMTRresultPlot,"off");
            plot(app.vMTRresultPlot,0,0);
            title(app.vMTRresultPlot,'');
            app.GenetoViewDropDown.Items = {};
            
            hold(app.Diff_vMTRresultPlot,"off");
            plot(app.Diff_vMTRresultPlot,0,0);
            title(app.Diff_vMTRresultPlot,'');
            app.Diff_GenetoViewDropDown.Items = {};
            
            hold(app.Perm_vMTRresultPlot,"off");
            plot(app.Perm_vMTRresultPlot,0,0);
            title(app.Perm_vMTRresultPlot,'');
            app.Perm_GenetoViewDropDown.Items = {};
        end
        
        function calculateResidueDistancesGenForApp(app)
            app.StatusEditField.Value = ['calculating calculating: pdb distances']; pause(0.001);
            i=1;
            structPDB = app.prsData.combinedPDBs;
            structPDB.Model.Residue = struct();
            resNum = 1;
            allLoc = [];
            allRes = {};
            while i <= size(structPDB.Model.Atom,2)
                
                app.StatusEditField.Value = ['calculating calculating: creating residues from all atoms: ' num2str(i) ' of ' num2str(size(structPDB.Model.Atom,2))]; pause(0.0001);
                res = structPDB.Model.Atom(i).resSeq;
                chain = structPDB.Model.Atom(i).chainID;
                j=i;
                curX = [];
                curY = [];
                curZ = [];
                while (structPDB.Model.Atom(j).resSeq == res && ...
                        structPDB.Model.Atom(j).chainID == chain)
                    
                    curX = [curX structPDB.Model.Atom(j).X];
                    curY = [curY structPDB.Model.Atom(j).Y];
                    curZ = [curZ structPDB.Model.Atom(j).Z];
                    
                    j = j + 1;
                    if j > size(structPDB.Model.Atom,2)
                        break
                    end
                end
                structPDB.Model.Residue(resNum).resName = structPDB.Model.Atom(i).resName;
                structPDB.Model.Residue(resNum).chainID = structPDB.Model.Atom(i).chainID;
                structPDB.Model.Residue(resNum).resSeq = structPDB.Model.Atom(i).resSeq;
                structPDB.Model.Residue(resNum).X = mean(curX);
                structPDB.Model.Residue(resNum).Y = mean(curY);
                structPDB.Model.Residue(resNum).Z = mean(curZ);
                allRes{resNum} = structPDB.Model.Atom(i).resName;
                i=j;
                resNum = resNum + 1;
                allLoc = [allLoc; mean(curX) mean(curY) mean(curZ)];
            end
            
            
            %calc distances
            a=1;
            
            % distances = pdist(allLoc)
            allDistances = zeros(size(allLoc,1),size(allLoc,1));
            for i = 1:size(allDistances,1)
                app.StatusEditField.Value = ['calculating calculating: calculating residue distances: ' num2str(i) ' of ' num2str(size(structPDB.Model.Residue,2))]; pause(0.0001);
                
                allDistances(i,:) = vecnorm((allLoc(i,:) - allLoc(:,:))');
            end
            
            %             allDistances = [];
            %             distancesXYZ = [];
            %             for i = 1:size(structPDB.Model.Residue,2)
            %                 app.StatusEditField.Value = ['calculating calculating: calculating residue distances: ' num2str(i) ' of ' num2str(size(structPDB.Model.Residue,2))]; pause(0.0001);
            %
            %
            %                 distances = vecnorm((allLoc(i,:) - allLoc(:,:))');
            %                 allDistances = [allDistances; distances];
            %             end
            
            %         t = tic
            
            
            a=1;
            
            %calculate factors
            app.StatusEditField.Value = ['calculating calculating: computing factors']; pause(0.001);
            
            %             fact_sqrtr = 1./sqrt(allDistances);
            %             fact_sqrtr(isinf(fact_sqrtr)) = 1;
            %             fact_r = 1./allDistances;
            %             fact_r(isinf(fact_r)) = 1;
            %             fact_rr = 1./(allDistances.^2);
            %             fact_rr(isinf(fact_rr)) = 1;
            %             fact_rrrr = 1./(allDistances.^4);
            %             fact_rrrr(isinf(fact_rrrr)) = 1;
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: t factors']; pause(0.001);
            fact_t05 = allDistances <= 5;
            fact_t10 = allDistances <= 10;
            fact_t15 = allDistances <= 15;
            fact_t20 = allDistances <= 20;
            fact_t30 = allDistances <= 30;
            fact_t40 = allDistances <= 40;
            
            
            fact_t05Avgd = 0;
            fact_t10Avgd = 0;
            fact_t15Avgd = 0;
            fact_t20Avgd = 0;
            fact_t30Avgd = 0;
            fact_t40Avgd = 0;
            for i = 1:size(allDistances,2)
                for j = 1:size(allDistances,1)
                    fact_t05Avgd = fact_t05Avgd + fact_t05(j,i).*allDistances(j,i);
                    fact_t10Avgd = fact_t10Avgd + fact_t10(j,i).*allDistances(j,i);
                    fact_t15Avgd = fact_t15Avgd + fact_t15(j,i).*allDistances(j,i);
                    fact_t20Avgd = fact_t20Avgd + fact_t20(j,i).*allDistances(j,i);
                    fact_t30Avgd = fact_t30Avgd + fact_t30(j,i).*allDistances(j,i);
                    fact_t40Avgd = fact_t40Avgd + fact_t40(j,i).*allDistances(j,i);
                end
            end
            fact_t05Avgd = fact_t05Avgd ./ sum(fact_t05,1);
            fact_t10Avgd = fact_t10Avgd ./ sum(fact_t10,1);
            fact_t15Avgd = fact_t15Avgd ./ sum(fact_t15,1);
            fact_t20Avgd = fact_t20Avgd ./ sum(fact_t20,1);
            fact_t30Avgd = fact_t30Avgd ./ sum(fact_t30,1);
            fact_t40Avgd = fact_t40Avgd ./ sum(fact_t40,1);
            
            fact_t05n = sum(fact_t05,1);
            fact_t05Avgn = mean(fact_t05n,2);
            fact_t10n = sum(fact_t10,1);
            fact_t10Avgn = mean(fact_t10n,2);
            fact_t15n = sum(fact_t15,1);
            fact_t15Avgn = mean(fact_t15n,2);
            fact_t20n = sum(fact_t20,1);
            fact_t20Avgn = mean(fact_t20n,2);
            fact_t30n = sum(fact_t30,1);
            fact_t30Avgn = mean(fact_t30n,2);
            fact_t40n = sum(fact_t40,1);
            fact_t40Avgn = mean(fact_t40n,2);
            
            % test = 0:.1:160;
            % fact_weightab = exp(-(test.^2)./(4.*(10.^2)));
            % figure;plot(test,fact_weightab)
            
            
            %             fact_wfuc10 = exp(-(allDistances.^2)./(2.*(10.^2)));
            %             fact_wfuc10sqrt = sqrt(exp(-(allDistances.^2)./(2.*(10.^2))));
            %             fact_wfuc05 = exp(-(allDistances.^2)./(2.*(5.^2)));
            %             fact_wfuc05sqrt = sqrt(exp(-(allDistances.^2)./(2.*(5.^2))));
            %             fact_wfuc20 = exp(-(allDistances.^2)./(2.*(20.^2)));
            %             fact_wfuc20sqrt = sqrt(exp(-(allDistances.^2)./(2.*(20.^2))));
            %
            %             fact_wfuc10n = sum(fact_wfuc10,1);
            %             fact_wfun10Avgn = mean(fact_wfuc10n,2);
            %             fact_wfuc05n = sum(fact_wfuc05,1);
            %             fact_wfun05Avgn = mean(fact_wfuc05n,2);
            %             fact_wfuc20n = sum(fact_wfuc20,1);
            %             fact_wfun20Avgn = mean(fact_wfuc20n,2);
            
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: c factors']; pause(0.001);
            %find the nearest 11, 21, 31 residues...
            [fact_c11val fact_c11i] = mink(allDistances,11);
            [fact_c21val fact_c21i] = mink(allDistances,21);
            [fact_c31val fact_c31i] = mink(allDistances,31);
            [fact_c15val fact_c15i] = mink(allDistances,15);
            [fact_c25val fact_c25i] = mink(allDistances,25);
            [fact_c35val fact_c35i] = mink(allDistances,35);
            [fact_c41val fact_c41i] = mink(allDistances,41);
            
            fact_c11 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_c21 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_c31 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_c15 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_c25 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_c35 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_c41 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            
            for i = 1:size(allDistances,2)
                for j = 1:size(fact_c11i,1)
                    fact_c11(fact_c11i(j,i),i) = true;
                end
                
                for j = 1:size(fact_c21i,1)
                    fact_c21(fact_c21i(j,i),i) = true;
                end
                
                for j = 1:size(fact_c31i,1)
                    fact_c31(fact_c31i(j,i),i) = true;
                end
                
                for j = 1:size(fact_c15i,1)
                    fact_c15(fact_c15i(j,i),i) = true;
                end
                
                for j = 1:size(fact_c25i,1)
                    fact_c25(fact_c25i(j,i),i) = true;
                end
                
                for j = 1:size(fact_c35i,1)
                    fact_c35(fact_c35i(j,i),i) = true;
                end
                
                for j = 1:size(fact_c41i,1)
                    fact_c41(fact_c41i(j,i),i) = true;
                end
                
            end
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: l factors']; pause(0.001);
            tempDistances = allDistances;
            tempDistances([structPDB.Model.Residue.chainID] ~= [structPDB.Model.Residue.chainID]') = 999;
            [fact_l11val fact_l11i] = mink(tempDistances,11);
            [fact_l21val fact_l21i] = mink(tempDistances,21);
            [fact_l31val fact_l31i] = mink(tempDistances,31);
            [fact_l15val fact_l15i] = mink(tempDistances,15);
            [fact_l25val fact_l25i] = mink(tempDistances,25);
            [fact_l35val fact_l35i] = mink(tempDistances,35);
            [fact_l41val fact_l41i] = mink(tempDistances,41);
            fact_l11 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_l21 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_l31 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_l15 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_l25 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_l35 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_l41 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            
            for i = 1:size(allDistances,2)
                
                
                for j = 1:size(fact_l11i,1)
                    fact_l11(fact_l11i(j,i),i) = true;
                end
                
                for j = 1:size(fact_l21i,1)
                    fact_l21(fact_l21i(j,i),i) = true;
                end
                
                for j = 1:size(fact_l31i,1)
                    fact_l31(fact_l31i(j,i),i) = true;
                end
                
                for j = 1:size(fact_l15i,1)
                    fact_l15(fact_l15i(j,i),i) = true;
                end
                
                for j = 1:size(fact_l25i,1)
                    fact_l25(fact_l25i(j,i),i) = true;
                end
                
                for j = 1:size(fact_l35i,1)
                    fact_l35(fact_l35i(j,i),i) = true;
                end
                
                for j = 1:size(fact_l41i,1)
                    fact_l41(fact_l41i(j,i),i) = true;
                end
            end
            
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: sw factors']; pause(0.001);
            
            fact_sw11 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw15 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw21 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw25 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw31 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw35 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw41 = false(size(allDistances)); %boolean(zeros(size(allDistances)));
            fact_sw11val = zeros(11,size(allDistances,1));
            fact_sw15val = zeros(15,size(allDistances,1));
            fact_sw21val = zeros(21,size(allDistances,1));
            fact_sw25val = zeros(25,size(allDistances,1));
            fact_sw31val = zeros(31,size(allDistances,1));
            fact_sw35val = zeros(35,size(allDistances,1));
            fact_sw41val = zeros(41,size(allDistances,1));
            for i = 1:size(allDistances,2)
                
                app.StatusEditField.Value = ['calculating calculating: computing factors: sw factors: ' num2str(i) ' of ' num2str(size(allDistances,2))]; pause(0.001);
                curRes = structPDB.Model.Residue(1,i).resSeq;
                curChain = structPDB.Model.Residue(1,i).chainID;
                w = 11;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw11(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw11val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw11val(:,i) = temp;
                end
                
                
                w = 15;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw15(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw15val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw15val(:,i) = temp;
                end
                
                
                w =21;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw21(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw21val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw21val(:,i) = temp;
                end
                
                
                w = 25;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw25(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw25val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw25val(:,i) = temp;
                end
                
                
                w = 31;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw31(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw31val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw31val(:,i) = temp;
                end
                
                
                w = 35;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw35(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw35val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw35val(:,i) = temp;
                end
                
                
                w = 41;
                t1 = curRes-((w-1)/2);
                t2 = curRes+((w-1)/2);
                incl = ([structPDB.Model.Residue.chainID]==curChain & ...
                    [structPDB.Model.Residue.resSeq] >= t1 & ...
                    [structPDB.Model.Residue.resSeq] <= t2 );
                fact_sw41(incl,i) = true;
                
                temp = allDistances(incl,i);
                if size(temp,1)<w
                    fact_sw41val(:,i) = [temp;ones(w-size(temp,1),1).*mean(temp)] ;
                else
                    fact_sw41val(:,i) = temp;
                end
                
                
            end
            
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: packing factor structure']; pause(0.001);
            distances = allDistances;
            resIDs = allRes;
            factors.fact_c11 = fact_c11;
            factors.fact_c15 = fact_c15;
            factors.fact_c21 = fact_c21;
            factors.fact_c25 = fact_c25;
            factors.fact_c31 = fact_c31;
            factors.fact_c35 = fact_c35;
            factors.fact_c41 = fact_c41;
            factors.fact_l11 = fact_l11;
            factors.fact_l15 = fact_l15;
            factors.fact_l21 = fact_l21;
            factors.fact_l25 = fact_l25;
            factors.fact_l31 = fact_l31;
            factors.fact_l35 = fact_l35;
            factors.fact_l41 = fact_l41;
            factors.fact_t05 = fact_t05;
            factors.fact_t10 = fact_t10;
            factors.fact_t15 = fact_t15;
            factors.fact_t20 = fact_t20;
            factors.fact_t30 = fact_t30;
            factors.fact_t40 = fact_t40;
            factors.fact_sw11 = fact_sw11;
            factors.fact_sw15 = fact_sw15;
            factors.fact_sw21 = fact_sw21;
            factors.fact_sw25 = fact_sw25;
            factors.fact_sw31 = fact_sw31;
            factors.fact_sw35 = fact_sw35;
            factors.fact_sw41 = fact_sw41;
            %             factors.fact_wfuc05 = fact_wfuc05;
            %             factors.fact_wfuc05sqrt = fact_wfuc05sqrt;
            %             factors.fact_wfuc10 = fact_wfuc10;
            %             factors.fact_wfuc10sqrt = fact_wfuc10sqrt;
            %             factors.fact_wfuc20 = fact_wfuc20;
            %             factors.fact_wfuc20sqrt = fact_wfuc20sqrt;
            %             factors.fact_sqrtr = fact_sqrtr;
            %             factors.fact_r = fact_r;
            %             factors.fact_rr = fact_rr;
            %             factors.fact_rrrr = fact_rrrr;
            
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: packing factor.info structure']; pause(0.001);
            factors.info.fact_t05n = fact_t05n;
            factors.info.fact_t05Avgd = fact_t05Avgd;
            factors.info.fact_t05Avgn = fact_t05Avgn;
            factors.info.fact_t10n = fact_t10n;
            factors.info.fact_t10Avgd = fact_t10Avgd;
            factors.info.fact_t10Avgn = fact_t10Avgn;
            factors.info.fact_t15n = fact_t15n;
            factors.info.fact_t15Avgd = fact_t15Avgd;
            factors.info.fact_t15Avgn = fact_t15Avgn;
            factors.info.fact_t20n = fact_t20n;
            factors.info.fact_t20Avgd = fact_t20Avgd;
            factors.info.fact_t20Avgn = fact_t20Avgn;
            factors.info.fact_t30n = fact_t30n;
            factors.info.fact_t30Avgd = fact_t30Avgd;
            factors.info.fact_t30Avgn = fact_t30Avgn;
            factors.info.fact_t40n = fact_t40n;
            factors.info.fact_t40Avgd = fact_t40Avgd;
            factors.info.fact_t40Avgn = fact_t40Avgn;
            
            
            factors.info.fact_c11val = fact_c11val;
            factors.info.fact_c11valAvgd = mean(fact_c11val,1);
            factors.info.fact_c15val = fact_c15val;
            factors.info.fact_c15valAvgd = mean(fact_c15val,1);
            factors.info.fact_c21val = fact_c21val;
            factors.info.fact_c21valAvgd =  mean(fact_c21val,1);
            factors.info.fact_c25val = fact_c25val;
            factors.info.fact_c25valAvgd =  mean(fact_c25val,1);
            factors.info.fact_c31val = fact_c31val;
            factors.info.fact_c31valAvgd =  mean(fact_c31val,1);
            factors.info.fact_c35val = fact_c35val;
            factors.info.fact_c35valAvgd =  mean(fact_c35val,1);
            factors.info.fact_c41val = fact_c41val;
            factors.info.fact_c41valAvgd =  mean(fact_c41val,1);
            
            factors.info.fact_l11val = fact_l11val;
            factors.info.fact_l11valAvgd = mean(fact_l11val,1);
            factors.info.fact_l15val = fact_l15val;
            factors.info.fact_l15valAvgd = mean(fact_l15val,1);
            factors.info.fact_l21val = fact_l21val;
            factors.info.fact_l21valAvgd =  mean(fact_l21val,1);
            factors.info.fact_l25val = fact_l25val;
            factors.info.fact_l25valAvgd =  mean(fact_l25val,1);
            factors.info.fact_l31val = fact_l31val;
            factors.info.fact_l31valAvgd =  mean(fact_l31val,1);
            factors.info.fact_l35val = fact_l35val;
            factors.info.fact_l35valAvgd =  mean(fact_l35val,1);
            factors.info.fact_l41val = fact_l41val;
            factors.info.fact_l41valAvgd =  mean(fact_l41val,1);
            
            factors.info.fact_sw11val = fact_sw11val;
            factors.info.fact_sw11valAvgd = mean(fact_sw11val,1);
            factors.info.fact_sw15val = fact_sw15val;
            factors.info.fact_sw15valAvgd = mean(fact_sw15val,1);
            factors.info.fact_sw21val = fact_sw21val;
            factors.info.fact_sw21valAvgd =  mean(fact_sw21val,1);
            factors.info.fact_sw25val = fact_sw25val;
            factors.info.fact_sw25valAvgd =  mean(fact_sw25val,1);
            factors.info.fact_sw31val = fact_sw31val;
            factors.info.fact_sw31valAvgd =  mean(fact_sw31val,1);
            factors.info.fact_sw35val = fact_sw35val;
            factors.info.fact_sw35valAvgd =  mean(fact_sw35val,1);
            factors.info.fact_sw41val = fact_sw41val;
            factors.info.fact_sw41valAvgd =  mean(fact_sw41val,1);
            
            app.StatusEditField.Value = ['calculating calculating: computing factors: packing residue structure']; pause(0.001);
            for i = 1:size(structPDB.Model.Residue,2)
                structPDB.Model.Residue(i).fact_c11 = fact_c11(:,i);
                structPDB.Model.Residue(i).fact_c15 = fact_c15(:,i);
                structPDB.Model.Residue(i).fact_c21 = fact_c21(:,i);
                structPDB.Model.Residue(i).fact_c25 = fact_c25(:,i);
                structPDB.Model.Residue(i).fact_c31 = fact_c31(:,i);
                structPDB.Model.Residue(i).fact_c35 = fact_c35(:,i);
                structPDB.Model.Residue(i).fact_c41 = fact_c41(:,i);
                structPDB.Model.Residue(i).fact_l11 = fact_l11(:,i);
                structPDB.Model.Residue(i).fact_l15 = fact_l15(:,i);
                structPDB.Model.Residue(i).fact_l21 = fact_l21(:,i);
                structPDB.Model.Residue(i).fact_l25 = fact_l25(:,i);
                structPDB.Model.Residue(i).fact_l31 = fact_l31(:,i);
                structPDB.Model.Residue(i).fact_l35 = fact_l35(:,i);
                structPDB.Model.Residue(i).fact_l41 = fact_l41(:,i);
                structPDB.Model.Residue(i).fact_t05 = fact_t05(:,i);
                structPDB.Model.Residue(i).fact_t10 = fact_t10(:,i);
                structPDB.Model.Residue(i).fact_t15 = fact_t15(:,i);
                structPDB.Model.Residue(i).fact_t20 = fact_t20(:,i);
                structPDB.Model.Residue(i).fact_t30 = fact_t30(:,i);
                structPDB.Model.Residue(i).fact_t40 = fact_t40(:,i);
                structPDB.Model.Residue(i).fact_sw11 = fact_sw11(:,i);
                structPDB.Model.Residue(i).fact_sw15 = fact_sw15(:,i);
                structPDB.Model.Residue(i).fact_sw21 = fact_sw21(:,i);
                structPDB.Model.Residue(i).fact_sw25 = fact_sw25(:,i);
                structPDB.Model.Residue(i).fact_sw31 = fact_sw31(:,i);
                structPDB.Model.Residue(i).fact_sw35 = fact_sw35(:,i);
                structPDB.Model.Residue(i).fact_sw41 = fact_sw41(:,i);
                %                             structPDB.Model.Residue(i).fact_sqrtr = fact_sqrtr(:,i);
                %                             structPDB.Model.Residue(i).fact_r = fact_r(:,i);
                %                             structPDB.Model.Residue(i).fact_rr = fact_rr(:,i);
                %                             structPDB.Model.Residue(i).fact_rrrr = fact_rrrr(:,i);
                %                             structPDB.Model.Residue(i).fact_wfuc05 = fact_wfuc05(:,i);
                %                             structPDB.Model.Residue(i).fact_wfuc05sqrt = fact_wfuc05sqrt(:,i);
                %                             structPDB.Model.Residue(i).fact_wfuc10 = fact_wfuc10(:,i);
                %                             structPDB.Model.Residue(i).fact_wfuc10sqrt = fact_wfuc10sqrt(:,i);
                %                             structPDB.Model.Residue(i).fact_wfuc20 = fact_wfuc20(:,i);
                %                             structPDB.Model.Residue(i).fact_wfuc20sqrt = fact_wfuc20sqrt(:,i);
            end
            
            
            fNames = fieldnames(factors);
            
            app.prsData.distances = distances;
            app.prsData.allLoc = allLoc;
            app.prsData.resIDs = resIDs;
            app.prsData.factors = factors;
            app.prsData.fNames = fNames;
            app.prsData.combinedPDBs = structPDB;


        end
        
        function results = updateMTRFactorTAB(app)
            app.StatusEditField.Value = ['updating updating: vMTR factor tab']; pause(0.01);
            option = app.CalcuationFactorDropDown.Value;
            if strcmp(option,'rawDistances')
                toplot = app.prsData.distances;
                toplot = toplot./max(max(toplot));
                cmap = redblue(255);
                toplot3 = zeros(size(toplot,1),size(toplot,2),3);
                toplot = toplot.*254;
                for i = 1:size(toplot,1)
                    for j = 1:size(toplot,2)
                        
                        toplot3(i,j,1) = cmap(1+round(toplot(i,j)),1);
                        toplot3(i,j,2) = cmap(1+round(toplot(i,j)),2);
                        toplot3(i,j,3) = cmap(1+round(toplot(i,j)),3);
                    end
                end
                %                 toplot3 = cmap(1+round(toplot.*254));
                imagesc(app.vMTRFactorPlot,toplot3);
                app.vMTRFactorPlot.Title.String = ['vMTR Includsion Matrix: ' option];
            else
                toplot = getfield(app.prsData.factors,option);
                im = zeros(size(toplot,1),size(toplot,2),3,'uint8');
                im(:,:,1) = ~toplot.*255;
                im(:,:,2) = ~toplot.*255;
                im(:,:,3) = ~toplot.*255;
                
                imagesc(app.vMTRFactorPlot,im);
            end
            app.vMTRFactorPlot.Title.String = ['vMTR Includsion Matrix: ' option];
            axis(app.vMTRFactorPlot,'tight');
        end
        
        function results = updateMTRresultsTAB(app)
            app.StatusEditField.Value = ['updating updating: vMTR results tab']; pause(0.01);
            gene = app.GenetoViewDropDown.Value;
            chain = gene(end);
            gene = gene(1:(find(gene==':',1,"first")-1));
            items = app.rawData.ID;
            
            
            chainLookup.chain = '';
            n=0;
            for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                curC = split(app.rawData.chains{j},',');
                for k = 1:size(curC,1)
                    n = n + 1;
                    chainLookup.chain(n) = (curC{k});
                    chainLookup.ID{n} = {app.rawData.ID{j}};
                    chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                end
            end
            
            for k = 1:size(items,2)
                if strcmp(gene,items{k})
                    break;
                end
            end
            
            resN = [app.prsData.varData.mtrSW{k}.resN];
            mtrSW = [app.prsData.varData.mtrSW{k}.mtrSW];
            hold(app.swMTRresultPlot,'off')
            plot(app.swMTRresultPlot,resN,mtrSW,'m-');
            hold(app.swMTRresultPlot,'on')
            plot(app.swMTRresultPlot,resN,mtrSW,'k.');
            axis(app.swMTRresultPlot,'tight');
            xlim(app.swMTRresultPlot,[0 app.swMTRresultPlot.XLim(2)]);
            ylim(app.swMTRresultPlot,[-.05 app.swMTRresultPlot.YLim(2)]);
            if app.swMTRresultPlot.YLim(2)<1
                ylim(app.swMTRresultPlot,[-.05 1]);
            end
            
            app.swMTRresultPlot.Title.String = ['swMTR: ' gene];
            
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                factor = app.CalcuationFactortoShowDropDown.Value;
                
                for j = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,j).meth,factor)
                        break;
                    end
                end
                
                
                curMTR = app.prsData.vMTRpdb(1,j).vMTR;
                toPlotI = ([app.prsData.pdbMTR.chainID] == chain);
                resN = zeros(size(toPlotI,1),size(toPlotI,2));
                for j = 1:size(toPlotI,2)
                    if isempty(app.prsData.pdbMTR(j).varGResN)
                        resN(j) = 0;
                        toPlotI(j) = 0;
                    else
                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                    end
                end
                
                %adjust to Gene Residues numbering ...
                chainLookup.chain = '';
                n=0;
                for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                    curC = split(app.rawData.chains{j},',');
                    for k = 1:size(curC,1)
                        n = n + 1;
                        chainLookup.chain(n) = (curC{k});
                        chainLookup.ID{n} = {app.rawData.ID{j}};
                        chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                    end
                end


                temp = find(chainLookup.chain==chain,1,'first');
                sel = [0 0];
                for a = 1:size(app.prsData.alignments,1)
                    for b = 1:size(app.prsData.alignments,2)
                        if app.prsData.alignments{a,b}.chain==chain
                            sel = [a b];
                        end
                    end
                end
                %                 for c = 1:size(resN,2)
                %                     if toPlotI(c)
                %                         [temp,~,~] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var, resN(c));
                %                         if ~isempty(temp)
                %                             resN(c) = temp;
                %                         else
                %                             resN(c) = resN(c-1)+1;
                %                         end
                %                         clear temp
                %                     end
                %                 end
                
                if resN(find(toPlotI,1,'first'))>1000
                    resN = resN-1000;
                end
                
                hold(app.vMTRresultPlot,'off')
                plot(app.vMTRresultPlot,resN(toPlotI),curMTR(toPlotI),'m-');
                hold(app.vMTRresultPlot,'on')
                plot(app.vMTRresultPlot,resN(toPlotI),curMTR(toPlotI),'k.');
                axis(app.vMTRresultPlot,'tight');
                xlim(app.vMTRresultPlot,[0 app.vMTRresultPlot.XLim(2)]);
                ylim(app.vMTRresultPlot,[-.05 app.vMTRresultPlot.YLim(2)]);
                if app.vMTRresultPlot.YLim(2)<1
                    ylim(app.vMTRresultPlot,[-.05 1]);
                end
                
                app.vMTRresultPlot.Title.String = ['vMTR: ' gene ': ' factor];
                switch app.fittoWhatDropDown.Value
                    case 'pdb'
                        %                         app.swMTRresultPlot.YLim = app.vMTRresultPlot.YLim;
                        app.swMTRresultPlot.XLim = app.vMTRresultPlot.XLim;
                    case 'gene'
                        app.vMTRresultPlot.XLim = app.swMTRresultPlot.XLim;
                        %                         app.vMTRresultPlot.YLim = app.swMTRresultPlot.YLim;
                    case 'tight'
                        %                         app.vMTRresultPlot.YLim = app.swMTRresultPlot.YLim;
                        axis(app.vMTRresultPlot,'tight');
                        app.swMTRresultPlot.XLim = app.vMTRresultPlot.XLim;
                        
                        ylim(app.vMTRresultPlot,[-.05 app.vMTRresultPlot.YLim(2)]);
                        if app.vMTRresultPlot.YLim(2)<1
                            ylim(app.vMTRresultPlot,[-.05 1]);
                        end
                        ylim(app.swMTRresultPlot,[-.05 app.swMTRresultPlot.YLim(2)]);
                        if app.swMTRresultPlot.YLim(2)<1
                            ylim(app.swMTRresultPlot,[-.05 1]);
                        end
                        
                        
                end
            end


        end
        
        function results = calculateVMTR(app)
            %calc
            app.StatusEditField.Value = ['calculating calculating: volumetric MTR']; pause(0.01);
            
            calculateVolumetricMTRgenForApp(app)
        end
        
        function calculateVolumetricMTRgenForApp(app)
            %             mtrWS = calculateVolumetricMTRgenForApp(app)
            
            fNames = fieldnames(app.prsData.factors);
            mtrV = struct;
            for k = 1:size(fNames,1)
                curFname = fNames{k};
                app.StatusEditField.Value = ['calculating calculating: volumetric MTR:' num2str(k) ' of ' num2str(size(fNames,1)) ': ' curFname]; pause(0.01);
                
                if contains(fNames{k},'_t') || contains(fNames{k},'_c') || contains(fNames{k},'_sw') || contains(fNames{k},'_l')
                    vMTR = zeros(1,size(app.prsData.pdbMTR,2));
                    allwidth = zeros(1,size(app.prsData.pdbMTR,2));
                    for i = 1:size(app.prsData.pdbMTR,2)
                        curF = getfield(app.prsData.pdbMTR(i),curFname)';
                        mtrV(k).meth = curFname;
                        mtrV(k).factor{i} = curF;
                        %for each spherical thresholds, cycle through each residue, adding
                        %all counts of E and O var, then calc MTR
                        oSSw = sum([app.prsData.pdbMTR(1,find(curF)).oSS]);
                        oMSw = sum([app.prsData.pdbMTR(1,find(curF)).oMS]);
                        eSSw = sum([app.prsData.pdbMTR(1,find(curF)).eSS]);
                        eMSw = sum([app.prsData.pdbMTR(1,find(curF)).eMS]);
                        widthw = sum(curF);
                        
                        mtrw = ( (oMSw/(oMSw+oSSw)) / (eMSw/(eMSw+eSSw)) );
                        vMTR(1,i) = mtrw;
                        allwidth(1,i) = widthw;
                        
                        mtrV(k).oSSw(i) = oSSw;
                        mtrV(k).oMSw(i) = oMSw;
                        mtrV(k).eSSw(i) = eSSw;
                        mtrV(k).eMSw(i) = eMSw;
                        mtrV(k).widthw(i) = widthw;
                        mtrV(k).mtrw(i) = mtrw;
                    end
                    mtrV(k).vMTR = vMTR;
                    mtrV(k).allwidth = allwidth;


                    %For each weight array, go through each reside, add each weighted MTR
                    %(figure out what to do with NANs), divide by sum of the weights.
                    
                elseif contains(fNames{k},'info')
                    
                else
                    vMTR = [];
                    vWT = [];
                    for i = 1:size(app.prsData.pdbMTR,2)
                        mtrTotal = 0;
                        weightTotal = 0;
                        curF = getfield(app.prsData.pdbMTR(i),curFname)';
                        mtrV(k).meth = curFname;
                        mtrV(k).factor{i} = curF;
                        %for each spherical thresholds, cycle through each residue, adding
                        %all counts of E and O var, then calc MTR
                        
                        for j = 1:size(curF,2)
                            oSSw = app.prsData.pdbMTR(j).oSS;
                            oMSw = app.prsData.pdbMTR(j).oMS;
                            eSSw = app.prsData.pdbMTR(j).eSS;
                            eMSw = app.prsData.pdbMTR(j).eMS;
                            mtr = ( (oMSw/(oMSw+oSSw)) / (eMSw/(eMSw+eSSw)) );
                            %handle NAN
                            if isnan(mtr)
                                option = 1;
                                switch option
                                    case 1 %don't add it in...
                                        
                                    case 2 % l'hopital...
                                        
                                        mtr = ( (1) / (eMSw/(eMSw+eSSw)) );
                                        if isnan(mtr)
                                            
                                        else
                                            mtrTotal = mtrTotal + mtr*curF(j);
                                            weightTotal = weightTotal + curF(j);
                                        end
                                        
                                end
                            elseif isempty(mtr)
                                
                            else
                                
                                mtrTotal = mtrTotal + mtr*curF(j);
                                weightTotal = weightTotal + curF(j);
                                
                            end
                        end
                        mtrw = mtrTotal/weightTotal;
                        
                        vMTR = [vMTR mtrw];
                        vWT = [vWT weightTotal];
                        
                        mtrV(k).mtrw(i) = mtrw;
                        mtrV(k).mtrTotal(i) = mtrTotal;
                        mtrV(k).weightTotal(i) = weightTotal;
                    end
                    mtrV(k).vMTR = vMTR;
                    mtrV(k).vWT = vWT;
                end
                
            end
            app.prsData.vMTRpdb = mtrV;
        end
        
        
        function results = updateMTRdiffTAB(app)
            app.StatusEditField.Value = ['updating updating: vMTR difference tab']; pause(0.01);
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                gene = app.Diff_GenetoViewDropDown.Value;
                
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                
                factor1 = app.Diff_Factor1DropDown.Value;
                factor2 = app.Diff_Factor2DropDown.Value;
                cj=0;
                ci=0;
                for j = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,j).meth,factor1)
                        cj =j;
                        break;
                    end
                end
                for i = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,i).meth,factor2)
                        ci = i;
                        
                        break;
                    end
                end
                
                
                if strcmp(factor2,'swMTR')
                    curMTR2 = [app.prsData.pdbMTR.mtrSW];
                else
                    curMTR2 = [app.prsData.vMTRpdb(1,ci).vMTR];
                end
                toPlotI2 = ([app.prsData.pdbMTR.chainID] == chain);
                if strcmp(factor1,'swMTR')
                    curMTR1 = [app.prsData.pdbMTR.mtrSW];
                else
                    curMTR1 = [app.prsData.vMTRpdb(1,cj).vMTR];
                end
                toPlotI1 = ([app.prsData.pdbMTR.chainID] == chain);
                resN = zeros(size(toPlotI1,1),size(toPlotI1,2));
                toremove =[];
                for j = 1:size(toPlotI1,2)
                    if isempty(app.prsData.pdbMTR(j).varGResN)
                        resN(j) = 0;
                        toremove = [toremove j];
                    else
                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                    end
                end
                if ~isempty(toremove)
                    resN(toremove)=[];
                    toPlotI1(toremove)=[];
                    toPlotI2(toremove)=[];
                end
                if resN(find(toPlotI1,1,'first'))>1000
                    resN = resN-1000;
                end


                hold(app.Diff_vMTRresultPlot,'off')
                plot(app.Diff_vMTRresultPlot,resN(toPlotI1),(curMTR2(toPlotI2) - curMTR1(toPlotI1)),'m-');
                hold(app.Diff_vMTRresultPlot,'on')
                plot(app.Diff_vMTRresultPlot,resN(toPlotI1),(curMTR2(toPlotI2) - curMTR1(toPlotI1)),'k.');
                axis(app.Diff_vMTRresultPlot,'tight');
                xlim(app.Diff_vMTRresultPlot,[0 app.Diff_vMTRresultPlot.XLim(2)]);
                
                app.Diff_vMTRresultPlot.Title.String = ['Difference vMTR: ' gene ': ' factor2 ' - ' factor1];
                
            end
        end
        
        function updatePermuationTAB(app)
            app.StatusEditField.Value = ['updating updating: permutations tab']; pause(0.01);
            
            if sum(app.PermutationsCalculatedLamp.Color == [0 1 0])==3
                permutations = app.prsData.permutations;
                %plot results
                gene = app.Perm_GenetoViewDropDown.Value;
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                curMTR = permutations.factorData.vMTR;
                toPlotI = ([app.prsData.pdbMTR.chainID] == chain);
                %                 resN = [app.prsData.pdbMTR.varGResN];
                
                resN = zeros(size(toPlotI,1),size(toPlotI,2));
                for j = 1:size(toPlotI,2)
                    if isempty(app.prsData.pdbMTR(j).varGResN)
                        resN(j) = 0;
                        toPlotI(j) = 0;
                    else
                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                    end
                end
                
                
                if resN(find(toPlotI,1,'first'))>1000
                    resN = resN-1000;
                end
                
                
                temp = (permutations.results);
                temp(isnan(temp)) = mean(mean(temp(~isnan(temp))));
                temp = reshape(temp,size(permutations.results));
                meanPermMTR = mean(temp,1);
                stdPermMTR = std(temp,1);
                
                
                stdArea1 = (meanPermMTR(toPlotI)-stdPermMTR(toPlotI));
                stdArea2 = (meanPermMTR(toPlotI)+stdPermMTR(toPlotI));
                stdArea = [stdArea1 stdArea2(end:-1:1)];
                stdAreaResN1 = (resN(toPlotI));
                stdAreaResN2 = (resN(toPlotI));
                stdAreaResN = [stdAreaResN1 stdAreaResN2(end:-1:1)];
                stdArea2x1 = (meanPermMTR(toPlotI)-(2.*stdPermMTR(toPlotI)));
                stdArea2x2 = (meanPermMTR(toPlotI)+(2.*stdPermMTR(toPlotI)));
                stdArea2x = [stdArea2x1 stdArea2x2(end:-1:1)];
                
                permutations.what = app.Perm_GenetoViewDropDown.Value;
                permutations.meanPermMTR = meanPermMTR;
                permutations.stdPermMTR = stdPermMTR;
                permutations.toPlotI = toPlotI;
                permutations.factor = app.Perm_CalcuationFactortoShowDropDown.Value;
                permutations.how = app.PermuteWhatDropDown.Value;
                permutations.resN = resN;
                
                hold(app.Perm_vMTRresultPlot,'off')
                fill(app.Perm_vMTRresultPlot,stdAreaResN,stdArea2x,[.5 .5 .5],'FaceAlpha', 0.3,'LineStyle','none')
                hold(app.Perm_vMTRresultPlot,'on')
                fill(app.Perm_vMTRresultPlot,stdAreaResN,stdArea,[.5 .5 .5],'FaceAlpha', 0.3,'LineStyle','none')
                plot(app.Perm_vMTRresultPlot,resN(toPlotI),meanPermMTR(toPlotI),'k')
                plot(app.Perm_vMTRresultPlot,resN(toPlotI),curMTR(toPlotI),'m-');
                plot(app.Perm_vMTRresultPlot,resN(toPlotI),curMTR(toPlotI),'k.');
                axis(app.Perm_vMTRresultPlot,'tight');
                %                 xlim(app.Perm_vMTRresultPlot,[0 app.Perm_vMTRresultPlot.XLim(2)]);
                ylim(app.Perm_vMTRresultPlot,[-.05 app.Perm_vMTRresultPlot.YLim(2)]);
                if app.Perm_vMTRresultPlot.YLim(2)<1
                    ylim(app.Perm_vMTRresultPlot,[-.05 1]);
                end
                title(app.Perm_vMTRresultPlot,['vMTR and Permutations: ' permutations.what ': ' permutations.factor ': ' permutations.how ': ' num2str(permutations.numP) ' Permutations']);
                ylabel(app.Perm_vMTRresultPlot,'MTR')
                xlabel(app.Perm_vMTRresultPlot,'Residue (pdb numbering)')
                
                app.prsData.permutations = permutations;
                
            end
        end
        

        function updateResidueDropDownBox(app)
            
            chainLookup.chain = '';
            n=0;
            for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                curC = split(app.rawData.chains{j},',');
                for k = 1:size(curC,1)
                    n = n + 1;
                    chainLookup.chain(n) = (curC{k});
                    chainLookup.ID{n} = {app.rawData.ID{j}};
                    chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                end
            end
            
            %make list of residue
            temp = cell(size(app.prsData.pdbMTR,2),1);
            for i = 1:size(app.prsData.pdbMTR,2)
                tempRes = app.prsData.pdbMTR(i);
                %                 temp2 = tempRes.resName;
                %
                %                 if strcmp(temp2,'HIE')
                %                     temp2 = 'HIS';
                %                 end
                %                 if strcmp(temp2,'LYN')
                %                     temp2 = 'LYS';
                %                 end
                %
                chain = tempRes.chainID;
                
                temp2 = app.prsData.pdbMTR(i).pdbRes;
                temp2N = app.prsData.pdbMTR(i).pdbResN;
                temp3 = app.prsData.pdbMTR(i).varGRes;
                temp3N = app.prsData.pdbMTR(i).varGResN;
                
                %                 sel = [0 0];
                %                 for a = 1:size(app.prsData.alignments,1)
                %                     for b = 1:size(app.prsData.alignments,2)
                %                         if app.prsData.alignments{a,b}.chain==chain
                %                             sel = [a b];
                %                         end
                %                     end
                %                 end
                %                 [temp3,~,~] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var, tempRes.resSeq);
                %
                %                 temp4 = app.prsData.alignments{sel(1),sel(2)}.transAlign_pbdG2varG(3,find(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG(1,:)==temp3));
                
                temp{i} = [temp2 '_' num2str(temp2N) ':' temp3 '_' num2str(temp3N) ':Chain' chain ];
            end
            
            app.ResiduetohighlightinPBDDropDown.Items = temp;
            app.ResiduetohighlightinPBDDropDown.Value = temp{1};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(1);
            app.baseFolder = 'C:\Users\riley\Desktop\ResearchWork\ProjectsScience\volumetricMTRscore';
            app.SelectedBaseFolderEditField.Value = 'C:\Users\riley\Desktop\ResearchWork\ProjectsScience\volumetricMTRscore';
            app.EntryIDEditField.Value = 'GluN1';
            app.SelectedPDBfileEditField.Value = '\pdbs\GluN1+GluN2A.pdb';
            app.SelectedPDBGeneSeqfileEditField.Value = '\genebank\GRIN2A-NM_000833-coding.txt';
            app.SelectedVariantfileEditField.Value = '\gnomAD\GRIN2A-gnomAD_v2.1.1_ENSG00000183454_2020_02_14_13_00_45.csv';
            app.SelectedVariantGeneSeqfileEditField.Value = '\genebank\GRIN2A-NM_000833-coding.txt';
            app.ChainsthatVariantFileCorrespondswithinthePDBStructureEditField.Value = 'A,C';
            pause(0.01);
            
            
            temp = {'swMTR','fact_t05','fact_t10','fact_t15','fact_t20','fact_t30','fact_t40','fact_c11','fact_c15','fact_c21','fact_c25','fact_c31','fact_c35','fact_c41','fact_sw11','fact_sw15','fact_sw21','fact_sw25','fact_sw31','fact_sw35','fact_sw41','fact_l11','fact_l15','fact_l21','fact_l25','fact_l31','fact_l35','fact_l41'};
            temp2 = {'y',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ','y',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ',' ',' '};
            
            app.rasterInclusionTable.Data = mergecell(temp',temp2',1);
            app.rasterInclusionTable.ColumnEditable = [false,true];
            
            
            app.AppInitializedLamp.Color = [0 1 0];
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {683, 683};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {556, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SelectPDBFileButton
        function SelectPDBFileButtonPushed(app, event)
            app.StatusEditField.Value = ['user input - button pushed:' event.Source.Text]; pause(0.01);
            
            curFolder = app.baseFolder;
            
            if isempty(curFolder)
                curFolder = 'C:';
            end
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uigetfile(...
                {'*.pdb','structure Files (*.pdb)'}, ...
                'Select a pdb File',curFolder,'MultiSelect','off');
            
            if selectedFolder==0
                app.SelectedPDBfileEditField.Value = 'error';
                app.StatusEditField.Value = 'idle - error, no file selected'; pause(0.01);
                return
            else
                
                fullpath = [selectedFolder selectedFile];
                if ~isempty(app.baseFolder)
                    if contains(fullpath,curFolder)
                        k = strfind(fullpath,curFolder);
                        showPath = fullpath((k+size(curFolder,2)):end);
                    else
                        showPath = fullpath;
                    end
                else
                    showPath = fullpath;
                end
                app.SelectedPDBfileEditField.Value = showPath;
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: SelectBaseFolderButton
        function SelectBaseFolderButtonPushed(app, event)
            app.StatusEditField.Value = ['user input - button pushed:' event.Source.Text]; pause(0.01);
            
            curFolder = app.baseFolder;
            
            if isempty(curFolder)
                curFolder = 'C:';
            end
            
            selpath = uigetdir(curFolder,'Select a Base Folder (for selecting and Saving)');
            
            %             soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uigetfile(...
            %                 {'*.xls;*.xlsx','todo Files (*.xls,*.xlsx)';
            %                 '*.*',  'All Files (*.*)'}, ...
            %                 'Select a File',curFolder,'MultiSelect','off');
            
            app.SelectedBaseFolderEditField.Value = selpath;
            app.baseFolder = selpath;
            %load xls to cell
            
            %             app.CurrentStateEditField.Value = 'reading data log'; pause(0.01);
            %             app.toDoCell = readcell([app.baseFolder app.xlsFile]);
            %
            %             updateFileLog(app);
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SelectPDBSeqFileButton
        function SelectPDBSeqFileButtonPushed(app, event)
            app.StatusEditField.Value = ['user input - button pushed:' event.Source.Text]; pause(0.01);
            
            curFolder = app.baseFolder;
            
            if isempty(curFolder)
                curFolder = 'C:';
            end
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uigetfile(...
                {'*.txt','coding Nucleotide fasta Files (*.txt)'}, ...
                'Select a fasta txt File',curFolder,'MultiSelect','off');
            
            if selectedFolder==0
                app.SelectedPDBGeneSeqfileEditField.Value = 'error';
                app.StatusEditField.Value = 'idle - error, no file selected'; pause(0.01);
                return
            else
                
                fullpath = [selectedFolder selectedFile];
                if ~isempty(app.baseFolder)
                    if contains(fullpath,curFolder)
                        k = strfind(fullpath,curFolder);
                        showPath = fullpath((k+size(curFolder,2)):end);
                    else
                        showPath = fullpath;
                    end
                else
                    showPath = fullpath;
                end
                app.SelectedPDBGeneSeqfileEditField.Value = showPath;
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: SelectVariantFileButton
        function SelectVariantFileButtonPushed(app, event)
            app.StatusEditField.Value = ['user input - button pushed:' event.Source.Text]; pause(0.01);
            
            curFolder = app.baseFolder;
            
            if isempty(curFolder)
                curFolder = 'C:';
            end
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uigetfile(...
                {'*.csv','Variant xls Files (*.csv)'}, ...
                'Select a Variant xls File',curFolder,'MultiSelect','off');
            
            if selectedFolder==0
                app.SelectedVariantfileEditField.Value = 'error';
                app.StatusEditField.Value = 'idle - error, no file selected'; pause(0.01);
                return
            else
                
                fullpath = [selectedFolder selectedFile];
                if ~isempty(app.baseFolder)
                    if contains(fullpath,curFolder)
                        k = strfind(fullpath,curFolder);
                        showPath = fullpath((k+size(curFolder,2)):end);
                    else
                        showPath = fullpath;
                    end
                else
                    showPath = fullpath;
                end
                app.SelectedVariantfileEditField.Value = showPath;
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: SelectVariantSeqFileButton
        function SelectVariantSeqFileButtonPushed(app, event)
            app.StatusEditField.Value = ['user input - button pushed:' event.Source.Text]; pause(0.01);
            
            curFolder = app.baseFolder;
            
            if isempty(curFolder)
                curFolder = 'C:';
            end
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uigetfile(...
                {'*.txt','coding Nucleotide fasta Files (*.txt)'}, ...
                'Select a Nucleotide fasta File',curFolder,'MultiSelect','off');
            
            if selectedFolder==0
                app.SelectedVariantGeneSeqfileEditField.Value = 'error';
                app.StatusEditField.Value = 'idle - error, no file selected'; pause(0.01);
                return
            else
                
                fullpath = [selectedFolder selectedFile];
                if ~isempty(app.baseFolder)
                    if contains(fullpath,curFolder)
                        k = strfind(fullpath,curFolder);
                        showPath = fullpath((k+size(curFolder,2)):end);
                    else
                        showPath = fullpath;
                    end
                else
                    showPath = fullpath;
                end
                app.SelectedVariantGeneSeqfileEditField.Value = showPath;
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: UseSelectedFilesButton
        function UseSelectedFilesButtonPushed(app, event)
            app.StatusEditField.Value = ['adding to table - button pushed:' event.Source.Text]; pause(0.01);
            ID = app.EntryIDEditField.Value;
            pdb = [app.SelectedBaseFolderEditField.Value app.SelectedPDBfileEditField.Value];
            pdbSeq = [app.SelectedBaseFolderEditField.Value app.SelectedPDBGeneSeqfileEditField.Value];
            var = [app.SelectedBaseFolderEditField.Value app.SelectedVariantfileEditField.Value];
            varSeq = [app.SelectedBaseFolderEditField.Value app.SelectedVariantGeneSeqfileEditField.Value];
            chains = app.ChainsthatVariantFileCorrespondswithinthePDBStructureEditField.Value;
            
            temp = {pdb,pdbSeq,var,varSeq,chains};
            
            curTable = app.inputTable.Data;
            toAdd = 1;
            if ~isempty(curTable)
                duplicate = 0;
                for i = 1:size(curTable,1)
                    matchesThisOne = 0;
                    for j = 1:size(temp,2)
                        if strcmp(curTable{i,j},temp{1,j})
                            matchesThisOne = matchesThisOne + (1/size(temp,2));
                        end
                    end
                    if matchesThisOne == 1
                        duplicate = 1;
                    end
                end
                if duplicate
                    toAdd = 0;
                end
            end
            
            if toAdd
                temp = {ID,pdb,pdbSeq,var,varSeq,chains};
                if ~isempty(curTable)
                    temp = mergecell(curTable,temp,2);
                end
                
                app.inputTable.Data = temp;
            end
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: ClearInputTableButton
        function ClearInputTableButtonPushed(app, event)
            app.StatusEditField.Value = ['adding to table - button pushed:' event.Source.Text]; pause(0.01);
            clearTableAndData(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SaveInputTableButton
        function SaveInputTableButtonPushed(app, event)
            app.StatusEditField.Value = ['saving table - button pushed:' event.Source.Text]; pause(0.01);
            curTable = app.inputTable.Data;
            
            curFolder = app.baseFolder;
            
            sugName = '';
            for i = 1:size(curTable,1)
                if i==1
                    sugName = curTable{i,1};
                else
                    sugName = [sugName '_' curTable{i,1}];
                end
            end
            sugName = [sugName '_' datestr(now,'yyyymmdd') '.mat'];
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                {'*.mat','matlab save file (*.mat)'}, ...
                'Select a file to save the current vMTR settings',[curFolder '\' sugName]);
            
            if selectedFolder ~= 0
                save([selectedFolder,selectedFile],"curTable","curFolder",'-mat')
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            else
                app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
            end
        end

        % Button pushed function: OpenInputTableButton
        function OpenInputTableButtonPushed(app, event)
            app.StatusEditField.Value = ['user input - button pushed:' event.Source.Text]; pause(0.01);
            
            curFolder = app.baseFolder;
            
            clearTableAndData(app)
            
            if isempty(curFolder)
                curFolder = 'C:';
            end
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uigetfile(...
                {'*.mat','matlab vMTR save file (*.mat)'}, ...
                'Select a vMTR settings File',curFolder,'MultiSelect','off');
            
            if selectedFolder==0
                app.StatusEditField.Value = 'idle - error, no file selected'; pause(0.01);
                return
            else
                
                fullpath = [selectedFolder selectedFile];
                
                load(fullpath)
                
                app.inputTable.Data = curTable;
                app.baseFolder = curFolder;
                app.SelectedBaseFolderEditField.Value = curFolder;
                
                app.EntryIDEditField.Value = curTable{1,1};
                
                pathTemp = curTable{1,2};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedPDBfileEditField.Value = showPath;
                
                pathTemp = curTable{1,3};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedPDBGeneSeqfileEditField.Value = showPath;
                
                pathTemp = curTable{1,4};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedVariantfileEditField.Value = showPath;
                
                pathTemp = curTable{1,5};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedVariantGeneSeqfileEditField.Value = showPath;
                
                app.ChainsthatVariantFileCorrespondswithinthePDBStructureEditField.Value = curTable{1,6};
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: UpdateProcessingTableButton
        function UpdateProcessingTableButtonPushed(app, event)
            app.StatusEditField.Value = ['allocating analysis fields - button pushed:' event.Source.Text]; pause(0.01);
            
            app.DMTRCalculatedLamp.Color = [0 0 0];
            curTable = app.inputTable.Data;
            
            app.rawData = struct;
            app.prsData = struct;
            
            if isempty(curTable)
                app.StatusEditField.Value = 'idle - error, no data  to load'; pause(0.01);
                app.DataLoadedLamp.Color = [1 0 0];
                return
            else
                loadFiles(app)
                
                calculateResidueDistancesGenForApp(app)
                
                calcSWMTR(app)
                
                alignSequences(app)
                
                items = {};
                for i = 1:(size(app.prsData.alignments,1) .* size(app.prsData.alignments,2))
                    items{i} = [app.prsData.alignments{i}.ID ': chain-' app.prsData.alignments{i}.chain];
                end
                app.GeneAlignmenttoshowDropDown.Items = items;
                app.GeneAlignmenttoshowDropDown.Value = items{1};
                app.GenetoViewDropDown.Items = items;
                app.GenetoViewDropDown.Value = items{1};
                app.Diff_GenetoViewDropDown.Items = items;
                app.Diff_GenetoViewDropDown.Value = items{1};
                app.Perm_GenetoViewDropDown.Items = items;
                app.Perm_GenetoViewDropDown.Value = items{1};
                app.GenetoplotDropDown.Items = items;
                app.GenetoplotDropDown.Value = items{1};
                updateSequenceTab(app)
                
                items = {};
                for i = 1:(size(app.rawData.files.pdb,2))
                    temp = app.rawData.files.pdb{i};
                    temp = temp(find(temp=='\',1,'last'):end);
                    add=1;
                    for j = 1:size(items,2)
                        if strcmp(items{j},temp)
                            add = 0;
                        end
                    end
                    if add
                        items = mergecell(items,{temp},1);
                    end
                end
                items = mergecell({'all'},items,1);
                app.PDBtoshowDropDown.Items = items;
                app.PDBtoshowDropDown.Value = items{1};
                
                %                 app.CalcuationFactorDropDown.Value = app.CalcuationFactorDropDown.Items{1};
                %                 app.CalcuationFactortoShowDropDown.Value = app.CalcuationFactortoShowDropDown.Items{1};
                %
                pause(0.01);
                updateMTRFactorTAB(app)
                pause(0.01);
                updateMTRresultsTAB(app)
                
                %                 app.Diff_Factor1DropDown.Items = mergecell({'swMTR'},app.CalcuationFactortoShowDropDown.Items,1);
                %                 app.Diff_Factor2DropDown.Items = app.Diff_Factor1DropDown.Items;
                %                 app.Diff_Factor1DropDown.Value = app.Diff_Factor1DropDown.Items{1};
                %                 app.Diff_Factor2DropDown.Value = app.Diff_Factor1DropDown.Items{2};
                %
                
                updateResidueDropDownBox(app)
                
                app.DataLoadedLamp.Color = [0 1 0];
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: Calculate3DMTRButton
        function Calculate3DMTRButtonPushed(app, event)
            app.StatusEditField.Value = ['Calculating Calculating: 3DMTR']; pause(0.01);
            
            if sum(app.DataLoadedLamp.Color == [0 1 0])==3
                
                app.DMTRCalculatedLamp.Color = [0 0 0];pause(0.01);
                calculateVMTR(app)
                
                app.DMTRCalculatedLamp.Color = [0 1 0];pause(0.01);
                
                updateMTRresultsTAB(app);
                updateMTRdiffTAB(app);
            end
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: CalcuationFactorDropDown
        function CalcuationFactorDropDownValueChanged(app, event)
            value = app.CalcuationFactorDropDown.Value;
            updateMTRFactorTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: GenetoViewDropDown
        function GenetoViewDropDownValueChanged(app, event)
            value = app.GenetoViewDropDown.Value;
            updateMTRresultsTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: GeneAlignmenttoshowDropDown
        function GeneAlignmenttoshowDropDownValueChanged(app, event)
            value = app.GeneAlignmenttoshowDropDown.Value;
            updateSequenceTab(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: ShowOriginalPDBButton
        function ShowOriginalPDBButtonPushed(app, event)
            
            showOriginalPDB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: CalcuationFactortoShowDropDown
        function CalcuationFactortoShowDropDownValueChanged(app, event)
            value = app.CalcuationFactortoShowDropDown.Value;
            updateMTRresultsTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SaveAlignmentPlotButton
        function SaveAlignmentPlotButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: Alignment Plot']; pause(0.01);
            
            if sum(app.DataLoadedLamp.Color == [0 1 0])==3
                
                fig = figure;
                
                gene = app.GeneAlignmenttoshowDropDown.Value;
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                
                chainLookup.chain = '';
                n=0;
                for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                    curC = split(app.rawData.chains{j},',');
                    for k = 1:size(curC,1)
                        n = n + 1;
                        chainLookup.chain(n) = (curC{k});
                        chainLookup.ID{n} = {app.rawData.ID{j}};
                        chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                    end
                end
                
                temp = find(chainLookup.chain==chain,1,'first');
                sel = [0 0];
                for a = 1:size(app.prsData.alignments,1)
                    for b = 1:size(app.prsData.alignments,2)
                        if app.prsData.alignments{a,b}.chain==chain
                            sel = [a b];
                        end
                    end
                end
                
                boxVal = app.GeneAlignmenttoshowDropDown.Value;
                for j = 1:size(app.GeneAlignmenttoshowDropDown.Items,2)
                    if strcmp(boxVal,app.GeneAlignmenttoshowDropDown.Items{j})
                        break;
                    end
                end
                
                %make good alignment plots
                p2pg = app.prsData.alignments{sel(1),sel(2)}.transAlign_pbd2pbdG;
                pg2vg = app.prsData.alignments{sel(1),sel(2)}.transAlign_pbdG2varG;
                vg2v = app.prsData.alignments{sel(1),sel(2)}.transAlign_varG2var;
                
                %             p2pgN = app.prsData.alignments{j,1}.transNumAlign_pbd2pbdG;
                %             pg2vgN = app.prsData.alignments{j,1}.transNumAlign_pbdG2varG;
                %             vg2vN = app.prsData.alignments{j,1}.transNumAlign_varG2var;
                
                [p2pgRasCX p2pgRasCY p2pgRasWX p2pgRasWY] = makeRasterFromAlignment(p2pg);
                [pg2vgRasCX pg2vgRasCY pg2vgRasWX pg2vgRasWY] = makeRasterFromAlignment(pg2vg);
                [vg2vRasCX vg2vRasCY vg2vRasWX vg2vRasWY] = makeRasterFromAlignment(vg2v);
                
                plot(p2pgRasCX,p2pgRasCY+1+6,'b')
                hold('on')
                plot(p2pgRasWX,p2pgRasWY+0+6,'m')
                plot(pg2vgRasCX,pg2vgRasCY+1+3,'b')
                plot(pg2vgRasWX,pg2vgRasWY+0+3,'m')
                plot(vg2vRasCX,vg2vRasCY+1+0,'b')
                plot(vg2vRasWX,vg2vRasWY+0+0,'m')
                
                yticks([0.5,1,1.5,3.5,4,4.5,6.5,7,7.5]);
                yticklabels({'W','Gene v Var','C','W','Gene v Gene','C','W','Gene v pdb','C',});
                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                title(['Sequence Alignment - ' sugName ' - ' boxVal]);
                ylim([0 8]);
                sugName = [sugName '-Alignment-' boxVal];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','save fig to png (*.png)'}, ...
                    'Select a file to save the current alignment plot',[curFolder '\' sugName '.png']);
                
                if selectedFolder ~= 0
                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            fig.Renderer = 'painters';
                            saveas(fig,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                end
                
                
            end
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: Save3DMTRPDBButton
        function Save3DMTRPDBButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: vMTR PDB']; pause(0.01);
            
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                factor = app.CalcuationFactortoapplytoPDBDropDown.Value;
                for m = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,m).meth,factor)
                        break;
                    end
                end
                
                
                curFolder = app.rawData.baseFolder;
                if contains(factor,'custom')
                    customScale = {};
                    for n = 1:size(app.rawData.loadedPDBs,2)
                        [selectedCustomFile,selectedCustomFolder,~] = uigetfile(...
                            {'*.txt','structure Files (*.txt)'}, ...
                            'Select a ascii file with a custom scale to color the PDB',curFolder,'MultiSelect','off');
                        
                        if selectedCustomFolder == 0
                            app.StatusEditField.Value = ['error: you need to select a custom PDB coloring scale']; pause(0.01);
                            return
                        end
                        
                        customScale{n} = importdata([selectedCustomFolder '\' selectedCustomFile]);
                        %                     customScale{n} = importdata([selectedCustomFolder '\' selectedCustomFile],' ');
                        if size(customScale{n},1)~=size(app.prsData.pdbMTR,2)
                            app.StatusEditField.Value = ['error: the custom PDB coloring scale was not the right size ' num2str(size(customScale{n},1)) ...
                                ' but should have been ' num2str(size(app.prsData.pdbMTR,2))]; pause(0.01);
                            return
                        end
                    end
                end
                
                
                numPDBs = 0;
                whichPDBIDs = {};
                whichPDBpointer = unique([app.rawData.loadedPDBFilePointer{:}]);
                for p = 1:size(whichPDBpointer,2)
                    temp = app.rawData.loadedPDBFiles{whichPDBpointer(p)};
                    st = find(temp=='\',1,'last');
                    en = find(temp=='.',1,'last');
                    whichPDBIDs{p} = temp(st+1:en-1);
                    numPDBs = p;
                end
                items = whichPDBIDs;
                
                for j = 1:size(app.rawData.loadedPDBs,2)
                    app.StatusEditField.Value = ['saving saving: vMTR PDB: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2))]; pause(0.01);
                    t = tic
                    structTemp = app.rawData.loadedPDBs{1,j};
                    chainVec = [app.prsData.pdbMTR.chainID];
                    seqVec = [app.prsData.pdbMTR.resSeq];
                    tempOld = [split(app.rawData.oldchains(j),',')];
                    tempNew = [split(app.rawData.chains(j),',')];
                    %                     for k = 1:size(structTemp.Model.Atom,2)
                    %                         %
                    %                         curResN = structTemp.Model.Atom(1,k).resSeq;
                    %                         curChain = structTemp.Model.Atom(1,k).chainID;
                    %
                    %                         tempC = find([tempOld{:}]==curChain,1,'first');
                    %                         if ~isempty(tempC)
                    %                             curChain = tempNew{(tempC)};
                    %                         end
                    %
                    %                         L = 0;
                    %                         L = find( ((chainVec==curChain)&(seqVec==curResN)) ,1,"first"  );
                    %                         app.StatusEditField.Value = ['saving saving: vMTR PDB: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': atom ' num2str(k) ' of ' num2str(size(structTemp.Model.Atom,2)) ': applying residue ' num2str(L)]; pause(0.001);
                    %
                    %                         if ~isempty(L)
                    %                             if contains(factor,'swMTR')
                    %                                 temp = app.prsData.pdbMTR(1,L).mtrSW;
                    %                             else
                    %                                 temp = app.prsData.vMTRpdb(1,m).vMTR(1,L);
                    %                             end
                    %                         else
                    %                             temp = 1;
                    %                         end
                    %                         if temp>1
                    %                             temp = 1;
                    %                         end
                    %                         structTemp.Model.Atom(1,k).tempFactor = 999.9.* temp;
                    %                     end


                    curResN = [structTemp.Model.Atom.resSeq];
                    curChain = [structTemp.Model.Atom.chainID];
                    curChaintemp = curChain;
                    tempFactors = [structTemp.Model.Atom.tempFactor];
                    for k = 1:size(tempOld,1)
                        ik = curChain==tempOld{k};
                        curChaintemp(ik) = tempNew{k};
                    end
                    curChain = curChaintemp;
                    
                    
                    switch factor
                        case 'custom_4xMean'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = curCustomScale-min(curCustomScale);
                            curCustomScale = ((curCustomScale./max(curCustomScale)));
                            
                            curCustomScale = ((curCustomScale)./(4.*mean(curCustomScale)));
                            curCustomScale(curCustomScale>1) = 1;
                            %                             curCustomScale = (1-curCustomScale);
                        case 'custom_1xSTD'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = curCustomScale-min(curCustomScale);
                            curCustomScale = ((curCustomScale./max(curCustomScale)));
                            
                            curCustomScale = 0.5+((curCustomScale-mean(curCustomScale))./(1*std(curCustomScale)));
                            curCustomScale(curCustomScale>1) = 1;
                            curCustomScale(curCustomScale<0.0001) = 0.0001;
                            
                            %                             curCustomScale = (1-curCustomScale);
                            
                        case 'custom_2xSTD'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = curCustomScale-min(curCustomScale);
                            curCustomScale = ((curCustomScale./max(curCustomScale)));
                            
                            curCustomScale = 0.5+((curCustomScale-mean(curCustomScale))./(2*std(curCustomScale)));
                            curCustomScale(curCustomScale>1) = 1;
                            curCustomScale(curCustomScale<0.0001) = 0.0001;
                            
                            %                             curCustomScale = (1-curCustomScale);
                            
                        case 'custom_3xSTD'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = curCustomScale-min(curCustomScale);
                            curCustomScale = ((curCustomScale./max(curCustomScale)));
                            
                            curCustomScale = 0.5+((curCustomScale-mean(curCustomScale))./(3*std(curCustomScale)));
                            curCustomScale(curCustomScale>1) = 1;
                            curCustomScale(curCustomScale<0.0001) = 0.0001;
                            
                            %                             curCustomScale = (1-curCustomScale);
                            
                        case 'custom_4xSTD'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = curCustomScale-min(curCustomScale);
                            curCustomScale = ((curCustomScale./max(curCustomScale)));
                            
                            curCustomScale = 0.5+((curCustomScale-mean(curCustomScale))./(4*std(curCustomScale)));
                            curCustomScale(curCustomScale>1) = 1;
                            curCustomScale(curCustomScale<0.0001) = 0.0001;
                            
                            %                             curCustomScale = (1-curCustomScale);
                            
                        case 'custom_rawNorm'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = curCustomScale-min(curCustomScale);
                            curCustomScale = ((curCustomScale./max(curCustomScale)));
                            
                        case 'custom_[-1_1]'
                            
                            curCustomScale = customScale{j};
                            curCustomScale = 0.5+(curCustomScale./2);
                            
                        case 'custom_[0_1]'
                            
                            curCustomScale = customScale{j};
                        otherwise
                            %                             curCustomScale = curCustomScale-min(curCustomScale);
                            %                             curCustomScale = ((curCustomScale./max(curCustomScale)));
                            %
                            %                             curCustomScale = 0.5+((curCustomScale-mean(curCustomScale))./(4*std(curCustomScale)));
                            %                             curCustomScale(curCustomScale>1) = 1;
                            %                             curCustomScale(curCustomScale<0.0001) = 0.0001;
                            %
                            %                             curCustomScale = (1-curCustomScale);
                    end
                    
                    
                    for k = 1:size(seqVec,2)
                        L =  ((chainVec(k)==curChain)&(seqVec(k)==curResN)) ;
                        app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': ' num2str(k) ' of ' num2str(size(seqVec,2))]; pause(0.001);
                        
                        if contains(factor,'swMTR')
                            if ~isempty(app.prsData.pdbMTR(1,k).mtrSW)
                                temp = app.prsData.pdbMTR(1,k).mtrSW;
                            else
                                temp = 1;
                            end
                        elseif contains(factor,'custom')
                            if ~isempty(curCustomScale(k))
                                temp = curCustomScale(k);
                            else
                                temp = 1;
                            end
                        else
                            if ~isempty(app.prsData.vMTRpdb(1,m).vMTR(1,k))
                                temp = app.prsData.vMTRpdb(1,m).vMTR(1,k);
                            else
                                temp = 1;
                            end
                        end
                        if temp>1
                            temp = 1;
                        end
                        if temp<0
                            temp=0;
                        end
                        
                        tempFactors(L) = 999.9.* temp;
                        %                                 [structTemp.Model.Atom(L).tempFactor] = [999.9.* temp]
                    end
                    %                             [A.B] = disperse(1:10)
                    %                             [structTemp.Model.Atom.tempFactor] = disperse(tempFactors);
                    
                    ttt=num2cell([tempFactors]);
                    [structTemp.Model.Atom(1,:).tempFactor] = ttt{:};


                    toc(t)
                    curFolder = app.prsData.resultsFolder;
                    curTable = app.inputTable.Data;
                    sugName = '';
                    for i = 1:size(curTable,1)
                        if i==1
                            sugName = curTable{i,1};
                        else
                            sugName = [sugName '_' curTable{i,1}];
                        end
                    end
                    sugName = [sugName '-pdb-' items{j} '-' factor];
                    if contains(factor,'custom')
                        sugName = [sugName '_' selectedCustomFile(1:end-4)];
                    end
                    sugName = [sugName '_' datestr(now,'yyyymmdd')];
                    sugName = replace(sugName,': ','_');
                    
                    soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                        {'*.pdb','save vMTR pdb structural file(*.pdb)'}, ...
                        'Select a file to save the current selected vMTR pdb',[curFolder '\' sugName '.pdb']);
                    
                    if selectedFolder ~= 0
                        try
                            pdbwrite([selectedFolder,selectedFile],structTemp)
                        catch ME
                            temptemp = struct()
                            temptemp.Model = structTemp.Model
                            pdbwrite([selectedFolder,selectedFile],temptemp)
                        end
                    else
                        app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                        return
                    end


                    if contains(factor,'custom')
                        soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a  raster plot of the custom scale?', ...
                           	'Save a raster plot?', ...
                           	'Yes','No','Yes');
                        switch answer
                            case 'Yes'
                                cmap = redblue(255);
                                fig = figure;
                                hold on
                                chainsFound = {};
                                chainLine = {};
                                curCustomScale(curCustomScale<0.0039) = 0.0039;
                                curCustomScale(curCustomScale>(1-0.0039)) = (1-0.0039);
                                for j = 1:size(curCustomScale,1)
                                    chain = app.prsData.pdbMTR(j).chainID;
                                    %                                 if (j == toremove)>0
                                    %                                     continue
                                    %                                 end
                                    if j==1
                                        chainsFound = {chain};
                                        chainLine = {0};
                                        wh = 1;
                                    else
                                        wh = 0;
                                        for k = 1:size(chainsFound,2)
                                            if chainsFound{k}==chain
                                                wh = k;
                                            end
                                        end
                                        if wh==0
                                            chainsFound = mergecell(chainsFound,{chain},1);
                                            chainLine = mergecell(chainLine,{(size(chainsFound,2)-1)*1.1},1);
                                            wh = k+1;
                                        end
                                    end
                                    if isnan(curCustomScale(j))
                                        curCustomScale(j) = 0.5;
                                    end
                                    curCustomScale(curCustomScale<0) = 0.0039;
                                    curCustomScale(curCustomScale>1) = 1;
                                    plot([app.prsData.pdbMTR(j).resSeq app.prsData.pdbMTR(j).resSeq]',[chainLine{wh} chainLine{wh}+1]','Color',cmap(1+round(curCustomScale(j)*254),:));
                                end
                                title(['Custom color scale (' factor ')']);
                                xlabel('residues number (PDB numbering)')
                                axis('tight')
                                [a] = xlim;
                                xlim([1 max([app.prsData.pdbMTR(j).resSeq])]);
                                yticks([(chainLine{:})]+0.5);
                                yticklabels(chainsFound)
                                ylabel('chain')
                                
                                c = colorbar;
                                colormap(redblue)
                                
                                switch factor
                                    case 'custom_4xMean'
                                        caxis([0 1])
                                        
                                    case 'custom_1xSTD'
                                        caxis([-1 1])
                                        
                                    case 'custom_2xSTD'
                                        caxis([-2 2])
                                        
                                    case 'custom_3xSTD'
                                        caxis([-3 3])
                                        
                                    case 'custom_4xSTD'
                                        caxis([-4 4])
                                        
                                    case 'custom_rawNorm'
                                        caxis([0 1])
                                        
                                    case 'custom_[-1_1]'
                                        caxis([-1 1])
                                        
                                    case 'custom_[0_1]'
                                        caxis([0 1])
                                        
                                    otherwise
                                        caxis([-1 1])
                                end
                                
                                
                                print(gcf,[selectedFolder,replace(selectedFile,'.pdb','.png')],'-dpng','-r900')
                                
                                
                                answer2 = questdlg('Would you like to save a vector file?', ...
                                   	'Save a Vector File', ...
                                   	'Yes','No','Yes');
                                switch answer2
                                    case 'Yes'
                                        fig.Renderer = 'painters';
                                        saveas(fig,replace([selectedFolder,selectedFile],'.pdb','.svg'),'svg');
                                    case 'No'
                                end
                                close
                            case 'No'
                        end
                    end
                    
                end
                
            end
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SaveMTRFactorPlotButton
        function SaveMTRFactorPlotButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: vMTR Factor plot']; pause(0.01);
            
            if sum(app.DataLoadedLamp.Color == [0 1 0])==3
                
                fig = figure;
                
                option = app.CalcuationFactorDropDown.Value;
                if strcmp(option,'rawDistances')
                    toplot = app.prsData.distances;
                    toplot = toplot./max(max(toplot));
                    cmap = redblue(255);
                    toplot3 = zeros(size(toplot,1),size(toplot,2),3);
                    toplot = toplot.*254;
                    for i = 1:size(toplot,1)
                        for j = 1:size(toplot,2)
                            
                            toplot3(i,j,1) = cmap(1+round(toplot(i,j)),1);
                            toplot3(i,j,2) = cmap(1+round(toplot(i,j)),2);
                            toplot3(i,j,3) = cmap(1+round(toplot(i,j)),3);
                        end
                    end
                    %                 toplot3 = cmap(1+round(toplot.*254));
                    imagesc(toplot3);
                    
                else
                    toplot = getfield(app.prsData.factors,option);
                    im = zeros(size(toplot,1),size(toplot,2),3,'uint8');
                    im(:,:,1) = ~toplot.*255;
                    im(:,:,2) = ~toplot.*255;
                    im(:,:,3) = ~toplot.*255;
                    
                    imagesc(im);
                end
                app.vMTRFactorPlot.Title.String = ['vMTR Factor Matirx: ' option];
                axis(app.vMTRFactorPlot,'tight');
                
                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                title(['vMTR Factor Matirx - ' sugName ' - ' option]);
                if strcmp(option,'rawDistances')
                    c = colorbar;
                    colormap(redblue)
                    c.TickLabels = num2cell(c.Ticks.*max(max(app.prsData.distances)));
                end
                sugName = [sugName '-vMTR_Factor_Matirx-' option];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','image file (*.png)'}, ...
                    'Select a file to save the current vMTR factor plot',[curFolder '\' sugName '.png']);
                
                if selectedFolder ~= 0
                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            fig.Renderer = 'painters';
                            saveas(fig,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                            if strcmp(option,'rawDistances')
                                imwrite(toplot3,replace([selectedFolder,selectedFile],'.png','-pixelField.png'),'png')
                            else
                                imwrite(im,replace([selectedFolder,selectedFile],'.png','-pixelField.png'),'png')
                            end
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                end
                
                
            end
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SaveTandemMTRPlotsButton
        function SaveTandemMTRPlotsButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: tandem vMTR plots']; pause(0.01);
            
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                
                t = tiledlayout('flow');
                t.TileSpacing = 'compact';
                t.Padding = 'compact';
                
                ax1=nexttile;
                gene = app.GenetoViewDropDown.Value;
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                
                resN = [app.prsData.varData.mtrSW{k}.resN];
                mtrSW = [app.prsData.varData.mtrSW{k}.mtrSW];
                hold('on')
                plot(resN,mtrSW,'m-');
                plot(resN,mtrSW,'k.');
                axis('tight');
                xlim([0 app.swMTRresultPlot.XLim(2)]);
                ylim([-.05 app.swMTRresultPlot.YLim(2)]);
                title(['swMTR: ' gene]);
                ylabel('MTR')
                
                ax2=nexttile;
                
                factor = app.CalcuationFactortoShowDropDown.Value;
                for j = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,j).meth,factor)
                        break;
                    end
                end
                
                curMTR = app.prsData.vMTRpdb(1,j).vMTR;
                toPlotI = ([app.prsData.pdbMTR.chainID] == chain);
                resN = zeros(size(toPlotI,1),size(toPlotI,2));
                for j = 1:size(toPlotI,2)
                    if isempty(app.prsData.pdbMTR(j).varGResN)
                        resN(j) = 0;
                        toPlotI(j) = 0;
                    else
                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                    end
                end
                
                chainLookup.chain = '';
                n=0;
                for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                    curC = split(app.rawData.chains{j},',');
                    for k = 1:size(curC,1)
                        n = n + 1;
                        chainLookup.chain(n) = (curC{k});
                        chainLookup.ID{n} = {app.rawData.ID{j}};
                        chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                    end
                end
                
                temp = find(chainLookup.chain==chain,1,'first');
                sel = [0 0];
                for a = 1:size(app.prsData.alignments,1)
                    for b = 1:size(app.prsData.alignments,2)
                        if app.prsData.alignments{a,b}.chain==chain
                            sel = [a b];
                        end
                    end
                end
                %                 for c = 1:size(resN,2)
                %                     if toPlotI(c)
                %                         [temp,~,~] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var, resN(c));
                %                         if ~isempty(temp)
                %                             resN(c) = temp;
                %                         else
                %                             resN(c) = resN(c-1)+1;
                %                         end
                %                         clear temp
                %                     end
                %                 end
                
                if resN(find(toPlotI,1,'first'))>1000
                    resN = resN-1000;
                end
                
                hold('on')
                plot(resN(toPlotI),curMTR(toPlotI),'m-');
                plot(resN(toPlotI),curMTR(toPlotI),'k.');
                axis('tight');
                xlim([0 app.vMTRresultPlot.XLim(2)]);
                ylim([-.05 app.vMTRresultPlot.YLim(2)]);
                if app.vMTRresultPlot.YLim(2)<1
                    ylim([-.05 1]);
                end
                title(['vMTR: ' gene ': ' factor]);
                ylabel('MTR')
                
                switch app.fittoWhatDropDown.Value
                    case 'pdb'
                        xlabel('Residue (pdb numbering)')
                    case 'gene'
                        xlabel('Residue (gene numbering')
                    case 'tight'
                        axis(ax2,'tight');
                        xlabel('Residue')
                        
                        
                        ylim(ax2,[-.05 app.vMTRresultPlot.YLim(2)]);
                        ylim(ax1,[-.05 app.swMTRresultPlot.YLim(2)]);
                        
                end


                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                sugName = [sugName '-swMTR_vs_vMTR-' app.GenetoViewDropDown.Value '-' factor];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','image file (*.png)'}, ...
                    'Select a file to save the current vMTR tandem plots',[curFolder '\' sugName '.png']);
                
                if selectedFolder ~= 0
                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            t.Parent.Renderer = 'painters';
                            saveas(t,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                end
                
                
            end
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: Diff_GenetoViewDropDown
        function Diff_GenetoViewDropDownValueChanged(app, event)
            value = app.Diff_GenetoViewDropDown.Value;
            
            updateMTRdiffTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: Diff_Factor1DropDown
        function Diff_Factor1DropDownValueChanged(app, event)
            value = app.Diff_Factor1DropDown.Value;
            
            updateMTRdiffTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Value changed function: Diff_Factor2DropDown
        function Diff_Factor2DropDownValueChanged(app, event)
            value = app.Diff_Factor2DropDown.Value;
            
            updateMTRdiffTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: CalculatePermutationsButton
        function CalculatePermutationsButtonPushed(app, event)
            app.StatusEditField.Value = ['calculating calculating: permuting permuting']; pause(0.01);
            
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                %get specific info
                app.prsData.permutations = struct;
                factor = app.Perm_CalcuationFactortoShowDropDown.Value;
                for j = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,j).meth,factor)
                        break;
                    end
                end
                
                %                 chainLookup = struct;
                %                 chainLookup.chain = '';
                %                 n=0;
                %                 for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                %                     curC = split(app.rawData.chains{j},',');
                %                     for k = 1:size(curC,1)
                %                         n = n + 1;
                %                         chainLookup.chain(n) = (curC{k});
                %                         chainLookup.ID{n} = {app.rawData.ID{j}};
                %                         chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                %                     end
                %                 end
                %
                %                 items = app.Perm_GenetoViewDropDown.Items;
                %                 chains = {};
                %                 genes = {};
                %                 for j = 1:size(items,2)
                %
                %                     chains{j} = items{j}(end);
                %                     genes{j} = items{j}(1:(find(items{j}==':',1,"first")-1));
                %                 end
                
                
                factorData = app.prsData.vMTRpdb(1,j);
                pdbData = app.prsData.pdbMTR;
                numP = app.NumPermuationsEditField.Value;
                seq = 1:size(pdbData,2);
                
                permutations = struct;
                permutations.orders = zeros(numP,size(pdbData,2));
                permutations.results = zeros(numP,size(pdbData,2));
                permutations.factorData = factorData;
                permutations.pdbData = pdbData;
                permutations.what = app.Perm_GenetoViewDropDown.Value;
                permutations.factor = app.Perm_CalcuationFactortoShowDropDown.Value;
                permutations.how = app.PermuteWhatDropDown.Value;
                permutations.numP = numP;
                %loop calling permuting functions and compiling results matrix
                %one chain at a time
                t = tic
                for i = 1:numP
                    app.StatusEditField.Value = ['calculating calculating: permuting permuting: ' app.Perm_CalcuationFactortoShowDropDown.Value ' by ' app.PermuteWhatDropDown.Value ': ' num2str(i) ' of ' num2str(numP)]; pause(0.0001);
                    
                    [permutations.results(i,:) permutations.orders(i,:) permutedfactor] = calculatePermutedMTRbyChainNew(pdbData,factorData,app.prsData.allLoc,permutations.how,permutations.factor);
                    
                end
                toc(t)
                
                permutations.permutedfactor = permutedfactor;
                
                app.prsData.permutations = permutations;
                
                app.PermutationsCalculatedLamp.Color = [0 1 0];
                
                
                updatePermuationTAB(app)
            end
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SavePermutationsButton
        function SavePermutationsButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: permutation spreadsheets']; pause(0.01);
            
            chainLookup = struct;
            chainLookup.chain = '';
            n=0;
            for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                curC = split(app.rawData.chains{j},',');
                for k = 1:size(curC,1)
                    n = n + 1;
                    chainLookup.chain(n) = (curC{k});
                    chainLookup.ID{n} = {app.rawData.ID{j}};
                    chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                end
            end
            
            %mtr results
            pdbS = app.prsData.pdbMTR;
            pdbMTR = app.prsData.vMTRpdb;
            factors = app.prsData.factors;
            outputCell = cell(size(pdbS,2)+1,20);
            
            outputCell{1,1} = 'pdb_Residue_Num';
            outputCell{1,2} = 'pdb_Residue';
            outputCell{1,3} = 'pdb_gene_Residue_Num';
            outputCell{1,4} = 'pdb_gene_Residue';
            outputCell{1,5} = 'variant_gene_Residue_Num';
            outputCell{1,6} = 'variant_gene_Residue';
            outputCell{1,7} = 'variant_Residue_Num';
            outputCell{1,8} = 'variant_Residue';
            
            outputCell{1,9} = 'residue#_pdb';
            outputCell{1,10} = 'residue#_pdbgene';
            outputCell{1,11} = 'residue#_vargene';
            outputCell{1,12} = 'residue#_var';
            outputCell{1,13} = 'chain';
            outputCell{1,14} = 'subunit';
            outputCell{1,15} = 'codon';
            outputCell{1,16} = 'residueL';
            outputCell{1,17} = 'residueS';
            outputCell{1,18} = 'X';
            outputCell{1,19} = 'Y';
            outputCell{1,20} = 'Z';
            outputCell{1,21} = 'eSS';
            outputCell{1,22} = 'eMS';
            outputCell{1,23} = 'oSS';
            outputCell{1,24} = 'oMS';
            outputCell{1,25} = 'Ind-MTR';
            outputCell{1,26} = 'IndFx-MTR';
            outputCell{1,27} = 'SW-MTR';
            
            for j = 1:size(pdbS,2)
                cChain = pdbS(j).chainID;
                temp = find(chainLookup.chain==cChain,1,'first');
                
                outputCell{j+1,1} = pdbS(j).pdbResN;
                outputCell{j+1,2} = pdbS(j).pdbRes;
                outputCell{j+1,3} = pdbS(j).pdbGResN;
                outputCell{j+1,4} = pdbS(j).pdbGRes;
                outputCell{j+1,5} = pdbS(j).varGResN;
                outputCell{j+1,6} = pdbS(j).varGRes;
                outputCell{j+1,7} = pdbS(j).varResN;
                outputCell{j+1,8} = pdbS(j).varRes;
                
                outputCell{j+1,9} = pdbS(j).resSeq;
                sel = [0 0];
                for a = 1:size(app.prsData.alignments,1)
                    for b = 1:size(app.prsData.alignments,2)
                        if app.prsData.alignments{a,b}.chain==cChain
                            sel = [a b];
                        end
                    end
                end
                
                if (sel(1)~=0)&&(sel(2)~=0)
                    [ta,tb,tc] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var,pdbS(j).resSeq);
                    outputCell{j+1,10} = ta;
                    outputCell{j+1,11} = tb;
                    outputCell{j+1,12} = tc;
                end
                outputCell{j+1,13} = pdbS(j).chainID;
                outputCell{j+1,14} = string(chainLookup.ID{temp});
                
                outputCell{j+1,15} = pdbS(j).codon;
                outputCell{j+1,16} = pdbS(j).resName;
                outputCell{j+1,17} = pdbS(j).resG;
                outputCell{j+1,18} = pdbS(j).X;
                outputCell{j+1,19} = pdbS(j).Y;
                outputCell{j+1,20} = pdbS(j).Z;
                outputCell{j+1,21} = pdbS(j).eSS;
                outputCell{j+1,22} = pdbS(j).eMS;
                outputCell{j+1,23} = pdbS(j).oSS;
                outputCell{j+1,24} = pdbS(j).oMS;
                outputCell{j+1,25} = pdbS(j).mtr;
                outputCell{j+1,26} = pdbS(j).mtrFx;
                outputCell{j+1,27} = pdbS(j).mtrSW;
            end
            
            for j = 1:size(pdbMTR,2)
                if strcmp(pdbMTR(j).meth,app.prsData.permutations.factor)
                    curMTR = pdbMTR(j).vMTR;
                    temp = cell(size(curMTR))';
                    for m = 1:size(curMTR,2)
                        temp{m,1} = curMTR(1,m);
                    end
                    temp2 = mergecell({pdbMTR(j).meth},temp,2);
                    outputCell = mergecell(outputCell,temp2,1);
                end
            end
            
            %Add permutation data
            outputCell = mergecell(outputCell,{[app.prsData.permutations.how '_permuted']},1);
            temp = mat2cell(app.prsData.permutations.meanPermMTR',ones(size(app.prsData.permutations.meanPermMTR,2),1),1);
            temp = mergecell({'Permuted_Avg'},temp,2);
            outputCell = mergecell(outputCell,temp,1);
            temp = mat2cell(app.prsData.permutations.stdPermMTR',ones(size(app.prsData.permutations.stdPermMTR,2),1),1);
            temp = mergecell({'Permuted_STD'},temp,2);
            outputCell = mergecell(outputCell,temp,1);
            
            %permute backing
            outputCellPermuteOrders = outputCell(:,1:6)';
            temp = mat2cell(app.prsData.permutations.orders,ones(size(app.prsData.permutations.orders,1),1),ones(size(app.prsData.permutations.orders,2),1));
            temp2 = mat2cell((1:size(temp,1))',ones(size(temp,1),1),1);
            temp = mergecell(temp2,temp,1);
            outputCellPermuteOrders = mergecell(outputCellPermuteOrders,temp,2);
            
            outputCellPermuteResults = outputCell(:,[1:6 20])';
            temp = mat2cell(app.prsData.permutations.results,ones(size(app.prsData.permutations.results,1),1),ones(size(app.prsData.permutations.results,2),1));
            temp2 = mat2cell((1:size(temp,1))',ones(size(temp,1),1),1);
            temp = mergecell(temp2,temp,1);
            temp = mergecell({app.prsData.permutations.how},temp,2);
            outputCellPermuteResults = mergecell(outputCellPermuteResults,temp,2);
            
            %generate suggested name
            curFolder = app.prsData.resultsFolder;
            curTable = app.inputTable.Data;
            sugName = '';
            for i = 1:size(curTable,1)
                if i==1
                    sugName = curTable{i,1};
                else
                    sugName = [sugName '_' curTable{i,1}];
                end
            end
            sugName = [sugName '_permutation-' app.Perm_CalcuationFactortoShowDropDown.Value '-' app.PermuteWhatDropDown.Value '-results_' datestr(now,'yyyymmdd')];
            sugName = replace(sugName,': ','_');
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                {'*.xlsx','permutation results file (*.xlsx)'}, ...
                'Select a file to save the permutation results',[curFolder '\' sugName '.xlsx']);
            
            if selectedFolder ~= 0
                %saveXLS
                
                writecell(outputCell,[selectedFolder,selectedFile]);
                writecell(outputCellPermuteOrders,[selectedFolder,replace(selectedFile,'.xlsx',['-' app.prsData.permutations.how '-permuteOrders.xlsx'])]);
                writecell(outputCellPermuteResults,[selectedFolder,replace(selectedFile,'.xlsx',['-' app.prsData.permutations.how '-permuteResults.xlsx'])]);


                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            else
                app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
            end
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: debugging state']; pause(0.01);
            saveStuc = struct();
            
            curFolder = app.baseFolder;
            curTable = app.inputTable.Data;
            sugName = '';
            for i = 1:size(curTable,1)
                if i==1
                    sugName = curTable{i,1};
                else
                    sugName = [sugName '_' curTable{i,1}];
                end
            end
            sugName = [sugName '_' datestr(now,'yyyymmdd')];
            sugName = replace(sugName,': ','_');
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000);
            [selectedFile,selectedFolder,~] = uiputfile(...
                {'*.mat','state file (*.mat)'}, ...
                'Select a file to save the current App State',[curFolder '\' sugName '.mat']);
            
            if selectedFolder ~= 0
                
                saveStruc.baseFolder = app.baseFolder;
                saveStruc.rawData = app.rawData;
                saveStruc.prsData = app.prsData;
                saveStruc.baseFolder = app.baseFolder;
                saveStruc.curTable = app.inputTable.Data;
                
                saveStruc.lamps.loaded = sum(app.DataLoadedLamp.Color == [0 1 0])==3;
                saveStruc.lamps.calc = sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3;
                saveStruc.lamps.perm = sum(app.PermutationsCalculatedLamp.Color == [0 1 0])==3;
                
                %save panel options
                saveStruc.options.SeqTab.gene =     app.GeneAlignmenttoshowDropDown.Value;
                saveStruc.options.SeqTab.method =   app.AlignmentMethodDropDown.Value;
                saveStruc.options.SeqTab.patch =    app.patchGaps.Value;
                saveStruc.options.pdbTab.pdb2show = app.PDBtoshowDropDown.Value;
                saveStruc.options.pdbTab.factor =   app.CalcuationFactortoapplytoPDBDropDown.Value;
                saveStruc.options.facTab.fact =     app.CalcuationFactorDropDown.Value;
                saveStruc.options.facTab.residue =  app.ResiduetohighlightinPBDDropDown.Value;
                saveStruc.options.mtrTab.gene =     app.GenetoViewDropDown.Value;
                saveStruc.options.mtrTab.fact =     app.CalcuationFactortoShowDropDown.Value;
                saveStruc.options.mtrTab.fit =      app.fittoWhatDropDown.Value;
                saveStruc.options.difTab.gene =     app.Diff_GenetoViewDropDown.Value;
                saveStruc.options.difTab.fact1 =    app.Diff_Factor1DropDown.Value;
                saveStruc.options.difTab.fact2 =    app.Diff_Factor2DropDown.Value;
                saveStruc.options.permTab.fact =    app.Perm_CalcuationFactortoShowDropDown.Value;
                saveStruc.options.permTab.num =     app.NumPermuationsEditField.Value;
                saveStruc.options.permTab.fit =     app.PermuteWhatDropDown.Value;
                saveStruc.options.permTab.gene =    app.Perm_GenetoViewDropDown.Value;
                saveStruc.options.rastTab.table =   app.rasterInclusionTable.Data;
                saveStruc.options.rastTab.gene =    app.GenetoplotDropDown.Value;
                
                saveStruc.saveDataVersion = mfilename;
                
                save([selectedFolder selectedFile], "saveStruc", '-mat')
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            else
                app.StatusEditField.Value = 'error: no file selected'; soundsc(makeNote(16000,1,220,0,0,'dampenE'),16000); pause(0.01);
            end
            
        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            app.StatusEditField.Value = ['loding loading: debugging state']; pause(0.01);
            
            %load data
            curFolder = app.baseFolder;
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000);
            [selectedFile,selectedFolder,~] = uigetfile(...
                {'*.mat','matlab vMTR save file (*.mat)'}, ...
                'Select a vMTR settings File',curFolder,'MultiSelect','off');
            
            if selectedFolder==0
                app.StatusEditField.Value = 'idle - error, no file selected'; soundsc(makeNote(16000,1,220,0,0,'dampenE'),16000); pause(0.01);
            else
                fullpath = [selectedFolder selectedFile];
                load(fullpath)
                
                if ~strcmp(mfilename,saveStruc.saveDataVersion)
                    answer = questdlg(['Save version is different than current app version (load version:' saveStruc.saveDataVersion '); Would you like to try and load whatever settings can be?'], ...
                       	'try and load anyway', ...
                       	'Yes','No','No');
                    if ~strcmp(answer,'Yes')
                        app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                        return
                    end
                end
                
                clearTableAndData(app)
                
                if isempty(curFolder)
                    curFolder = 'C:';
                end
                
                
                app.inputTable.Data = saveStruc.curTable;
                curFolder = saveStruc.baseFolder;
                app.baseFolder = curFolder;
                app.SelectedBaseFolderEditField.Value = curFolder;
                
                app.EntryIDEditField.Value = saveStruc.curTable{1,1};
                
                pathTemp = saveStruc.curTable{1,2};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedPDBfileEditField.Value = showPath;
                
                pathTemp = saveStruc.curTable{1,3};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedPDBGeneSeqfileEditField.Value = showPath;
                
                pathTemp = saveStruc.curTable{1,4};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedVariantfileEditField.Value = showPath;
                
                pathTemp = saveStruc.curTable{1,5};
                k = strfind(pathTemp,curFolder);
                showPath = pathTemp((k+size(curFolder,2)):end);
                app.SelectedVariantGeneSeqfileEditField.Value = showPath;
                
                app.ChainsthatVariantFileCorrespondswithinthePDBStructureEditField.Value = saveStruc.curTable{1,6};
                
                app.baseFolder = saveStruc.baseFolder;
                app.rawData = saveStruc.rawData;
                app.prsData = saveStruc.prsData;
                
                %update panel
                if saveStruc.lamps.loaded
                    app.DataLoadedLamp.Color = [0 1 0]; %%% need to check to see if the data is loaded
                    items = {};
                    for i = 1:(size(app.prsData.alignments,1) .* size(app.prsData.alignments,2))
                        items{i} = [app.prsData.alignments{i}.ID ': chain-' app.prsData.alignments{i}.chain];
                    end
                    app.GeneAlignmenttoshowDropDown.Items = items;
                    app.GeneAlignmenttoshowDropDown.Value = items{1};
                    app.GenetoViewDropDown.Items = items;
                    app.GenetoViewDropDown.Value = items{1};
                    app.Diff_GenetoViewDropDown.Items = items;
                    app.Diff_GenetoViewDropDown.Value = items{1};
                    app.Perm_GenetoViewDropDown.Items = items;
                    app.Perm_GenetoViewDropDown.Value = items{1};
                    app.GenetoplotDropDown.Items = items;
                    app.GenetoplotDropDown.Value = items{1};
                    
                    app.GeneAlignmenttoshowDropDown.Value = saveStruc.options.SeqTab.gene;
                    app.AlignmentMethodDropDown.Value = saveStruc.options.SeqTab.method;
                    app.patchGaps.Value = saveStruc.options.SeqTab.patch;
                    
                    updateSequenceTab(app)
                    
                    items = {};
                    for i = 1:(size(app.rawData.files.pdb,2))
                        temp = app.rawData.files.pdb{i};
                        temp = temp(find(temp=='\',1,'last'):end);
                        add=1;
                        for j = 1:size(items,2)
                            if strcmp(items{j},temp)
                                add = 0;
                            end
                        end
                        if add
                            items = mergecell(items,{temp},1);
                        end
                    end
                    items = mergecell({'all'},items,1);
                    app.PDBtoshowDropDown.Items = items;
                    app.PDBtoshowDropDown.Value = items{1};
                    
                    %                 app.CalcuationFactorDropDown.Value = app.CalcuationFactorDropDown.Items{1};
                    %                 app.CalcuationFactortoShowDropDown.Value = app.CalcuationFactortoShowDropDown.Items{1};
                    %
                    pause(0.01);
                    app.PDBtoshowDropDown.Value = saveStruc.options.pdbTab.pdb2show;
                    app.CalcuationFactortoapplytoPDBDropDown.Value = saveStruc.options.pdbTab.factor;
                    app.CalcuationFactorDropDown.Value = saveStruc.options.facTab.fact;
                    
                    updateMTRFactorTAB(app)
                    
                    updateResidueDropDownBox(app)
                    app.ResiduetohighlightinPBDDropDown.Value = saveStruc.options.facTab.residue;
                    pause(0.01);
                    if saveStruc.lamps.calc
                        app.DMTRCalculatedLamp.Color = [0 1 0];pause(0.01); %%% need to check to see if MTR is calculated
                        app.GenetoViewDropDown.Value = saveStruc.options.mtrTab.gene;
                        app.CalcuationFactortoShowDropDown.Value = saveStruc.options.mtrTab.fact;
                        app.fittoWhatDropDown.Value = saveStruc.options.mtrTab.fit;
                        app.Diff_GenetoViewDropDown.Value = saveStruc.options.difTab.gene;
                        app.rasterInclusionTable.Data = saveStruc.options.rastTab.table;
                        app.GenetoplotDropDown.Value = saveStruc.options.rastTab.gene;
                        updateMTRresultsTAB(app)
                        
                        app.Diff_Factor1DropDown.Items = mergecell({'swMTR'},app.CalcuationFactortoShowDropDown.Items,1);
                        app.Diff_Factor2DropDown.Items = app.Diff_Factor1DropDown.Items;
                        app.Diff_Factor1DropDown.Value = app.Diff_Factor1DropDown.Items{1};
                        app.Diff_Factor2DropDown.Value = app.Diff_Factor1DropDown.Items{2};
                        %
                        
                        app.Diff_Factor1DropDown.Value = saveStruc.options.difTab.fact1;
                        app.Diff_Factor2DropDown.Value =  saveStruc.options.difTab.fact2;
                        updateMTRdiffTAB(app)
                        if saveStruc.lamps.perm
                            app.PermutationsCalculatedLamp.Color = [0 1 0]; %%% need to check to see if there are permutations
                            app.Perm_CalcuationFactortoShowDropDown.Value = saveStruc.options.permTab.fact;
                            app.NumPermuationsEditField.Value = saveStruc.options.permTab.num;
                            app.PermuteWhatDropDown.Value = saveStruc.options.permTab.fit;
                            app.Perm_GenetoViewDropDown.Value = saveStruc.options.permTab.gene;
                            updatePermuationTAB(app)
                        end
                    end
                    
                    
                    if ~(isfolder(app.rawData.baseFolder))
                        app.StatusEditField.Value = ['making results folder']; pause(0.01);
                        mkdir(app.rawData.baseFolder);
                    end
                    
                    
                    app.prsData.resultsFolder = [app.rawData.baseFolder '\' 'results\'];
                    if ~(isfolder(app.prsData.resultsFolder))
                        app.StatusEditField.Value = ['making results folder']; pause(0.01);
                        mkdir(app.prsData.resultsFolder);
                    end
                    
                    for i = 1:size(app.rawData.ID,2)
                        if i==1
                            sugName = app.rawData.ID{1,i};
                        else
                            sugName = [sugName '_' app.rawData.ID{1,i}];
                        end
                    end
                    
                    app.prsData.resultsFolder = [app.prsData.resultsFolder '\' sugName];
                    if ~(isfolder(app.prsData.resultsFolder))
                        app.StatusEditField.Value = ['making results folder']; pause(0.01);
                        mkdir(app.prsData.resultsFolder);
                    end
                    
                    app.prsData.resultsFolder = [app.prsData.resultsFolder '\' datestr(now,'yyyymmdd') '\'];
                    if ~(isfolder(app.prsData.resultsFolder))
                        app.StatusEditField.Value = ['making results folder']; pause(0.01);
                        mkdir(app.prsData.resultsFolder);
                    end
                    
                end
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Value changed function: fittoWhatDropDown
        function fittoWhatDropDownValueChanged(app, event)
            value = app.fittoWhatDropDown.Value;
            updateMTRresultsTAB(app)
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: Diff_SaveDiffMTRPlotsButton
        function Diff_SaveDiffMTRPlotsButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: tandem vMTR plots']; pause(0.01);
            
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                fig = figure;
                
                gene = app.Diff_GenetoViewDropDown.Value;
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                
                factor1 = app.Diff_Factor1DropDown.Value;
                factor2 = app.Diff_Factor2DropDown.Value;
                cj=0;
                ci=0;
                for j = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,j).meth,factor1)
                        cj =j;
                        break;
                    end
                end
                for i = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,i).meth,factor2)
                        ci = i;
                        
                        break;
                    end
                end
                
                
                if strcmp(factor1,'swMTR')
                    curMTR1 = [app.prsData.pdbMTR.mtrSW];
                else
                    curMTR1 = [app.prsData.vMTRpdb(1,cj).vMTR];
                end
                toPlotI1 = ([app.prsData.pdbMTR.chainID] == chain);
                resN = [app.prsData.pdbMTR.varGResN];
                if resN(find(toPlotI1,1,'first'))>1000
                    resN = resN-1000;
                end
                
                
                if strcmp(factor2,'swMTR')
                    curMTR2 = [app.prsData.pdbMTR.mtrSW];
                else
                    curMTR2 = [app.prsData.vMTRpdb(1,ci).vMTR];
                end
                toPlotI2 = ([app.prsData.pdbMTR.chainID] == chain);
                if strcmp(factor1,'swMTR')
                    curMTR1 = [app.prsData.pdbMTR.mtrSW];
                else
                    curMTR1 = [app.prsData.vMTRpdb(1,cj).vMTR];
                end
                toPlotI1 = ([app.prsData.pdbMTR.chainID] == chain);
                resN = zeros(size(toPlotI1,1),size(toPlotI1,2));
                toremove =[];
                for j = 1:size(toPlotI1,2)
                    if isempty(app.prsData.pdbMTR(j).varGResN)
                        resN(j) = 0;
                        toremove = [toremove j];
                    else
                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                    end
                end
                if ~isempty(toremove)
                    resN(toremove)=[];
                    toPlotI1(toremove)=[];
                    toPlotI2(toremove)=[];
                end
                if resN(find(toPlotI1,1,'first'))>1000
                    resN = resN-1000;
                end
                
                %adjust to Gene Residues numbering ...
                chainLookup.chain = '';
                n=0;
                for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                    curC = split(app.rawData.chains{j},',');
                    for k = 1:size(curC,1)
                        n = n + 1;
                        chainLookup.chain(n) = (curC{k});
                        chainLookup.ID{n} = {app.rawData.ID{j}};
                        chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                    end
                end
                
                %                 temp = find(chainLookup.chain==chain,1,'first');
                %                 sel = [0 0];
                %                 for a = 1:size(app.prsData.alignments,1)
                %                     for b = 1:size(app.prsData.alignments,2)
                %                         if app.prsData.alignments{a,b}.chain==chain
                %                             sel = [a b];
                %                         end
                %                     end
                %                 end
                %                 for c = 1:size(resN,2)
                %                     if app.prsData.pdbMTR(c).chainID == chain
                %                         [resN(c),~,~] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var, resN(c));
                %                     end
                %                 end
                
                
                plot(resN(toPlotI1),(curMTR2((toPlotI2)) - curMTR1((toPlotI1))),'m-');
                hold('on')
                plot(resN(toPlotI1),(curMTR2((toPlotI2)) - curMTR1((toPlotI1))),'k.');
                axis('tight');
                xlim([0 app.Diff_vMTRresultPlot.XLim(2)]);
                
                title(['Difference vMTR: ' gene ': ' factor2 ' - ' factor1]);


                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                sugName = [sugName '-' gene];
                sugName = [sugName '-diff_vMTR-' factor2 '_minus_' factor1];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','image file (*.png)'}, ...
                    'Select a file to save the current vMTR difference plot',[curFolder '\' sugName '.png']);
                
                if selectedFolder ~= 0
                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            fig.Renderer = 'painters';
                            saveas(fig,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                end
                
                
            end
            
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: results spreadsheets']; pause(0.01);
            %compile results
            results = struct;
            
            chainLookup = struct;
            chainLookup.chain = '';
            n=0;
            for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                curC = split(app.rawData.chains{j},',');
                for k = 1:size(curC,1)
                    n = n + 1;
                    chainLookup.chain(n) = (curC{k});
                    chainLookup.ID{n} = {app.rawData.ID{j}};
                    chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                end
            end
            
            
            %alignment results
            alignmentCell = {};
            for j = 1:(size(app.prsData.alignments,1)*size(app.prsData.alignments,2))
                
                temp = app.prsData.alignments{j}.fullAlign;
                temp4 = app.prsData.alignments{j}.fullNumAlign;
                
                eachRowTemp = cell(1,size(temp,2));
                row1=eachRowTemp;
                row1{1,1} = chainLookup.IDfull{j};
                
                row2=eachRowTemp;
                start = find(temp(1,:)~='-',1,"first");
                for i = start:5:size(temp4(1,:),2)
                    row2{1,i} = num2str(temp4(1,i));
                end
                toShowTable =row2;
                
                for i = 1:size(temp,1)
                    rowtemp = eachRowTemp;
                    for k = 1:size(temp(i,:),2)
                        rowtemp{1,k} = temp(i,k);
                    end
                    toShowTable = mergecell(toShowTable,rowtemp,2);
                end
                toShowTable = mergecell({chainLookup.IDfull{j};'pdb';' ';'pdb gene seq';' ';'variant gene seq';' ';'variant gene data'},toShowTable,1);
                alignmentCell = mergecell(alignmentCell,toShowTable,2);
                
                tempCell = {};
                tempCellTemp = {};
                for k = 1:size(app.prsData.alignments{j}.numAlign_pbd2pbdG,2)
                    tempCellTemp{k+1,1} = app.prsData.alignments{j}.numAlign_pbd2pbdG(1,k);
                    tempCellTemp{k+1,2} = app.prsData.alignments{j}.transAlign_pbd2pbdG(1,k);
                    tempCellTemp{k+1,3} = app.prsData.alignments{j}.transAlign_pbd2pbdG(2,k);
                    tempCellTemp{k+1,4} = app.prsData.alignments{j}.transAlign_pbd2pbdG(3,k);
                    tempCellTemp{k+1,5} = app.prsData.alignments{j}.numAlign_pbd2pbdG(3,k);
                end
                tempCellTemp = mergecell({'PDB to PDB Gene FASTA Alignment'},tempCellTemp,1);
                tempCell = mergecell(tempCell,tempCellTemp,1);
                
                
                tempCellTemp = {};
                for k = 1:size(app.prsData.alignments{j}.numAlign_pbdG2varG,2)
                    tempCellTemp{k+1,1} = app.prsData.alignments{j}.numAlign_pbdG2varG(1,k);
                    tempCellTemp{k+1,2} = app.prsData.alignments{j}.transAlign_pbdG2varG(1,k);
                    tempCellTemp{k+1,3} = app.prsData.alignments{j}.transAlign_pbdG2varG(2,k);
                    tempCellTemp{k+1,4} = app.prsData.alignments{j}.transAlign_pbdG2varG(3,k);
                    tempCellTemp{k+1,5} = app.prsData.alignments{j}.numAlign_pbdG2varG(3,k);
                end
                tempCellTemp = mergecell({'PDB Gene FASTA to Variant Gene FASTA Alignment'},tempCellTemp,1);
                tempCell = mergecell(tempCell,tempCellTemp,1);
                
                
                tempCellTemp = {};
                for k = 1:size(app.prsData.alignments{j}.numAlign_varG2var,2)
                    tempCellTemp{k+1,1} = app.prsData.alignments{j}.numAlign_varG2var(1,k);
                    tempCellTemp{k+1,2} = app.prsData.alignments{j}.transAlign_varG2var(1,k);
                    tempCellTemp{k+1,3} = app.prsData.alignments{j}.transAlign_varG2var(2,k);
                    tempCellTemp{k+1,4} = app.prsData.alignments{j}.transAlign_varG2var(3,k);
                    tempCellTemp{k+1,5} = app.prsData.alignments{j}.numAlign_varG2var(3,k);
                end
                tempCellTemp = mergecell({'Variant Gene FASTA to Variant Data Alignment'},tempCellTemp,1);
                tempCell = mergecell(tempCell,tempCellTemp,1);
                
                tempCell = mergecell({replace(replace(app.GeneAlignmenttoshowDropDown.Items{j},': ','_'),'-','_')},tempCell,1);
                
                results = setfield(results,replace(replace(app.GeneAlignmenttoshowDropDown.Items{j},': ','_'),'-','_'),tempCell);
            end
            
            %mtr results
            pdbS = app.prsData.pdbMTR;
            pdbMTR = app.prsData.vMTRpdb;
            factors = app.prsData.factors;
            outputCell = {};
            outputCell{1,1} = 'pdb_Residue_Num';
            outputCell{1,2} = 'pdb_Residue';
            outputCell{1,3} = 'pdb_gene_Residue_Num';
            outputCell{1,4} = 'pdb_gene_Residue';
            outputCell{1,5} = 'variant_gene_Residue_Num';
            outputCell{1,6} = 'variant_gene_Residue';
            outputCell{1,7} = 'variant_Residue_Num';
            outputCell{1,8} = 'variant_Residue';
            outputCell{1,9} = 'residue#_pdb';
            outputCell{1,10} = 'residue#_pdbgene';
            outputCell{1,11} = 'residue#_vargene';
            outputCell{1,12} = 'residue#_var';
            outputCell{1,13} = 'chain';
            outputCell{1,14} = 'subunit';
            outputCell{1,15} = 'codon';
            outputCell{1,16} = 'residueL';
            outputCell{1,17} = 'residueS';
            outputCell{1,18} = 'X';
            outputCell{1,19} = 'Y';
            outputCell{1,20} = 'Z';
            outputCell{1,21} = 'eSS';
            outputCell{1,22} = 'eMS';
            outputCell{1,23} = 'oSS';
            outputCell{1,24} = 'oMS';
            outputCell{1,25} = 'Ind-MTR';
            outputCell{1,26} = 'IndFx-MTR';
            outputCell{1,27} = 'SW-MTR';
            
            for j = 1:size(pdbS,2)
                cChain = pdbS(j).chainID;
                temp = find(chainLookup.chain==cChain,1,'first');
                
                outputCell{j+1,1} = pdbS(j).pdbResN;
                outputCell{j+1,2} = pdbS(j).pdbRes;
                outputCell{j+1,3} = pdbS(j).pdbGResN;
                outputCell{j+1,4} = pdbS(j).pdbGRes;
                outputCell{j+1,5} = pdbS(j).varGResN;
                outputCell{j+1,6} = pdbS(j).varGRes;
                outputCell{j+1,7} = pdbS(j).varResN;
                outputCell{j+1,8} = pdbS(j).varRes;
                
                outputCell{j+1,9} = pdbS(j).resSeq;
                sel = [0 0];
                for a = 1:size(app.prsData.alignments,1)
                    for b = 1:size(app.prsData.alignments,2)
                        if app.prsData.alignments{a,b}.chain==cChain
                            sel = [a b];
                        end
                    end
                end
                
                if (sel(1)~=0)&&(sel(2)~=0)
                    [ta,tb,tc] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var,pdbS(j).resSeq);
                    outputCell{j+1,10} = ta;
                    outputCell{j+1,11} = tb;
                    outputCell{j+1,12} = tc;
                end
                outputCell{j+1,13} = pdbS(j).chainID;
                outputCell{j+1,14} = string(chainLookup.ID{temp});
                
                outputCell{j+1,15} = pdbS(j).codon;
                outputCell{j+1,16} = pdbS(j).resName;
                outputCell{j+1,17} = pdbS(j).resG;
                outputCell{j+1,18} = pdbS(j).X;
                outputCell{j+1,19} = pdbS(j).Y;
                outputCell{j+1,20} = pdbS(j).Z;
                outputCell{j+1,21} = pdbS(j).eSS;
                outputCell{j+1,22} = pdbS(j).eMS;
                outputCell{j+1,23} = pdbS(j).oSS;
                outputCell{j+1,24} = pdbS(j).oMS;
                outputCell{j+1,25} = pdbS(j).mtr;
                outputCell{j+1,26} = pdbS(j).mtrFx;
                outputCell{j+1,27} = pdbS(j).mtrSW;
            end
            outputCellExt = outputCell;
            for j = 1:size(pdbMTR,2)
                curMTR = pdbMTR(j).vMTR;
                temp = cell(size(curMTR))';
                for m = 1:size(curMTR,2)
                    temp{m,1} = curMTR(1,m);
                end
                temp2 = mergecell({pdbMTR(j).meth},temp,2);
                
                
                outputCell = mergecell(outputCell,temp2,1);
            end
            
            for j = 1:size(pdbMTR,2)
                curMTR = pdbMTR(j).vMTR;
                temp = cell(size(curMTR))';
                for m = 1:size(curMTR,2)
                    temp{m,1} = curMTR(1,m);
                end
                temp2 = mergecell({pdbMTR(j).meth},temp,2);
                
                
                if contains(pdbMTR(j).meth,'_t')
                    
                    curWidth = pdbMTR(j).widthw;
                    temp3 = cell(size(curWidth))';
                    for m = 1:size(curWidth,2)
                        temp3{m,1} = curWidth(1,m);
                    end
                    temp4 = mergecell({[pdbMTR(j).meth '-totalResIncluded']},temp3,2);
                    
                    curDist = getfield(factors.info,[pdbMTR(j).meth 'Avgd']);
                    temp5 = cell(size(curDist))';
                    for m = 1:size(curDist,2)
                        temp5{m,1} = curDist(1,m);
                    end
                    temp6 = mergecell({[pdbMTR(j).meth '-AvgResDist']},temp5,2);
                    temp6 = mergecell(temp4,temp6,1);
                    
                elseif contains(pdbMTR(j).meth,'_c')
                    
                    curDist = getfield(factors.info,[pdbMTR(j).meth 'valAvgd']);
                    temp5 = cell(size(curDist))';
                    for m = 1:size(curDist,2)
                        temp5{m,1} = curDist(1,m);
                    end
                    temp6 = mergecell({[pdbMTR(j).meth '-AvgResDist']},temp5,2);
                elseif contains(pdbMTR(j).meth,'_l')
                    
                    curDist = getfield(factors.info,[pdbMTR(j).meth 'valAvgd']);
                    temp5 = cell(size(curDist))';
                    for m = 1:size(curDist,2)
                        temp5{m,1} = curDist(1,m);
                    end
                    temp6 = mergecell({[pdbMTR(j).meth '-AvgResDist']},temp5,2);
                elseif contains(pdbMTR(j).meth,'_sw')
                    curDist = getfield(factors.info,[pdbMTR(j).meth 'valAvgd']);
                    temp5 = cell(size(curDist))';
                    for m = 1:size(curDist,2)
                        temp5{m,1} = curDist(1,m);
                    end
                    temp6 = mergecell({[pdbMTR(j).meth '-AvgResDist']},temp5,2);
                else
                    
                    curWT = pdbMTR(j).vWT;
                    temp3 = cell(size(curWT))';
                    for m = 1:size(curWT,2)
                        temp3{m,1} = curWT(1,m);
                    end
                    temp6= mergecell({[pdbMTR(j).meth '-totalWeights']},temp3,2);
                end
                
                outputCellExt = mergecell(outputCellExt,temp2,1);
                outputCellExt = mergecell(outputCellExt,temp6,1);
            end


            %generate suggested name
            curFolder = app.prsData.resultsFolder;
            curTable = app.inputTable.Data;
            sugName = '';
            for i = 1:size(curTable,1)
                if i==1
                    sugName = curTable{i,1};
                else
                    sugName = [sugName '_' curTable{i,1}];
                end
            end
            sugName = [sugName '_results_' datestr(now,'yyyymmdd')];
            sugName = replace(sugName,': ','_');
            
            soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                {'*.xlsx','results file (*.xlsx)'}, ...
                'Select a file to save the vMTR results',[curFolder '\' sugName '.xlsx']);
            
            if selectedFolder ~= 0
                %saveXLS
                
                writecell(outputCell,[selectedFolder,selectedFile]);
                writecell(outputCellExt,[selectedFolder,replace(selectedFile,'.xlsx','-extended.xlsx')]);
                
                for j = 1:size(app.GeneAlignmenttoshowDropDown.Items,2)
                    %                     xlswrite([selectedFolder,replace(selectedFile,'.xlsx','-allAlignments.xlsx')],...
                    %                         getfield(results,replace(replace(app.GeneAlignmenttoshowDropDown.Items{j},': ','_'),'-','_')),...
                    %                         app.GeneAlignmenttoshowDropDown.Items{j});
                    tempPath = [selectedFolder,replace(selectedFile,'.xlsx',['-alignments-' replace(replace(app.GeneAlignmenttoshowDropDown.Items{j},': ','_'),'-','_') '.xlsx'])];
                    writecell(getfield(results,replace(replace(app.GeneAlignmenttoshowDropDown.Items{j},': ','_'),'-','_')),tempPath)
                end
                
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            else
                app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
            end
        end

        % Value changed function: Perm_GenetoViewDropDown
        function Perm_GenetoViewDropDownValueChanged(app, event)
            value = app.Perm_GenetoViewDropDown.Value;
            updatePermuationTAB(app);
            app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
        end

        % Button pushed function: SavePermutationPlotButton
        function SavePermutationPlotButtonPushed(app, event)
            app.StatusEditField.Value = ['saving saving: permutation plot']; pause(0.01);
            
            if sum(app.PermutationsCalculatedLamp.Color == [0 1 0])==3
                
                permutations = app.prsData.permutations;
                %                 soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save the plot?', ...
                %                    	'Save a the current plot', ...
                %                    	'Yes','No','Yes');
                %                 switch answer
                %                     case 'Yes'
                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                sugName = [sugName '-Permutations-' app.Perm_GenetoViewDropDown.Value '-' permutations.factor ...
                    '-By' replace(app.PermuteWhatDropDown.Value,' ','') '-' num2str(permutations.numP) 'permutations'];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                
                
                meanPermMTR = permutations.meanPermMTR;
                stdPermMTR = permutations.stdPermMTR;
                resN = permutations.resN;
                toPlotI = permutations.toPlotI;
                
                stdArea1 = (meanPermMTR(toPlotI)-stdPermMTR(toPlotI));
                stdArea2 = (meanPermMTR(toPlotI)+stdPermMTR(toPlotI));
                stdArea = [stdArea1 stdArea2(end:-1:1)];
                stdAreaResN1 = (resN(toPlotI));
                stdAreaResN2 = (resN(toPlotI));
                stdAreaResN = [stdAreaResN1 stdAreaResN2(end:-1:1)];
                stdArea2x1 = (meanPermMTR(toPlotI)-(2.*stdPermMTR(toPlotI)));
                stdArea2x2 = (meanPermMTR(toPlotI)+(2.*stdPermMTR(toPlotI)));
                stdArea2x = [stdArea2x1 stdArea2x2(end:-1:1)];


                fig = figure;
                fill(stdAreaResN,stdArea2x,[.5 .5 .5],'FaceAlpha', 0.3,'LineStyle','none')
                hold('on')
                fill(stdAreaResN,stdArea,[.5 .5 .5],'FaceAlpha', 0.3,'LineStyle','none')
                plot(permutations.resN(permutations.toPlotI),permutations.meanPermMTR(permutations.toPlotI),'k')
                plot(permutations.resN(permutations.toPlotI),permutations.factorData.vMTR(permutations.toPlotI),'m-');
                plot(permutations.resN(permutations.toPlotI),permutations.factorData.vMTR(permutations.toPlotI),'k.');
                axis('tight');
                %                 xlim([0 app.Perm_vMTRresultPlot.XLim(2)]);
                ylim([-.05 app.Perm_vMTRresultPlot.YLim(2)]);
                tempLim = ylim();
                if tempLim(2)<1
                    ylim([-.05 1]);
                end
                fig.Position = [100 100 1200 500];
                %                         gca.PlotBoxAspectRatio = [3 1 1]
                title(['vMTR and Permutations: ' permutations.what ': ' permutations.factor ': ' permutations.how ': ' num2str(permutations.numP) ' Permutations']);
                ylabel('MTR')
                xlabel('Residue (pdb numbering)')
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','image file (*.png)'}, ...
                    'Select a file to save the current Permutation plots',[curFolder '\' sugName '.png']);
                
                if selectedFolder ~= 0
                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            fig.Renderer = 'painters';
                            saveas(fig,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                end
            else
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: 
        % SavePermutationSignificancePDBButton
        function SavePermutationSignificancePDBButtonPushed(app, event)
            
            
            app.StatusEditField.Value = ['saving saving: vMTR permutation significance PDB']; pause(0.001);
            if (sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3) && (sum(app.PermutationsCalculatedLamp.Color == [0 1 0])==3)
                factor = app.Perm_CalcuationFactortoShowDropDown.Value;
                for m = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,m).meth,factor)
                        break;
                    end
                end
                
                numPDBs = 0;
                whichPDBIDs = {};
                whichPDBpointer = unique([app.rawData.loadedPDBFilePointer{:}]);
                for p = 1:size(whichPDBpointer,2)
                    temp = app.rawData.loadedPDBFiles{whichPDBpointer(p)};
                    st = find(temp=='\',1,'last');
                    en = find(temp=='.',1,'last');
                    whichPDBIDs{p} = temp(st+1:en-1);
                    numPDBs = p;
                end
                items = whichPDBIDs;
                
                for j = 1:size(app.rawData.loadedPDBs,2)
                    app.StatusEditField.Value = ['saving saving: vMTR permutation significance PDB: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2))]; pause(0.0001);
                    t = tic;
                    structTemp = app.rawData.loadedPDBs{1,j};
                    chainVec = [app.prsData.pdbMTR.chainID];
                    seqVec = [app.prsData.pdbMTR.resSeq];
                    tempOld = [split(app.rawData.oldchains(j),',')];
                    tempNew = [split(app.rawData.chains(j),',')];
                    %                     for k = 1:size(structTemp.Model.Atom,2)
                    %                         %
                    %                         curResN = structTemp.Model.Atom(1,k).resSeq;
                    %                         curChain = structTemp.Model.Atom(1,k).chainID;
                    %
                    %                         tempC = find([tempOld{:}]==curChain,1,'first');
                    %                         if ~isempty(tempC)
                    %                             curChain = tempNew{(tempC)};
                    %                         end
                    %
                    %                         L = 0;
                    %                         L = find( ((chainVec==curChain)&(seqVec==curResN)) ,1,"first"  );
                    %                         app.StatusEditField.Value = ['saving saving: vMTR permutation significance PDB: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': atom ' num2str(k) ' of ' num2str(size(structTemp.Model.Atom,2)) ': applying residue ' num2str(L)]; pause(0.001);
                    %
                    %                         temp = 0.5;
                    %                         if ~isempty(L)
                    %                             meanV = app.prsData.permutations.meanPermMTR(1,L);
                    %                             stdV = app.prsData.permutations.stdPermMTR(1,L);
                    %                             mtr = app.prsData.vMTRpdb(1,m).vMTR(1,L);
                    %                             if mtr<(meanV-(stdV*2))
                    %                                 temp = 0;
                    %                             elseif mtr>(meanV+(stdV*2))
                    %                                 temp = 1;
                    %                             else
                    %                                 temp = 0.5;
                    %                             end
                    %                         else
                    %                             temp = 0.5;
                    %                         end
                    %                         structTemp.Model.Atom(1,k).tempFactor = 999.9.* temp;
                    %                     end
                    
                    
                    curResN = [structTemp.Model.Atom.resSeq];
                    curChain = [structTemp.Model.Atom.chainID];
                    curChaintemp = curChain;
                    tempFactors = [structTemp.Model.Atom.tempFactor];
                    for k = 1:size(tempOld,1)
                        ik = curChain==tempOld{k};
                        curChaintemp(ik) = tempNew{k};
                    end
                    curChain = curChaintemp;
                    for k = 1:size(seqVec,2)
                        L =  ((chainVec(k)==curChain)&(seqVec(k)==curResN)) ;
                        app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': ' num2str(k) ' of ' num2str(size(seqVec,2))]; pause(0.001);


                        temp = 0.5;
                        if ~isempty(app.prsData.permutations.meanPermMTR(1,k))
                            meanV = app.prsData.permutations.meanPermMTR(1,k);
                            stdV = app.prsData.permutations.stdPermMTR(1,k);
                            mtr = app.prsData.vMTRpdb(1,m).vMTR(1,k);
                            if mtr<(meanV-(stdV*2))
                                temp = 0;
                            elseif mtr>(meanV+(stdV*2))
                                temp = 1;
                            else
                                temp = 0.5;
                            end
                        else
                            temp = 0.5;
                        end
                        tempFactors(L) = 999.9.* temp;
                        %                                 [structTemp.Model.Atom(L).tempFactor] = [999.9.* temp]
                    end
                    %                             [A.B] = disperse(1:10)
                    %                             [structTemp.Model.Atom.tempFactor] = disperse(tempFactors);
                    ttt=num2cell([tempFactors]);
                    [structTemp.Model.Atom(1,:).tempFactor] = ttt{:};


                    toc(t);
                    curFolder = app.prsData.resultsFolder;
                    curTable = app.inputTable.Data;
                    sugName = '';
                    for i = 1:size(curTable,1)
                        if i==1
                            sugName = curTable{i,1};
                        else
                            sugName = [sugName '_' curTable{i,1}];
                        end
                    end
                    sugName = [sugName '-pdb-' items{j} '-' app.prsData.permutations.how '-' app.prsData.permutations.factor];
                    sugName = [sugName '_' datestr(now,'yyyymmdd')];
                    sugName = replace(sugName,': ','_');
                    
                    soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                        {'*.pdb','save vMTR permutation significance pdb structural file(*.pdb)'}, ...
                        'Select a file to save the current selected vMTR permutation significance pdb',[curFolder '\' sugName '.pdb']);
                    
                    if selectedFolder ~= 0
                        try
                            pdbwrite([selectedFolder,selectedFile],structTemp)
                        catch ME
                            temptemp = struct()
                            temptemp.Model = structTemp.Model
                            pdbwrite([selectedFolder,selectedFile],temptemp)
                        end
                        app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                    else
                        app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                        return
                    end


                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a  raster plot of the included residues?', ...
                       	'Save a raster plot', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            meanV = [app.prsData.permutations.meanPermMTR];
                            stdV = [app.prsData.permutations.stdPermMTR];
                            mtr = [app.prsData.vMTRpdb(1,m).vMTR];
                            
                            fig = figure;
                            hold on
                            chainsFound = {};
                            chainLine = {};
                            for j = 1:size(meanV,2)
                                chain = app.prsData.pdbMTR(j).chainID;
                                if j==1
                                    chainsFound = {chain};
                                    chainLine = {0};
                                    wh = 1;
                                else
                                    wh = 0;
                                    for k = 1:size(chainsFound,2)
                                        if chainsFound{k}==chain
                                            wh = k;
                                        end
                                    end
                                    if wh==0
                                        x=j;
                                        chainsFound = mergecell(chainsFound,{chain},1);
                                        chainLine = mergecell(chainLine,{(size(chainsFound,2)-1)*1.1},1);
                                        wh = k+1;
                                    end
                                end
                                if mtr(j)<(meanV(j)-(stdV(j)*2))
                                    c = [0 0 1];
                                    plot([app.prsData.pdbMTR(j).resSeq app.prsData.pdbMTR(j).resSeq]',[chainLine{wh} chainLine{wh}+1]','Color',c);
                                end
                                if mtr(j)>(meanV(j)+(stdV(j)*2))
                                    c = [1 0 0];
                                    plot([app.prsData.pdbMTR(j).resSeq app.prsData.pdbMTR(j).resSeq]',[chainLine{wh} chainLine{wh}+1]','Color',c);
                                end
                            end
                            title(['Significance Determined by Permutatation Analysis: ' factor]);
                            xlabel('residues number (PDB numbering)')
                            axis('tight')
                            [a] = xlim;
                            xlim([1 max([app.prsData.pdbMTR(:).resSeq])]);
                            yticks([(chainLine{:})]+0.5);
                            yticklabels(chainsFound)
                            ylabel('chain')
                            
                            
                            print(gcf,[selectedFolder,replace(selectedFile,'.pdb','-rasterPlot.png')],'-dpng','-r900')
                            
                            
                            answer2 = questdlg('Would you like to save a vector file?', ...
                               	'Save a Vector File', ...
                               	'Yes','No','Yes');
                            switch answer2
                                case 'Yes'
                                    fig.Renderer = 'painters';
                                    saveas(fig,replace([selectedFolder,selectedFile],'.pdb','-rasterPlot.svg'),'svg');
                                case 'No'
                            end
                            close
                        case 'No'
                    end
                    
                end
            else
                
                app.StatusEditField.Value = 'idle - error - finish analyzing the data first'; pause(0.01);
                
            end
        end

        % Button pushed function: 
        % SaveExamplePermutationFactorPlotButton
        function SaveExamplePermutationFactorPlotButtonPushed(app, event)
            
            app.StatusEditField.Value = ['saving saving: vMTR permutation evidence plot']; pause(0.001);
            if (sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3) && (sum(app.PermutationsCalculatedLamp.Color == [0 1 0])==3)
                if strcmp(app.prsData.permutations.how,'MTR Score')
                    return
                end
                option = app.prsData.permutations.factor;
                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                
                t = tiledlayout(1,2,'TileSpacing','Compact');
                t.Padding = 'compact';
                
                nexttile;
                toplot = getfield(app.prsData.factors,app.prsData.permutations.factor);
                im = zeros(size(toplot,1),size(toplot,2),3,'uint8');
                im(:,:,1) = ~toplot.*255;
                im(:,:,2) = ~toplot.*255;
                im(:,:,3) = ~toplot.*255;
                imagesc(im);
                %                 imagesc(getfield(app.prsData.factors,app.prsData.permutations.factor))
                
                nexttile;
                toplot = app.prsData.permutations.permutedfactor;
                im2 = zeros(size(toplot,1),size(toplot,2),3,'uint8');
                im2(:,:,1) = ~toplot.*255;
                im2(:,:,2) = ~toplot.*255;
                im2(:,:,3) = ~toplot.*255;
                imagesc(im2);
                %                 imagesc(app.prsData.permutations.permutedfactor)
                
                t.Children(1).PlotBoxAspectRatio = [1 1 1];
                t.Children(2).PlotBoxAspectRatio = [1 1 1];
                t.Parent.Position = [100,100,1400,700];
                
                t.Title.String = ['Permutation Example: ' sugName ' - ' app.prsData.permutations.factor ' - ' app.prsData.permutations.how];
                t.Children(2).Title.String = ['vMTR Includsion Matrix: ' app.prsData.permutations.factor];
                t.Children(1).Title.String = ['vMTR Permuted Includsion Matrix: ' app.prsData.permutations.factor];
                
                t.Children(1).XLabel.String = ['Residue (pdb numbering)'];
                t.Children(2).XLabel.String = ['Residue (pdb numbering)'];
                t.Children(1).YLabel.String = ['Residue (pdb numbering)'];
                t.Children(2).YLabel.String = ['Residue (pdb numbering)'];


                sugName = [sugName '-vMTR_Factor_Matirx_permutationExample-' app.prsData.permutations.factor '-' app.prsData.permutations.how];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','image file (*.png)'}, ...
                    'Select a file to save the current permutation example plot',[curFolder '\' sugName '.png']);
                
                if selectedFolder ~= 0
                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            t.Parent.Renderer = 'painters';
                            saveas(t,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                            imwrite(im,replace([selectedFolder,selectedFile],'.png','-pixelFieldA.png'),'png')
                            imwrite(im2,replace([selectedFolder,selectedFile],'.png','-pixelFieldB.png'),'png')
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                end


            else
                
                app.StatusEditField.Value = 'idle - error - finish analyzing the data first'; pause(0.01);
                
            end
        end

        % Button pushed function: RealignButton
        function RealignButtonPushed(app, event)
            app.StatusEditField.Value = ['realigning:' event.Source.Text]; pause(0.01);
            
            app.DMTRCalculatedLamp.Color = [0 0 0];
            curTable = app.inputTable.Data;
            
            if isempty(curTable)
                app.StatusEditField.Value = 'idle - error, no data  to load'; pause(0.01);
                app.DataLoadedLamp.Color = [1 0 0];
                return
            else
                
                
                alignSequences(app)
                
                items = {};
                for i = 1:(size(app.prsData.alignments,1) .* size(app.prsData.alignments,2))
                    items{i} = [app.prsData.alignments{i}.ID ': chain-' app.prsData.alignments{i}.chain];
                end
                app.GeneAlignmenttoshowDropDown.Items = items;
                app.GeneAlignmenttoshowDropDown.Value = items{1};
                app.GenetoViewDropDown.Items = items;
                app.GenetoViewDropDown.Value = items{1};
                app.Diff_GenetoViewDropDown.Items = items;
                app.Diff_GenetoViewDropDown.Value = items{1};
                app.Perm_GenetoViewDropDown.Items = items;
                app.Perm_GenetoViewDropDown.Value = items{1};
                updateSequenceTab(app)
                
                items = {};
                for i = 1:(size(app.rawData.files.pdb,2))
                    temp = app.rawData.files.pdb{i};
                    temp = temp(find(temp=='\',1,'last'):end);
                    add=1;
                    for j = 1:size(items,2)
                        if strcmp(items{j},temp)
                            add = 0;
                        end
                    end
                    if add
                        items = mergecell(items,{temp},1);
                    end
                end
                items = mergecell({'all'},items,1);
                app.PDBtoshowDropDown.Items = items;
                app.PDBtoshowDropDown.Value = items{1};
                
                %                 app.CalcuationFactorDropDown.Value = app.CalcuationFactorDropDown.Items{1};
                %                 app.CalcuationFactortoShowDropDown.Value = app.CalcuationFactortoShowDropDown.Items{1};
                %
                pause(0.01);
                updateMTRFactorTAB(app)
                pause(0.01);
                updateMTRresultsTAB(app)
                
                %                 app.Diff_Factor1DropDown.Items = mergecell({'swMTR'},app.CalcuationFactortoShowDropDown.Items,1);
                %                 app.Diff_Factor2DropDown.Items = app.Diff_Factor1DropDown.Items;
                %                 app.Diff_Factor1DropDown.Value = app.Diff_Factor1DropDown.Items{1};
                %                 app.Diff_Factor2DropDown.Value = app.Diff_Factor1DropDown.Items{2};
                %
                app.DataLoadedLamp.Color = [0 1 0];
                app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
            end
        end

        % Button pushed function: Save3DMTRFactorPDBButton
        function Save3DMTRFactorPDBButtonPushed(app, event)
            app.StatusEditField.Value = ['Calculating Calculating: 3DMTR factor pdb']; pause(0.01);
            
            if sum(app.DataLoadedLamp.Color == [0 1 0])==3
                option = app.CalcuationFactorDropDown.Value;
                option2 = app.ResiduetohighlightinPBDDropDown.Value;
                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                
                if strcmp(option, 'rawDistances')
                    sugName = [sugName '-distanceFromResiduePDB-' option '-' option2];
                    sugName = [sugName '_' datestr(now,'yyyymmdd')];
                    sugName = replace(sugName,': ','_');
                    sugName = replace(sugName,':','_');
                    
                    soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                        {'*.pdb','image file (*.pdb)'}, ...
                        'Select a file to save a PDB of the distances from a selected residues',[curFolder '\' sugName '.pdb']);
                    
                    if selectedFolder ~= 0
                        cur = 0;
                        for j = 1:size(app.ResiduetohighlightinPBDDropDown.Items,2)
                            if strcmp(app.ResiduetohighlightinPBDDropDown.Items{j},option2)
                                cur = j;
                            end
                        end
                        tempDist = app.prsData.distances(cur,:);
                        %                         normDist = tempDist./(max(max(app.prsData.distances)));
                        normDist = tempDist./(max(tempDist));


                        for j = 1:size(app.rawData.loadedPDBs,2)
                            app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2))]; pause(0.0001);
                            t = tic;
                            structTemp = app.rawData.loadedPDBs{1,j};
                            chainVec = [app.prsData.pdbMTR.chainID];
                            seqVec = [app.prsData.pdbMTR.resSeq];
                            tempOld = [split(app.rawData.oldchains(j),',')];
                            tempNew = [split(app.rawData.chains(j),',')];
                            %                             t = tic;
                            %                             for k = 1:size(structTemp.Model.Atom,2)
                            %                                 %
                            %                                 curResN = structTemp.Model.Atom(1,k).resSeq;
                            %                                 curChain = structTemp.Model.Atom(1,k).chainID;
                            %
                            %                                 tempC = find([tempOld{:}]==curChain,1,'first');
                            %                                 if ~isempty(tempC)
                            %                                     curChain = tempNew{(tempC)};
                            %                                 end
                            %
                            %                                 L = 0;
                            %                                 L = find( ((chainVec==curChain)&(seqVec==curResN)) ,1,"first"  );
                            %                                 app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': atom ' num2str(k) ' of ' num2str(size(structTemp.Model.Atom,2)) ': applying residue ' num2str(L)]; pause(0.001);
                            %
                            %                                 temp = 0.5;
                            %                                 if ~isempty(L)
                            %                                     temp = normDist(L);
                            %                                 else
                            %                                     temp = 0.5;
                            %                                 end
                            %                                 structTemp.Model.Atom(1,k).tempFactor = 999.9.* temp;
                            %                             end
                            %                             toc(t);
                            
                            %                             t = tic;
                            curResN = [structTemp.Model.Atom.resSeq];
                            curChain = [structTemp.Model.Atom.chainID];
                            curChaintemp = curChain;
                            tempFactors = [structTemp.Model.Atom.tempFactor];
                            for k = 1:size(tempOld,1)
                                ik = curChain==tempOld{k};
                                curChaintemp(ik) = tempNew{k};
                            end
                            curChain = curChaintemp;
                            for k = 1:size(seqVec,2)
                                L =  ((chainVec(k)==curChain)&(seqVec(k)==curResN)) ;
                                app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': ' num2str(k) ' of ' num2str(size(seqVec,2))]; pause(0.001);
                                
                                temp = 0.5;
                                if ~isempty(normDist(k))
                                    temp = normDist(k);
                                else
                                    temp = 0.5;
                                end
                                if temp>1
                                    temp = 1;
                                end
                                if temp<0
                                    temp=0;
                                end
                                tempFactors(L) = 999.9.* temp;
                                %                                 [structTemp.Model.Atom(L).tempFactor] = [999.9.* temp]
                            end
                            %                             [A.B] = disperse(1:10)
                            %                             [structTemp.Model.Atom.tempFactor] = disperse(tempFactors);
                            ttt=num2cell([tempFactors]);
                            [structTemp.Model.Atom(1,:).tempFactor] = ttt{:};
                            %                             structTemp.Model.Atom = setfield(structTemp.Model.Atom,'tempFactor',tempFactors)
                            toc(t);
                            
                            header = app.rawData.loadedPDB_IDs{j};
                            s = find(header=='\');
                            header = header(s(end)+1:end-4);
                            pdbwrite([selectedFolder,header '_' selectedFile],structTemp)
                            try
                                pdbwrite([selectedFolder,header '_' selectedFile],structTemp)
                            catch ME
                                temptemp = struct()
                                temptemp.Model = structTemp.Model
                                pdbwrite([selectedFolder,header '_' selectedFile],temptemp)
                            end
                            
                        end


                        soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a  raster plot of the distances?', ...
                           	'Save a raster plot', ...
                           	'Yes','No','Yes');
                        switch answer
                            case 'Yes'
                                cmap = redblue(255);
                                fig = figure;
                                hold on
                                chainsFound = {};
                                chainLine = {};
                                for j = 1:size(normDist,2)
                                    chain = app.prsData.pdbMTR(j).chainID;
                                    if j==1
                                        chainsFound = {chain};
                                        chainLine = {0};
                                        wh = 1;
                                    else
                                        wh = 0;
                                        for k = 1:size(chainsFound,2)
                                            if chainsFound{k}==chain
                                                wh = k;
                                            end
                                        end
                                        if wh==0
                                            chainsFound = mergecell(chainsFound,{chain},1);
                                            chainLine = mergecell(chainLine,{(size(chainsFound,2)-1)*1.1},1);
                                            wh = k+1;
                                        end
                                    end
                                    plot([app.prsData.pdbMTR(j).resSeq app.prsData.pdbMTR(j).resSeq]',[chainLine{wh} chainLine{wh}+1]','Color',cmap(1+round(normDist(j)*254),:));
                                end
                                title(['Residue Distances from ' option2]);
                                xlabel('residues number (PDB numbering)')
                                axis('tight')
                                [a] = xlim;
                                xlim([1 max([app.prsData.pdbMTR(j).resSeq])]);
                                yticks([(chainLine{:})]+0.5);
                                yticklabels(chainsFound)
                                ylabel('chain')
                                c = colorbar;
                                colormap(redblue)
                                %                                 c.TickLabels = num2cell(c.Ticks.*max(max(app.prsData.distances)));
                                c.TickLabels = num2cell(c.Ticks.*max(tempDist));
                                
                                print(gcf,[selectedFolder,replace(selectedFile,'.pdb','.png')],'-dpng','-r900')
                                
                                
                                answer2 = questdlg('Would you like to save a vector file?', ...
                                   	'Save a Vector File', ...
                                   	'Yes','No','Yes');
                                switch answer2
                                    case 'Yes'
                                        fig.Renderer = 'painters';
                                        saveas(fig,replace([selectedFolder,selectedFile],'.pdb','.svg'),'svg');
                                    case 'No'
                                end
                                close
                            case 'No'
                        end
                        
                        
                        app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                    else
                        app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                    end
                else
                    sugName = [sugName '-includedResiduesPDB-' option '-' option2];
                    sugName = [sugName '_' datestr(now,'yyyymmdd')];
                    sugName = replace(sugName,': ','_');
                    sugName = replace(sugName,':','_');
                    
                    soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                        {'*.pdb','image file (*.pdb)'}, ...
                        'Select a file to save a PDB of the residues included in the 3DMTR calculation',[curFolder '\' sugName '.pdb']);
                    
                    if selectedFolder ~= 0
                        cur = 0;
                        for j = 1:size(app.ResiduetohighlightinPBDDropDown.Items,2)
                            if strcmp(app.ResiduetohighlightinPBDDropDown.Items{j},option2)
                                cur = j;
                            end
                        end
                        eval(['curFact = app.prsData.pdbMTR(cur).'  option ';']);
                        
                        
                        for j = 1:size(app.rawData.loadedPDBs,2)
                            app.StatusEditField.Value = ['saving saving: 3DMTR inclusion factor pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2))]; pause(0.0001);
                            t = tic;
                            structTemp = app.rawData.loadedPDBs{1,j};
                            chainVec = [app.prsData.pdbMTR.chainID];
                            seqVec = [app.prsData.pdbMTR.resSeq];
                            tempOld = [split(app.rawData.oldchains(j),',')];
                            tempNew = [split(app.rawData.chains(j),',')];
                            %                             for k = 1:size(structTemp.Model.Atom,2)
                            %                                 %
                            %                                 curResN = structTemp.Model.Atom(1,k).resSeq;
                            %                                 curChain = structTemp.Model.Atom(1,k).chainID;
                            %
                            %                                 tempC = find([tempOld{:}]==curChain,1,'first');
                            %                                 if ~isempty(tempC)
                            %                                     curChain = tempNew{(tempC)};
                            %                                 end
                            %
                            %                                 L = 0;
                            %                                 L = find( ((chainVec==curChain)&(seqVec==curResN)) ,1,"first"  );
                            %                                 app.StatusEditField.Value = ['saving saving: 3DMTR inclusion factor pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': atom ' num2str(k) ' of ' num2str(size(structTemp.Model.Atom,2)) ': applying residue ' num2str(L)]; pause(0.001);
                            %
                            %
                            %                                 structTemp.Model.Atom(1,k).tempFactor = 999.9.* temp;
                            %                             end
                            
                            
                            curResN = [structTemp.Model.Atom.resSeq];
                            curChain = [structTemp.Model.Atom.chainID];
                            curChaintemp = curChain;
                            tempFactors = [structTemp.Model.Atom.tempFactor];
                            for k = 1:size(tempOld,1)
                                ik = curChain==tempOld{k};
                                curChaintemp(ik) = tempNew{k};
                            end
                            curChain = curChaintemp;
                            for k = 1:size(seqVec,2)
                                L =  ((chainVec(k)==curChain)&(seqVec(k)==curResN)) ;
                                app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': ' num2str(k) ' of ' num2str(size(seqVec,2))]; pause(0.001);
                                
                                temp = 0.5;
                                if ~isempty(curFact(k))
                                    if curFact(k)
                                        temp = 0;
                                        if k==cur
                                            temp = 1;
                                        end
                                    else
                                        temp = 0.5;
                                    end
                                    
                                else
                                    temp = 0.5;
                                end
                                tempFactors(L) = 999.9.* temp;
                                %                                 [structTemp.Model.Atom(L).tempFactor] = [999.9.* temp]
                            end
                            %                             [A.B] = disperse(1:10)
                            %                             [structTemp.Model.Atom.tempFactor] = disperse(tempFactors);
                            ttt=num2cell([tempFactors]);
                            [structTemp.Model.Atom(1,:).tempFactor] = ttt{:};


                            toc(t);
                            
                            header = app.rawData.loadedPDB_IDs{j};
                            s = find(header=='\');
                            header = header(s(end)+1:end-4);
                            try
                                pdbwrite([selectedFolder,header '_' selectedFile],structTemp)
                            catch ME
                                temptemp = struct()
                                temptemp.Model = structTemp.Model
                                pdbwrite([selectedFolder,header '_' selectedFile],temptemp)
                            end
                            
                        end


                        soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a  raster plot of the included residues?', ...
                           	'Save a raster plot', ...
                           	'Yes','No','Yes');
                        switch answer
                            case 'Yes'
                                fig = figure;
                                hold on
                                chainsFound = {};
                                chainLine = {};
                                for j = 1:size(curFact,1)
                                    chain = app.prsData.pdbMTR(j).chainID;
                                    if j==1
                                        chainsFound = {chain};
                                        chainLine = {0};
                                        wh = 1;
                                    else
                                        wh = 0;
                                        for k = 1:size(chainsFound,2)
                                            if chainsFound{k}==chain
                                                wh = k;
                                            end
                                        end
                                        if wh==0
                                            x=j;
                                            chainsFound = mergecell(chainsFound,{chain},1);
                                            chainLine = mergecell(chainLine,{(size(chainsFound,2)-1)*1.1},1);
                                            wh = k+1;
                                        end
                                    end
                                    if curFact(j)
                                        if j == cur
                                            c = [1 0 0];
                                        else
                                            c = [0 0 1];
                                        end
                                        plot([app.prsData.pdbMTR(j).resSeq app.prsData.pdbMTR(j).resSeq]',[chainLine{wh} chainLine{wh}+1]','Color',c);
                                    end
                                end
                                title(['Residue included: ' option2]);
                                xlabel('residues number (PDB numbering)')
                                axis('tight')
                                [a] = xlim;
                                xlim([1 max([app.prsData.pdbMTR(j).resSeq])]);
                                yticks([(chainLine{:})]+0.5);
                                yticklabels(chainsFound)
                                ylabel('chain')
                                
                                
                                print(gcf,[selectedFolder,replace(selectedFile,'.pdb','.png')],'-dpng','-r900')
                                
                                
                                answer2 = questdlg('Would you like to save a vector file?', ...
                                   	'Save a Vector File', ...
                                   	'Yes','No','Yes');
                                switch answer2
                                    case 'Yes'
                                        fig.Renderer = 'painters';
                                        saveas(fig,replace([selectedFolder,selectedFile],'.pdb','.svg'),'svg');
                                    case 'No'
                                end
                                close
                            case 'No'
                        end
                        
                        app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                    else
                        app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                    end
                end
            end
        end

        % Button pushed function: SaveDiff3DMTRPDBButton
        function SaveDiff3DMTRPDBButtonPushed(app, event)
            app.StatusEditField.Value = ['Calculating Calculating: 3DMTR difference pdb']; pause(0.01);
            
            if sum(app.DMTRCalculatedLamp.Color == [0 1 0])==3
                
                
                gene = app.Diff_GenetoViewDropDown.Value;
                
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                
                factor1 = app.Diff_Factor1DropDown.Value;
                factor2 = app.Diff_Factor2DropDown.Value;
                cj=0;
                ci=0;
                for j = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,j).meth,factor1)
                        cj =j;
                        break;
                    end
                end
                for i = 1:size(app.prsData.vMTRpdb,2)
                    if strcmp(app.prsData.vMTRpdb(1,i).meth,factor2)
                        ci = i;
                        
                        break;
                    end
                end
                
                
                tt = [app.prsData.pdbMTR.chainID];
                toPlotI2 = true(size(tt,1),size(tt,2));
                tt = [app.prsData.pdbMTR.chainID];
                toPlotI1 = true(size(tt,1),size(tt,2));
                
                if strcmp(factor2,'swMTR')
                    %                     curMTR2 = [app.prsData.pdbMTR.mtrSW];
                    %                     resNB = [app.prsData.pdbMTR.resSeq];
                    %                     toremoveA =[];
                    
                    resNA = zeros(size(toPlotI2,1),size(toPlotI2,2));
                    toremoveA =[];
                    for j = 1:size(toPlotI2,2)
                        tt = app.prsData.pdbMTR(j).mtrSW;
                        if isempty(tt)
                            resNA(j) = 0;
                            toremoveA = [toremoveA j];
                            curMTR2(j) = 0;
                        else
                            resNA(j) = app.prsData.pdbMTR(j).resSeq;
                            curMTR2(j) = tt;
                        end
                    end
                else
                    %                     curMTR2 = [app.prsData.vMTRpdb(1,ci).vMTR];
                    
                    resNA = zeros(size(toPlotI2,1),size(toPlotI2,2));
                    toremoveA =[];
                    for j = 1:size(toPlotI2,2)
                        tt = app.prsData.vMTRpdb(1,ci).vMTR(j);
                        if isempty(tt)
                            resNA(j) = 0;
                            toremoveA = [toremoveA j];
                            curMTR2(j) = 0;
                        else
                            resNA(j) = app.prsData.pdbMTR(j).resSeq;
                            curMTR2(j) = [app.prsData.vMTRpdb(1,ci).vMTR(j)];
                        end
                    end
                end
                
                if strcmp(factor1,'swMTR')
                    curMTR1 = [app.prsData.pdbMTR.mtrSW];
                    resNA = [app.prsData.pdbMTR.resSeq];
                    toremoveB =[];
                    
                    resNB = zeros(size(toPlotI1,1),size(toPlotI1,2));
                    toremoveB =[];
                    for j = 1:size(toPlotI1,2)
                        tt = app.prsData.pdbMTR(j).mtrSW;
                        if isempty(tt)
                            resNB(j) = 0;
                            toremoveB = [toremoveA j];
                            curMTR1(j) = 0;
                        else
                            resNB(j) = app.prsData.pdbMTR(j).resSeq;
                            curMTR1(j) = tt;
                        end
                    end
                else
                    %                     curMTR1 = [app.prsData.vMTRpdb(1,cj).vMTR];
                    
                    resNB = zeros(size(toPlotI1,1),size(toPlotI1,2));
                    toremoveB =[];
                    for j = 1:size(toPlotI1,2)
                        if isempty(app.prsData.vMTRpdb(1,cj).vMTR(j))
                            resNB(j) = 0;
                            toremoveB = [toremoveB j];
                            curMTR1(j) = 0;
                        else
                            resNB(j) = app.prsData.pdbMTR(j).resSeq;
                            curMTR1(j) = [app.prsData.vMTRpdb(1,cj).vMTR(j)];
                        end
                    end
                end
                toremove = unique([toremoveA toremoveB]);
                %                 if ~isempty(toremove)
                %                     resNA(toremove)=[];
                %                     resNB(toremove)=[];
                %                     toPlotI1(toremove)=[];
                %                     toPlotI2(toremove)=[];
                %                 end
                
                %resN(toPlotI1)
                tempDiff = (((curMTR2(toPlotI2) - curMTR1(toPlotI1))+1)./2);


                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                sugName = [sugName '-diff_vMTR-' factor2 '_minus_' factor1];
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.pdb','save 3DMTR difference pdb (*.pdb)'}, ...
                    'Select a file to save 3DMTR difference pdb',[curFolder '\' sugName '.pdb']);
                
                if selectedFolder ~= 0


                    for j = 1:size(app.rawData.loadedPDBs,2)
                        app.StatusEditField.Value = ['saving saving: 3DMTR difference pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2))]; pause(0.0001);
                        
                        t = tic;
                        structTemp = app.rawData.loadedPDBs{1,j};
                        chainVec = [app.prsData.pdbMTR.chainID];
                        seqVec = [app.prsData.pdbMTR.resSeq];
                        tempOld = [split(app.rawData.oldchains(j),',')];
                        tempNew = [split(app.rawData.chains(j),',')];
                        %                         for k = 1:size(structTemp.Model.Atom,2)
                        %                             %
                        %                             curResN = structTemp.Model.Atom(1,k).resSeq;
                        %                             curChain = structTemp.Model.Atom(1,k).chainID;
                        %
                        %                             tempC = find([tempOld{:}]==curChain,1,'first');
                        %                             if ~isempty(tempC)
                        %                                 curChain = tempNew{(tempC)};
                        %                             end
                        %
                        %                             L = 0;
                        %                             L = find( ((chainVec==curChain)&(seqVec==curResN)) ,1,"first"  );
                        %                             app.StatusEditField.Value = ['saving saving: 3DMTR difference pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': atom ' num2str(k) ' of ' num2str(size(structTemp.Model.Atom,2)) ': applying residue ' num2str(L)]; pause(0.001);
                        %
                        %                             temp = 0.5;
                        %                             if ~isempty(L)
                        %                                 temp = tempDiff(L);
                        %                             else
                        %                                 temp = 0.5;
                        %                             end
                        %                             if temp>1
                        %                                 temp = 1;
                        %                             end
                        %                             if (k == toremove)>0
                        %                                 temp = 0.5
                        %                             end
                        %                             if temp<0
                        %                                 temp = 0;
                        %                             end
                        %                             structTemp.Model.Atom(1,k).tempFactor = 999.9.* temp;
                        %                         end


                        curResN = [structTemp.Model.Atom.resSeq];
                        curChain = [structTemp.Model.Atom.chainID];
                        curChaintemp = curChain;
                        tempFactors = [structTemp.Model.Atom.tempFactor];
                        for k = 1:size(tempOld,1)
                            ik = curChain==tempOld{k};
                            curChaintemp(ik) = tempNew{k};
                        end
                        curChain = curChaintemp;
                        for k = 1:size(seqVec,2)
                            L =  ((chainVec(k)==curChain)&(seqVec(k)==curResN)) ;
                            app.StatusEditField.Value = ['saving saving: 3DMTR distance pdb: pdb #' num2str(j) ' of ' num2str(size(app.rawData.loadedPDBs,2)) ': ' num2str(k) ' of ' num2str(size(seqVec,2))]; pause(0.001);


                            temp = 0.5;
                            if ~isempty(tempDiff(k))
                                temp = tempDiff(k);
                            else
                                temp = 0.5;
                            end
                            if temp>1
                                temp = 1;
                            end
                            if (k == toremove)>0
                                temp = 0.5
                            end
                            if temp<0
                                temp = 0;
                            end
                            
                            
                            tempFactors(L) = 999.9.* temp;
                            %                                 [structTemp.Model.Atom(L).tempFactor] = [999.9.* temp]
                        end
                        %                             [A.B] = disperse(1:10)
                        %                             [structTemp.Model.Atom.tempFactor] = disperse(tempFactors);
                        ttt=num2cell([tempFactors]);
                        [structTemp.Model.Atom(1,:).tempFactor] = ttt{:};


                        toc(t);
                        
                        header = app.rawData.loadedPDB_IDs{j};
                        s = find(header=='\');
                        header = header(s(end)+1:end-4);
                        try
                            pdbwrite([selectedFolder,header '_' selectedFile],structTemp)
                        catch ME
                            temptemp = struct()
                            temptemp.Model = structTemp.Model
                            pdbwrite([selectedFolder,header '_' selectedFile],temptemp)
                        end
                        
                    end


                    soundsc(makeNote(16000,1,440,0,0,'dampenE'),16000); answer = questdlg('Would you like to save a  raster plot of the differences?', ...
                       	'Save a raster plot?', ...
                       	'Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            cmap = redblue(255);
                            fig = figure;
                            hold on
                            chainsFound = {};
                            chainLine = {};
                            tempDiff(tempDiff<0.0039) = 0.0039;
                            tempDiff(tempDiff>(1-0.0039)) = (1-0.0039);
                            for j = 1:size(tempDiff,2)
                                chain = app.prsData.pdbMTR(j).chainID;
                                if (j == toremove)>0
                                    continue
                                end
                                if j==1
                                    chainsFound = {chain};
                                    chainLine = {0};
                                    wh = 1;
                                else
                                    wh = 0;
                                    for k = 1:size(chainsFound,2)
                                        if chainsFound{k}==chain
                                            wh = k;
                                        end
                                    end
                                    if wh==0
                                        chainsFound = mergecell(chainsFound,{chain},1);
                                        chainLine = mergecell(chainLine,{(size(chainsFound,2)-1)*1.1},1);
                                        wh = k+1;
                                    end
                                end
                                if isnan(tempDiff(j))
                                    tempDiff(j) = 0.5;
                                end
                                tempDiff(tempDiff<0) = 0.0039;
                                tempDiff(tempDiff>1) = 1;
                                plot([app.prsData.pdbMTR(j).resSeq app.prsData.pdbMTR(j).resSeq]',[chainLine{wh} chainLine{wh}+1]','Color',cmap(1+round(tempDiff(j)*254),:));
                            end
                            title(['Diff in MTR (' factor2 ' - ' factor1 ')']);
                            xlabel('residues number (PDB numbering)')
                            axis('tight')
                            [a] = xlim;
                            xlim([1 max([app.prsData.pdbMTR(j).resSeq])]);
                            yticks([(chainLine{:})]+0.5);
                            yticklabels(chainsFound)
                            ylabel('chain')
                            
                            c = colorbar;
                            colormap(redblue)
                            caxis([-1 1])
                            
                            print(gcf,[selectedFolder,replace(selectedFile,'.pdb','.png')],'-dpng','-r900')
                            
                            
                            answer2 = questdlg('Would you like to save a vector file?', ...
                               	'Save a Vector File', ...
                               	'Yes','No','Yes');
                            switch answer2
                                case 'Yes'
                                    fig.Renderer = 'painters';
                                    saveas(fig,replace([selectedFolder,selectedFile],'.pdb','.svg'),'svg');
                                case 'No'
                            end
                            close
                        case 'No'
                    end


                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                    return
                end


            end
        end

        % Button pushed function: SaveMTRFactorSpreadsheetButton
        function SaveMTRFactorSpreadsheetButtonPushed(app, event)
            app.StatusEditField.Value = ['Calculating Calculating: Factor Spreadsheet']; pause(0.01);
            if sum(app.DataLoadedLamp.Color == [0 1 0])==3
                option = app.CalcuationFactorDropDown.Value;
                
                if strcmp(option,'rawDistances')
                    
                    resTemp = app.ResiduetohighlightinPBDDropDown.Items;
                    curFolder = app.prsData.resultsFolder;
                    curTable = app.inputTable.Data;
                    sugName = '';
                    for i = 1:size(curTable,1)
                        if i==1
                            sugName = curTable{i,1};
                        else
                            sugName = [sugName '_' curTable{i,1}];
                        end
                    end


                    sugName = [sugName '-residuesDistances'];
                    sugName = [sugName '_' datestr(now,'yyyymmdd')];
                    sugName = replace(sugName,': ','_');
                    
                    soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                        {'*.xlsx','spreadsheet file (*.xlsx)'}, ...
                        'Select a file to save a record of the residues distances',[curFolder '\' sugName '.xlsx']);
                    
                    if selectedFolder ~= 0
                        tempA = {};
                        temp2A = {};
                        for j = 1:size(app.prsData.pdbMTR,2)
                            app.StatusEditField.Value = ['Calculating Calculating: Residue Distance Spreadsheet: Residue ' num2str(j) ' of ' num2str(size(app.prsData.pdbMTR,2))]; pause(0.0001);
                            temp2A = resTemp(j);
                            for k = 1:size(app.prsData.pdbMTR,2)
                                
                                
                                temp2A = mergecell(temp2A,  {num2str(app.prsData.distances(j,k))}  ,1);


                            end
                            tempA = mergecell(tempA,temp2A,2);
                        end
                        
                        temp3A = {' '};
                        for j = 1:size(app.prsData.pdbMTR,2)
                            temp3A = mergecell(temp3A,resTemp(j),1);
                        end
                        tempA = mergecell(temp3A,tempA,2);
                        
                        writecell(tempA,replace([selectedFolder,selectedFile],'.xlsx','-residuesDistances.xlsx' ));
                        app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                    else
                        app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                    end
                    
                else
                    resTemp = app.ResiduetohighlightinPBDDropDown.Items;
                    curFolder = app.prsData.resultsFolder;
                    curTable = app.inputTable.Data;
                    sugName = '';
                    for i = 1:size(curTable,1)
                        if i==1
                            sugName = curTable{i,1};
                        else
                            sugName = [sugName '_' curTable{i,1}];
                        end
                    end
                    sugNameA = sugName;
                    chainLookup = struct;
                    chainLookup.chain = '';
                    n=0;
                    for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                        curC = split(app.rawData.chains{j},',');
                        for k = 1:size(curC,1)
                            n = n + 1;
                            chainLookup.chain(n) = (curC{k});
                            chainLookup.ID{n} = {app.rawData.ID{j}};
                            chainLookup.chainCounts(n) = 0;
                            chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                        end
                    end
                    
                    sugName = [sugName '-includedResidues-' option];
                    sugName = [sugName '_' datestr(now,'yyyymmdd')];
                    sugName = replace(sugName,': ','_');
                    
                    soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                        {'*.xlsx','spreadsheet file (*.xlsx)'}, ...
                        'Select a file to save a record of the included residues in the 3DMTR calcualtion',[curFolder '\' sugName '.xlsx']);
                    
                    if selectedFolder ~= 0
                        tempA = {};
                        temp2A = {};
                        tempB = {};
                        temp2B = {};
                        tempC = {};
                        temp2C = {};
                        tempD = {};
                        temp2D = {};
                        for j = 1:size(app.prsData.pdbMTR,2)
                            app.StatusEditField.Value = ['Calculating Calculating: Factor Spreadsheet: Residue ' num2str(j) ' of ' num2str(size(app.prsData.pdbMTR,2))]; pause(0.0001);
                            sep = find(resTemp{j}==':');
                            temp2A = {[resTemp{j}(1:sep(1)) resTemp{j}(1+sep(2):end)]};
                            temp2B = {[resTemp{j}(1+sep(1):end)]};
                            temp2C = {[resTemp{j}]};
                            temp2D = {[resTemp{j}]};
                            for pp = 1:(size(chainLookup.chainCounts,1)*size(chainLookup.chainCounts,2))
                                chainLookup.chainCounts(pp) = 0;
                            end
                            eval(['curFact = app.prsData.pdbMTR(j).'  option ';']);
                            for k = 1:size(app.prsData.pdbMTR,2)
                                if curFact(k)
                                    
                                    sep = find(resTemp{k}==':');
                                    temp2A = mergecell(temp2A,  {[resTemp{k}(1:sep(1)) resTemp{k}(1+sep(2):end)]}  ,1);
                                    temp2B = mergecell(temp2B,  {[resTemp{k}(1+sep(1):end)]}   ,1);
                                    temp2C = mergecell(temp2C,  {[resTemp{k}]}   ,1);
                                    
                                    
                                    for pp = 1:(size(chainLookup.chainCounts,1)*size(chainLookup.chainCounts,2))
                                        if contains([resTemp{k}],['Chain' chainLookup.chain(pp)])
                                            chainLookup.chainCounts(pp) = chainLookup.chainCounts(pp) + 1;
                                        end
                                    end
                                end
                            end
                            tempA = mergecell(tempA,temp2A,2);
                            tempB = mergecell(tempB,temp2B,2);
                            tempC = mergecell(tempC,temp2C,2);
                            
                            for pp = 1:(size(chainLookup.chainCounts,1)*size(chainLookup.chainCounts,2))
                                temp2D = mergecell(temp2D,  {num2str(chainLookup.chainCounts(pp))}   ,1);
                            end
                            
                            tempD = mergecell(tempD,temp2D,2);
                        end
                        tt = {'Residue Index','Residue''s included'};
                        tempA = mergecell(tt,tempA,2);
                        tempB = mergecell(tt,tempB,2);
                        tempC = mergecell(tt,tempC,2);
                        temp2D = {'Chain'};
                        for pp = 1:(size(chainLookup.chainCounts,1)*size(chainLookup.chainCounts,2))
                            temp2D = mergecell(temp2D, [{chainLookup.chain(pp)}]   ,1);
                        end
                        tempD = mergecell(temp2D,tempD,2);
                        
                        writecell(tempA,replace([selectedFolder,selectedFile],'.xlsx','-alignedResidues.xlsx' ));
                        writecell(tempB,replace([selectedFolder,selectedFile],'.xlsx','-pdbResidues.xlsx' ));
                        writecell(tempC,replace([selectedFolder,selectedFile],'.xlsx','-bothResidues.xlsx' ));
                        writecell(tempD,replace([selectedFolder,selectedFile],'.xlsx','-whichChainResAreUsed.xlsx' ));


                        eval(['curFact = app.prsData.factors.'  option ';']);


                        tempE = num2cell(sum(curFact,1)');
                        tempF = num2cell(sum(curFact,2));
                        
                        tempE = mergecell({'residues used'},tempE,2);
                        tempF = mergecell({'times this residue is used'},tempF,2);
                        tempE = mergecell(tempE,tempF,1);
                        tempE = mergecell(tempD(:,1),tempE,1);
                        
                        tempG1 = mergecell({'resN'},num2cell([app.prsData.pdbData.pdbSeq(:).n]'),2);
                        tempG2 = mergecell({'chain'},num2cell([app.prsData.pdbData.pdbSeq(:).chain]'),2);
                        tempG3 = mergecell({'res'},num2cell([app.prsData.pdbData.pdbSeq(:).res]'),2);
                        
                        tempG = mergecell(mergecell(tempG1,tempG2,1),tempG3,1);
                        
                        tempE = mergecell(tempG,tempE,1);
                        
                        
                        writecell(tempE,replace([selectedFolder,selectedFile],'.xlsx','-frequencyOfUse.xlsx' ));
                        
                        
                        fig = figure;
                        hold on
                        
                        temp = sum(curFact,2);
                        stairs(temp)
                        
                        title(['times this residue is used ' sugNameA ' - ' option]);
                        xlabel('residue(factor numbering)')
                        axis('tight')
                        [a] = ylim;
                        ylim([0 max(a)]);
                        ylabel('counts')
                        %                     c.TickLabels = num2cell(c.Ticks.*max(max(app.prsData.distances)));


                        print(gcf,replace([selectedFolder,selectedFile],'.xlsx',['-' sugName '-' option 'frequencyOfUse.png'] ),'-dpng','-r900')
                        
                        answer2 = questdlg('Would you like to save a vector file?', ...
                           	'Save a Vector File', ...
                           	'Yes','No','Yes');
                        switch answer2
                            case 'Yes'
                                fig = gcf;
                                fig.Renderer = 'painters';
                                saveas(fig,replace([selectedFolder,selectedFile],'.xlsx',['-' sugName '-' option '-frequencyOfUse.svg'] ),'svg');
                            case 'No'
                        end
                        close
                        
                        %each chain
                        chains = [app.prsData.pdbData.pdbSeq(:).chain];
                        resN = [app.prsData.pdbMTR(:).resSeq]; %[app.prsData.pdbMTR(:).resSeq] or [app.prsData.pdbData.pdbSeq(:).n]
                        for m = 1:size(chainLookup.chain,2)
                            fig = figure;
                            hold on
                            stairs(resN(chains==chainLookup.chain(m)),temp(chains==chainLookup.chain(m)))
                            
                            title(['times this residue is used ' sugNameA ' - ' option ' - chain' chainLookup.chain(m)]);
                            xlabel('residue(factor numbering)')
                            axis('tight')
                            [a] = ylim;
                            ylim([0 max(a)]);
                            ylabel('counts')
                            %                     c.TickLabels = num2cell(c.Ticks.*max(max(app.prsData.distances)));


                            print(gcf,replace([selectedFolder,selectedFile],'.xlsx',['-' sugName '-' option '-chain' chainLookup.chain(m) '-frequencyOfUse.png'] ),'-dpng','-r900')
                            
                            switch answer2
                                case 'Yes'
                                    fig = gcf;
                                    fig.Renderer = 'painters';
                                    saveas(fig,replace([selectedFolder,selectedFile],'.xlsx',['-' sugName '-' option '-chain' chainLookup.chain(m) '-frequencyOfUse.svg'] ),'svg');
                                case 'No'
                            end
                            close
                        end
                        
                        app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                    else
                        app.StatusEditField.Value = 'error: no file selected'; pause(0.01);
                    end
                end
            end
        end

        % Button pushed function: Save3DMTRRasterPlotButton
        function Save3DMTRRasterPlotButtonPushed(app, event)
            app.StatusEditField.Value = ['Calculating Calculating: Raster Plot']; pause(0.01);
            
            if sum(app.DataLoadedLamp.Color == [0 1 0])==3
                gene = app.GenetoplotDropDown.Value;
                chain = gene(end);
                gene = gene(1:(find(gene==':',1,"first")-1));
                items = app.rawData.ID;
                cmap = redblue(255);
                fig = figure;
                hold on
                
                curFolder = app.prsData.resultsFolder;
                curTable = app.inputTable.Data;
                sugName = '';
                for i = 1:size(curTable,1)
                    if i==1
                        sugName = curTable{i,1};
                    else
                        sugName = [sugName '_' curTable{i,1}];
                    end
                end
                
                chainLookup.chain = '';
                n=0;
                for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                    curC = split(app.rawData.chains{j},',');
                    for k = 1:size(curC,1)
                        n = n + 1;
                        chainLookup.chain(n) = (curC{k});
                        chainLookup.ID{n} = {app.rawData.ID{j}};
                        chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                    end
                end
                
                for k = 1:size(items,2)
                    if strcmp(gene,items{k})
                        break;
                    end
                end
                
                %go through the table and find the calculation ways to plot
                sugName = [sugName '-rasterPlot-' gene '-chain' chain];
                sugNameIDs = '';
                cellIDs = {};
                tabData = app.rasterInclusionTable.Data;
                toRaster = struct;
                toNum = 0;
                for i = 1:size(tabData,1)
                    if replace(tabData{i,2},' ','')=='y'
                        toNum = toNum+1;
                        toRaster(toNum).ID = tabData{i,1};
                        toRaster(toNum).line = (toNum-1).*1.1;
                        sugName = [sugName '-' toRaster(toNum).ID ];
                        sugNameIDs = [sugNameIDs '-' toRaster(toNum).ID ];
                        cellIDs{toNum} = toRaster(toNum).ID;
                    end
                end
                sugName = [sugName '_' datestr(now,'yyyymmdd')];
                sugName = replace(sugName,': ','_');
                sugName = replace(sugName,':','_');
                
                soundsc(makeNote(16000,.3,440,0,0,'dampenE'),16000); pause(0.05); soundsc(makeNote(16000,.3,560,0,0,'dampenE'),16000); [selectedFile,selectedFolder,~] = uiputfile(...
                    {'*.png','image file (*.png)'}, ...
                    'Select a file to save a series of raster plot to compare the 3DMTR methods',[curFolder '\' sugName '.png']);
                
                
                if selectedFolder ~= 0
                    %make the plot
                    for i = 1:size(toRaster,2)
                        
                        chainVec = [app.prsData.pdbMTR.chainID];
                        curMTR = zeros(size(chainVec,1),size(chainVec,2));
                        resN = zeros(size(chainVec,1),size(chainVec,2));
                        toremove = [];
                        switch toRaster(i).ID
                            case 'swMTR'
                                for j = 1:size(resN,2)
                                    if isempty(app.prsData.pdbMTR(j).mtrSW)
                                        curMTR(j) = 0;
                                        toremove = [toremove j];
                                        resN(j) = 0;
                                    else
                                        curMTR(j) = app.prsData.pdbMTR(j).mtrSW;
                                        
                                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                                    end
                                end
                                toPlotI = ([app.prsData.pdbMTR.chainID] == chain);
                                curMTR(toremove) = [];
                                resN(toremove) = [];
                                toPlotI(toremove) = [];
                                
                            otherwise
                                for j = 1:size(app.prsData.vMTRpdb,2)
                                    if strcmp(app.prsData.vMTRpdb(j).meth,toRaster(i).ID)
                                        curMTR = app.prsData.vMTRpdb(j).vMTR;
                                    end
                                end
                                
                                for j = 1:size(resN,2)
                                    if isempty(app.prsData.pdbMTR(j).mtrSW)
                                        toremove = [toremove j];
                                        resN(j) = 0;
                                    else
                                        
                                        resN(j) = app.prsData.pdbMTR(j).varGResN;
                                    end
                                end
                                %
                                toPlotI = ([app.prsData.pdbMTR.chainID] == chain);
                                curMTR(toremove) = [];
                                resN(toremove) = [];
                                toPlotI(toremove) = [];
                        end


                        %%%
                        %                         resN = [app.prsData.pdbMTR.varGResN];
                        
                        
                        %adjust to Gene Residues numbering ...
                        chainLookup.chain = '';
                        n=0;
                        for j = 1:(size(app.rawData.chains,2)*size(app.rawData.chains,1))
                            curC = split(app.rawData.chains{j},',');
                            for k = 1:size(curC,1)
                                n = n + 1;
                                chainLookup.chain(n) = (curC{k});
                                chainLookup.ID{n} = {app.rawData.ID{j}};
                                chainLookup.IDfull{n} = {[app.rawData.ID{j} '_chain_' curC{k}]};
                            end
                        end


                        temp = find(chainLookup.chain==chain,1,'first');
                        sel = [0 0];
                        for a = 1:size(app.prsData.alignments,1)
                            for b = 1:size(app.prsData.alignments,2)
                                if app.prsData.alignments{a,b}.chain==chain
                                    sel = [a b];
                                end
                            end
                        end
                        %                         for c = 1:size(resN,2)
                        %                             if toPlotI(c)
                        %                                 [temp,~,~] = pdbResRelatedToX(app.prsData.alignments{sel(1),sel(2)}.numAlign_pbd2pbdG,app.prsData.alignments{sel(1),sel(2)}.numAlign_pbdG2varG,app.prsData.alignments{sel(1),sel(2)}.numAlign_varG2var, resN(c));
                        %                                 if ~isempty(temp)
                        %                                     resN(c) = temp;
                        %                                 else
                        %                                     resN(c) = resN(c-1)+1;
                        %                                 end
                        %                                 clear temp
                        %                             end
                        %                         end
                        
                        if resN(find(toPlotI,1,'first'))>1000
                            resN = resN-1000;
                        end
                        
                        curMTR(curMTR>1) = 1;
                        curMTR(isnan(curMTR)) = .5;
                        
                        
                        for j = 1:size(curMTR,2)
                            if toPlotI(j)
                                plot([resN(j) resN(j)]',...
                                    [toRaster(i).line toRaster(i).line+1]','Color',cmap(1+round(curMTR(j)*254),:));
                            end
                        end


                    end
                    title(['Residue MTR from ' sugNameIDs ' - ' gene]);
                    xlabel('residues number (gene numbering)')
                    axis('tight')
                    [a] = xlim;
                    %                     xlim([1 max(resN)]);
                    yticks([(toRaster(:).line)]+0.5);
                    yticklabels(cellIDs)
                    ylabel('factor')
                    c = colorbar;
                    colormap(redblue)
                    %                     c.TickLabels = num2cell(c.Ticks.*max(max(app.prsData.distances)));


                    print(gcf,[selectedFolder,selectedFile],'-dpng','-r900')
                    
                    answer2 = questdlg('Would you like to save a vector file?', ...
                       	'Save a Vector File', ...
                       	'Yes','No','Yes');
                    switch answer2
                        case 'Yes'
                            fig.Renderer = 'painters';
                            saveas(fig,replace([selectedFolder,selectedFile],'.png','.svg'),'svg');
                        case 'No'
                    end
                    close
                    
                    app.StatusEditField.Value = 'idle'; soundsc(makeNote(16000,.25,440,0,0,'dampenE'),16000); pause(0.01);
                else
                    app.StatusEditField.Value = 'idle - no file selected'; pause(0.01);
                end
            else
                
                app.StatusEditField.Value = 'idle - 3DMTR not Calculated'; pause(0.01);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectedPDBfileEditFieldLabel = uilabel(app.LeftPanel);
            app.SelectedPDBfileEditFieldLabel.Text = 'Selected PDB file';
            app.SelectedPDBfileEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectPDBFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectPDBFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPDBFileButtonPushed, true);
            app.SelectPDBFileButton.Text = 'Select PDB File';
            app.inputTable = uitable(app.LeftPanel);
            app.inputTable.ColumnName = {'ID'; 'PDB'; 'PDB Gene Seq'; 'Variant Data'; 'Variant Gene Seq'; 'Chain(s) Variants Corr.'};
            app.inputTable.RowName = {};
            app.ClearInputTableButton = uibutton(app.LeftPanel, 'push');
            app.ClearInputTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInputTableButtonPushed, true);
            app.ClearInputTableButton.Text = 'Clear Input Table';
            app.SaveInputTableButton = uibutton(app.LeftPanel, 'push');
            app.SaveInputTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInputTableButtonPushed, true);
            app.SaveInputTableButton.Text = 'Save Input Table';
            app.OpenInputTableButton = uibutton(app.LeftPanel, 'push');
            app.OpenInputTableButton.ButtonPushedFcn = createCallbackFcn(app, @OpenInputTableButtonPushed, true);
            app.OpenInputTableButton.Text = 'Open Input Table';
            app.SelectBaseFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectBaseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBaseFolderButtonPushed, true);
            app.SelectBaseFolderButton.Text = 'Select Base Folder';
            app.SelectedBaseFolderEditFieldLabel = uilabel(app.LeftPanel);
            app.SelectedBaseFolderEditFieldLabel.Text = 'Selected Base Folder';
            app.SelectedBaseFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectPDBSeqFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectPDBSeqFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPDBSeqFileButtonPushed, true);
            app.SelectPDBSeqFileButton.Text = 'Select PDB Seq File';
            app.SelectedPDBGeneSeqfileEditFieldLabel = uilabel(app.LeftPanel);
            app.SelectedPDBGeneSeqfileEditFieldLabel.Text = 'Selected PDB Gene Seq file';
            app.SelectedPDBGeneSeqfileEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectVariantFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectVariantFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVariantFileButtonPushed, true);
            app.SelectVariantFileButton.Text = 'Select Variant File';
            app.SelectedVariantfileEditFieldLabel = uilabel(app.LeftPanel);
            app.SelectedVariantfileEditFieldLabel.Text = 'Selected Variant file';
            app.SelectedVariantfileEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectVariantSeqFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectVariantSeqFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVariantSeqFileButtonPushed, true);
            app.SelectVariantSeqFileButton.Text = 'Select Variant Seq File';
            app.SelectedVariantGeneSeqfileEditFieldLabel = uilabel(app.LeftPanel);
            app.SelectedVariantGeneSeqfileEditFieldLabel.Text = 'Selected Variant Gene Seq file';
            app.SelectedVariantGeneSeqfileEditField = uieditfield(app.LeftPanel, 'text');
            app.UseSelectedFilesButton = uibutton(app.LeftPanel, 'push');
            app.UseSelectedFilesButton.ButtonPushedFcn = createCallbackFcn(app, @UseSelectedFilesButtonPushed, true);
            app.UseSelectedFilesButton.Text = 'Use Selected Files';
            app.SelectedVariantGeneSeqfileEditField_2Label = uilabel(app.LeftPanel);
            app.SelectedVariantGeneSeqfileEditField_2Label.Text = 'Chain(s) that Variant File Corresponds with in the PDB Structure';
            app.ChainsthatVariantFileCorrespondswithinthePDBStructureEditField = uieditfield(app.LeftPanel, 'text');
            app.StatusEditFieldLabel = uilabel(app.LeftPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.LeftPanel, 'text');
            app.StatusEditField.Value = 'idle';
            app.UpdateProcessingTableButton = uibutton(app.LeftPanel, 'push');
            app.UpdateProcessingTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateProcessingTableButtonPushed, true);
            app.UpdateProcessingTableButton.Text = 'Update Processing Table';
            app.AppInitializedLampLabel = uilabel(app.LeftPanel);
            app.AppInitializedLampLabel.Text = 'App Initialized';
            app.AppInitializedLamp = uilamp(app.LeftPanel);
            app.EntryIDEditFieldLabel = uilabel(app.LeftPanel);
            app.EntryIDEditFieldLabel.Text = 'Entry ID';
            app.EntryIDEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SequenceAlignmentTab = uitab(app.TabGroup);
            app.SequenceAlignmentTab.Title = 'Sequence Alignment';
            app.SequenceAlignmentDisplayTable = uitable(app.SequenceAlignmentTab);
            app.SequenceAlignmentDisplayTable.ColumnName = {''};
            app.SequenceAlignmentDisplayTable.RowName = {};
            app.GeneAlignmenttoshowDropDownLabel = uilabel(app.SequenceAlignmentTab);
            app.GeneAlignmenttoshowDropDownLabel.Text = 'Gene Alignment to show';
            app.GeneAlignmenttoshowDropDown = uidropdown(app.SequenceAlignmentTab);
            app.GeneAlignmenttoshowDropDown.Items = {};
            app.GeneAlignmenttoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneAlignmenttoshowDropDownValueChanged, true);
            app.GeneAlignmenttoshowDropDown.Value = {};
            app.alignmentPlot = uiaxes(app.SequenceAlignmentTab);
            title(app.alignmentPlot, '')
            xlabel(app.alignmentPlot, 'Residue (entry in alignment)')
            ylabel(app.alignmentPlot, '')
            app.alignmentPlot.YLim = [0 8];
            app.alignmentPlot.YTick = [0.5 1 1.5 3.5 4 4.5 6.5 7 7.5];
            app.alignmentPlot.YTickLabel = {'''W'''; '''Gene v Var'''; '''C'''; '''W'''; '''Gene v Gene'''; '''C'''; '''W'''; '''Gene v pdb'''; '''C'''; ''};
            app.SaveAlignmentPlotButton = uibutton(app.SequenceAlignmentTab, 'push');
            app.SaveAlignmentPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAlignmentPlotButtonPushed, true);
            app.SaveAlignmentPlotButton.Text = 'Save Alignment Plot';
            app.AlignmentMethodDropDownLabel = uilabel(app.SequenceAlignmentTab);
            app.AlignmentMethodDropDownLabel.Text = 'Alignment Method';
            app.AlignmentMethodDropDown = uidropdown(app.SequenceAlignmentTab);
            app.AlignmentMethodDropDown.Items = {'standard', 'local', 'custom'};
            app.AlignmentMethodDropDown.Value = 'standard';
            app.RealignButton = uibutton(app.SequenceAlignmentTab, 'push');
            app.RealignButton.ButtonPushedFcn = createCallbackFcn(app, @RealignButtonPushed, true);
            app.RealignButton.Text = 'Realign';
            app.patchGaps = uicheckbox(app.SequenceAlignmentTab);
            app.patchGaps.Text = 'Patch Gaps';
            app.PDBTab = uitab(app.TabGroup);
            app.PDBTab.Title = 'PDB';
            app.ShowOriginalPDBButton = uibutton(app.PDBTab, 'push');
            app.ShowOriginalPDBButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOriginalPDBButtonPushed, true);
            app.ShowOriginalPDBButton.Text = 'Show Original PDB';
            app.PDBtoshowDropDownLabel = uilabel(app.PDBTab);
            app.PDBtoshowDropDownLabel.Text = 'PDB to show';
            app.PDBtoshowDropDown = uidropdown(app.PDBTab);
            app.PDBtoshowDropDown.Items = {};
            app.PDBtoshowDropDown.Value = {};
            app.InputPDBsLabel = uilabel(app.PDBTab);
            app.InputPDBsLabel.Text = 'Input PDBs';
            app.ResultantPDBsLabel = uilabel(app.PDBTab);
            app.ResultantPDBsLabel.Text = 'Resultant PDBs';
            app.CalcuationFactortoapplytoPDBDropDownLabel = uilabel(app.PDBTab);
            app.CalcuationFactortoapplytoPDBDropDownLabel.Text = 'Calcuation Factor to apply to PDB';
            app.CalcuationFactortoapplytoPDBDropDown = uidropdown(app.PDBTab);
            app.CalcuationFactortoapplytoPDBDropDown.Items = {'swMTR', 'fact_t05', 'fact_t10', 'fact_t15', 'fact_t20', 'fact_t30', 'fact_t40', 'fact_c11', 'fact_c15', 'fact_c21', 'fact_c25', 'fact_c31', 'fact_c35', 'fact_c41', 'fact_sw11', 'fact_sw15', 'fact_sw21', 'fact_sw25', 'fact_sw31', 'fact_sw35', 'fact_sw41', 'fact_l11', 'fact_l15', 'fact_l21', 'fact_l25', 'fact_l31', 'fact_l35', 'fact_l41', 'custom_rawNorm', 'custom_4xMean', 'custom_1xSTD', 'custom_2xSTD', 'custom_3xSTD', 'custom_4xSTD', 'custom_[0_1]', 'custom_[-1_1]', 'custom'};
            app.CalcuationFactortoapplytoPDBDropDown.Value = 'fact_c31';
            app.Save3DMTRPDBButton = uibutton(app.PDBTab, 'push');
            app.Save3DMTRPDBButton.ButtonPushedFcn = createCallbackFcn(app, @Save3DMTRPDBButtonPushed, true);
            app.Save3DMTRPDBButton.Text = 'Save 3DMTR PDB';
            app.savedPDBdescriptionLabel = uilabel(app.PDBTab);
            app.savedPDBdescriptionLabel.Text = 'The score is applied to the b-factor in the PDB, use XXX to recolor the PDB';
            app.savedPDBdescriptionLabel_2 = uilabel(app.PDBTab);
            app.savedPDBdescriptionLabel_2.Text = 'comand: "color_b all,mode=hist,gradient=bwr,nbins=1000,sat=1, value=1."';
            app.EditField = uieditfield(app.PDBTab, 'text');
            app.EditField.Value = 'comand: "color_b all,mode=hist,gradient=bwr,nbins=1000,sat=1, value=1."';
            app.DMTRSelectedFactorTab = uitab(app.TabGroup);
            app.DMTRSelectedFactorTab.Title = '3DMTR Selected Factor';
            app.CalcuationFactorDropDownLabel = uilabel(app.DMTRSelectedFactorTab);
            app.CalcuationFactorDropDownLabel.Text = 'Calcuation Factor';
            app.CalcuationFactorDropDown = uidropdown(app.DMTRSelectedFactorTab);
            app.CalcuationFactorDropDown.Items = {'rawDistances', 'fact_t05', 'fact_t10', 'fact_t15', 'fact_t20', 'fact_t30', 'fact_t40', 'fact_c11', 'fact_c15', 'fact_c21', 'fact_c25', 'fact_c31', 'fact_c35', 'fact_c41', 'fact_sw11', 'fact_sw15', 'fact_sw21', 'fact_sw25', 'fact_sw31', 'fact_sw35', 'fact_sw41', 'fact_l11', 'fact_l15', 'fact_l21', 'fact_l25', 'fact_l31', 'fact_l35', 'fact_l41'};
            app.CalcuationFactorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcuationFactorDropDownValueChanged, true);
            app.CalcuationFactorDropDown.Value = 'fact_c31';
            app.vMTRFactorPlot = uiaxes(app.DMTRSelectedFactorTab);
            title(app.vMTRFactorPlot, '3DMTR FactorMatrix')
            xlabel(app.vMTRFactorPlot, 'Residue (pdb order)')
            ylabel(app.vMTRFactorPlot, 'Residue (pdb order)')
            app.SaveMTRFactorPlotButton = uibutton(app.DMTRSelectedFactorTab, 'push');
            app.SaveMTRFactorPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMTRFactorPlotButtonPushed, true);
            app.SaveMTRFactorPlotButton.Text = 'Save MTR Factor Plot';
            app.Save3DMTRFactorPDBButton = uibutton(app.DMTRSelectedFactorTab, 'push');
            app.Save3DMTRFactorPDBButton.ButtonPushedFcn = createCallbackFcn(app, @Save3DMTRFactorPDBButtonPushed, true);
            app.Save3DMTRFactorPDBButton.Text = {'Save 3DMTR'; 'Factor PDB'};
            app.ResiduetohighlightinPBDDropDownLabel = uilabel(app.DMTRSelectedFactorTab);
            app.ResiduetohighlightinPBDDropDownLabel.Text = 'Residue to highlight in PBD';
            app.ResiduetohighlightinPBDDropDown = uidropdown(app.DMTRSelectedFactorTab);
            app.ResiduetohighlightinPBDDropDown.Items = {'Chain_A:res1', 'Chain_A:res2', 'Chain_B:res1'};
            app.ResiduetohighlightinPBDDropDown.Value = 'Chain_A:res1';
            app.SaveaPDBmarkingaselectedResidueandtheresiduesLabel = uilabel(app.DMTRSelectedFactorTab);
            app.SaveaPDBmarkingaselectedResidueandtheresiduesLabel.Text = {'Save a PDB marking a selected residue and the'; 'residues incuded in the 3DMTR calculation (that '; 'is selected above). A PDB will be colored in a '; 'binary fasion if a factor is selected, otherwise a '; 'normalized distance is applied'};
            app.SaveMTRFactorSpreadsheetButton = uibutton(app.DMTRSelectedFactorTab, 'push');
            app.SaveMTRFactorSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMTRFactorSpreadsheetButtonPushed, true);
            app.SaveMTRFactorSpreadsheetButton.Text = 'Save MTR Factor Spreadsheet';
            app.SaveaPDBmarkingaselectedResidueandtheresiduesLabel_2 = uilabel(app.DMTRSelectedFactorTab);
            app.SaveaPDBmarkingaselectedResidueandtheresiduesLabel_2.Text = {'Save a spreadsheet detailing the residues included in the'; '3DMTR calculation'};
            app.Label = uilabel(app.DMTRSelectedFactorTab);
            app.Label.Text = {'The Aligned gnomAD sequence residue is shown first,'; 'then the PDB sequence residue, last the chain'};
            app.Calculated3DMTRTab = uitab(app.TabGroup);
            app.Calculated3DMTRTab.Title = 'Calculated 3DMTR';
            app.GenetoViewDropDown_2Label = uilabel(app.Calculated3DMTRTab);
            app.GenetoViewDropDown_2Label.Text = 'Gene to View';
            app.GenetoViewDropDown = uidropdown(app.Calculated3DMTRTab);
            app.GenetoViewDropDown.ValueChangedFcn = createCallbackFcn(app, @GenetoViewDropDownValueChanged, true);
            app.CalcuationFactortoShowDropDownLabel = uilabel(app.Calculated3DMTRTab);
            app.CalcuationFactortoShowDropDownLabel.Text = 'Calcuation Factor to Show';
            app.CalcuationFactortoShowDropDown = uidropdown(app.Calculated3DMTRTab);
            app.CalcuationFactortoShowDropDown.Items = {'fact_t05', 'fact_t10', 'fact_t15', 'fact_t20', 'fact_t30', 'fact_t40', 'fact_c11', 'fact_c15', 'fact_c21', 'fact_c25', 'fact_c31', 'fact_c35', 'fact_c41', 'fact_sw11', 'fact_sw15', 'fact_sw21', 'fact_sw25', 'fact_sw31', 'fact_sw35', 'fact_sw41', 'fact_l11', 'fact_l15', 'fact_l21', 'fact_l25', 'fact_l31', 'fact_l35', 'fact_l41'};
            app.CalcuationFactortoShowDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcuationFactortoShowDropDownValueChanged, true);
            app.CalcuationFactortoShowDropDown.Value = 'fact_c31';
            app.swMTRresultPlot = uiaxes(app.Calculated3DMTRTab);
            title(app.swMTRresultPlot, 'swMTR')
            xlabel(app.swMTRresultPlot, 'Residue (gene numbering)')
            ylabel(app.swMTRresultPlot, 'MTR')
            app.vMTRresultPlot = uiaxes(app.Calculated3DMTRTab);
            title(app.vMTRresultPlot, '3DMTR')
            xlabel(app.vMTRresultPlot, 'Residue (gene numbering)')
            ylabel(app.vMTRresultPlot, 'MTR')
            app.SaveTandemMTRPlotsButton = uibutton(app.Calculated3DMTRTab, 'push');
            app.SaveTandemMTRPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTandemMTRPlotsButtonPushed, true);
            app.SaveTandemMTRPlotsButton.Text = 'Save Tandem MTR Plots';
            app.fittoWhatDropDownLabel = uilabel(app.Calculated3DMTRTab);
            app.fittoWhatDropDownLabel.Text = 'fit to What?';
            app.fittoWhatDropDown = uidropdown(app.Calculated3DMTRTab);
            app.fittoWhatDropDown.Items = {'pdb', 'gene', 'tight'};
            app.fittoWhatDropDown.ValueChangedFcn = createCallbackFcn(app, @fittoWhatDropDownValueChanged, true);
            app.fittoWhatDropDown.Value = 'pdb';
            app.DMTRDifferenceTab = uitab(app.TabGroup);
            app.DMTRDifferenceTab.Title = '3DMTR Difference';
            app.Diff_SaveDiffMTRPlotsButton = uibutton(app.DMTRDifferenceTab, 'push');
            app.Diff_SaveDiffMTRPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @Diff_SaveDiffMTRPlotsButtonPushed, true);
            app.Diff_SaveDiffMTRPlotsButton.Text = 'Save Diff. MTR Plots';
            app.GenetoViewDropDown_2Label_2 = uilabel(app.DMTRDifferenceTab);
            app.GenetoViewDropDown_2Label_2.Text = 'Gene to View';
            app.Diff_GenetoViewDropDown = uidropdown(app.DMTRDifferenceTab);
            app.Diff_GenetoViewDropDown.ValueChangedFcn = createCallbackFcn(app, @Diff_GenetoViewDropDownValueChanged, true);
            app.Factor1DropDownLabel = uilabel(app.DMTRDifferenceTab);
            app.Factor1DropDownLabel.Text = 'Factor 1';
            app.Diff_Factor1DropDown = uidropdown(app.DMTRDifferenceTab);
            app.Diff_Factor1DropDown.Items = {'swMTR', 'fact_t05', 'fact_t10', 'fact_t15', 'fact_t20', 'fact_t30', 'fact_t40', 'fact_c11', 'fact_c15', 'fact_c21', 'fact_c25', 'fact_c31', 'fact_c35', 'fact_c41', 'fact_sw11', 'fact_sw15', 'fact_sw21', 'fact_sw25', 'fact_sw31', 'fact_sw35', 'fact_sw41', 'fact_l11', 'fact_l15', 'fact_l21', 'fact_l25', 'fact_l31', 'fact_l35', 'fact_l41'};
            app.Diff_Factor1DropDown.ValueChangedFcn = createCallbackFcn(app, @Diff_Factor1DropDownValueChanged, true);
            app.Diff_Factor1DropDown.Value = 'swMTR';
            app.Factor2DropDownLabel = uilabel(app.DMTRDifferenceTab);
            app.Factor2DropDownLabel.Text = 'Factor 2';
            app.Diff_Factor2DropDown = uidropdown(app.DMTRDifferenceTab);
            app.Diff_Factor2DropDown.Items = {'swMTR', 'fact_t05', 'fact_t10', 'fact_t15', 'fact_t20', 'fact_t30', 'fact_t40', 'fact_c11', 'fact_c15', 'fact_c21', 'fact_c25', 'fact_c31', 'fact_c35', 'fact_c41', 'fact_sw11', 'fact_sw15', 'fact_sw21', 'fact_sw25', 'fact_sw31', 'fact_sw35', 'fact_sw41', 'fact_l11', 'fact_l15', 'fact_l21', 'fact_l25', 'fact_l31', 'fact_l35', 'fact_l41'};
            app.Diff_Factor2DropDown.ValueChangedFcn = createCallbackFcn(app, @Diff_Factor2DropDownValueChanged, true);
            app.Diff_Factor2DropDown.Value = 'fact_c31';
            app.DifferenceMTRplotsFactor2Factor1Label = uilabel(app.DMTRDifferenceTab);
            app.DifferenceMTRplotsFactor2Factor1Label.Text = 'Difference MTR plots [Factor 2 - Factor 1]';
            app.Diff_vMTRresultPlot = uiaxes(app.DMTRDifferenceTab);
            title(app.Diff_vMTRresultPlot, '3DMTR Difference')
            xlabel(app.Diff_vMTRresultPlot, 'Residue (gene numbering)')
            ylabel(app.Diff_vMTRresultPlot, 'MTR')
            app.SaveDiff3DMTRPDBButton = uibutton(app.DMTRDifferenceTab, 'push');
            app.SaveDiff3DMTRPDBButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDiff3DMTRPDBButtonPushed, true);
            app.SaveDiff3DMTRPDBButton.Text = 'Save Diff. 3DMTR PDB';
            app.PermutationsTab = uitab(app.TabGroup);
            app.PermutationsTab.Title = 'Permutations';
            app.GenetoViewDropDown_3Label = uilabel(app.PermutationsTab);
            app.GenetoViewDropDown_3Label.Text = 'Gene to View';
            app.Perm_GenetoViewDropDown = uidropdown(app.PermutationsTab);
            app.Perm_GenetoViewDropDown.ValueChangedFcn = createCallbackFcn(app, @Perm_GenetoViewDropDownValueChanged, true);
            app.CalcuationFactortoShowDropDown_2Label = uilabel(app.PermutationsTab);
            app.CalcuationFactortoShowDropDown_2Label.Text = 'Calcuation Factor to Show';
            app.Perm_CalcuationFactortoShowDropDown = uidropdown(app.PermutationsTab);
            app.Perm_CalcuationFactortoShowDropDown.Items = {'fact_t05', 'fact_t10', 'fact_t15', 'fact_t20', 'fact_t30', 'fact_t40', 'fact_c11', 'fact_c15', 'fact_c21', 'fact_c25', 'fact_c31', 'fact_c35', 'fact_c41', 'fact_sw11', 'fact_sw15', 'fact_sw21', 'fact_sw25', 'fact_sw31', 'fact_sw35', 'fact_sw41', 'fact_l11', 'fact_l15', 'fact_l21', 'fact_l25', 'fact_l31', 'fact_l35', 'fact_l41'};
            app.Perm_CalcuationFactortoShowDropDown.Value = 'fact_c31';
            app.CalculatePermutationsButton = uibutton(app.PermutationsTab, 'push');
            app.CalculatePermutationsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePermutationsButtonPushed, true);
            app.CalculatePermutationsButton.Text = 'Calculate Permutations';
            app.SavePermutationsButton = uibutton(app.PermutationsTab, 'push');
            app.SavePermutationsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePermutationsButtonPushed, true);
            app.SavePermutationsButton.Text = 'Save Permutations';
            app.NumPermuationsEditFieldLabel = uilabel(app.PermutationsTab);
            app.NumPermuationsEditFieldLabel.Text = 'Num. Permuations';
            app.NumPermuationsEditField = uieditfield(app.PermutationsTab, 'numeric');
            app.NumPermuationsEditField.Value = 1000;
            app.Perm_vMTRresultPlot = uiaxes(app.PermutationsTab);
            title(app.Perm_vMTRresultPlot, '3DMTR')
            xlabel(app.Perm_vMTRresultPlot, 'Residue (gene numbering)')
            ylabel(app.Perm_vMTRresultPlot, 'MTR')
            app.PermuteWhatDropDownLabel = uilabel(app.PermutationsTab);
            app.PermuteWhatDropDownLabel.Text = 'Permute What?';
            app.PermuteWhatDropDown = uidropdown(app.PermutationsTab);
            app.PermuteWhatDropDown.Items = {'Residues', 'MTR Score', 'Factor'};
            app.PermuteWhatDropDown.Value = 'Residues';
            app.PermutationsCalculatedLampLabel = uilabel(app.PermutationsTab);
            app.PermutationsCalculatedLampLabel.Text = 'Permutations Calculated';
            app.PermutationsCalculatedLamp = uilamp(app.PermutationsTab);
            app.SavePermutationPlotButton = uibutton(app.PermutationsTab, 'push');
            app.SavePermutationPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePermutationPlotButtonPushed, true);
            app.SavePermutationPlotButton.Text = 'Save Permutation Plot';
            app.SaveExamplePermutationFactorPlotButton = uibutton(app.PermutationsTab, 'push');
            app.SaveExamplePermutationFactorPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExamplePermutationFactorPlotButtonPushed, true);
            app.SaveExamplePermutationFactorPlotButton.Text = 'Save Example Permutation Factor Plot';
            app.SavePermutationSignificancePDBButton = uibutton(app.PermutationsTab, 'push');
            app.SavePermutationSignificancePDBButton.ButtonPushedFcn = createCallbackFcn(app, @SavePermutationSignificancePDBButtonPushed, true);
            app.SavePermutationSignificancePDBButton.Text = 'Save Permutation Significance PDB';
            app.RasterPlotsTab = uitab(app.TabGroup);
            app.RasterPlotsTab.Title = 'Raster Plots';
            app.rasterInclusionTable = uitable(app.RasterPlotsTab);
            app.rasterInclusionTable.ColumnName = {'Factor'; 'Include?'};
            app.rasterInclusionTable.RowName = {};
            app.Save3DMTRRasterPlotButton = uibutton(app.RasterPlotsTab, 'push');
            app.Save3DMTRRasterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @Save3DMTRRasterPlotButtonPushed, true);
            app.Save3DMTRRasterPlotButton.Text = 'Save 3DMTR Raster Plot';
            app.GenetoplotDropDownLabel = uilabel(app.RasterPlotsTab);
            app.GenetoplotDropDownLabel.Text = 'Gene to plot';
            app.GenetoplotDropDown = uidropdown(app.RasterPlotsTab);
            app.DebugingTab = uitab(app.TabGroup);
            app.DebugingTab.Title = 'Debuging';
            app.SaveStateButton = uibutton(app.DebugingTab, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Text = 'Save State';
            app.LoadStateButton = uibutton(app.DebugingTab, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Text = 'Load State';
            app.Calculate3DMTRButton = uibutton(app.RightPanel, 'push');
            app.Calculate3DMTRButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate3DMTRButtonPushed, true);
            app.Calculate3DMTRButton.Text = 'Calculate 3DMTR';
            app.DataLoadedLampLabel = uilabel(app.RightPanel);
            app.DataLoadedLampLabel.Text = 'Data Loaded';
            app.DataLoadedLamp = uilamp(app.RightPanel);
            app.DMTRCalculatedLampLabel = uilabel(app.RightPanel);
            app.DMTRCalculatedLampLabel.Text = '3DMTR Calculated';
            app.DMTRCalculatedLamp = uilamp(app.RightPanel);
            app.SaveResultsButton = uibutton(app.RightPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volumetricMTR_v01_008

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end